classdef NIF_Toolbar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NIFToolbarUIFigure            matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        Button_User                   matlab.ui.control.Button
        Button_Experiment             matlab.ui.control.Button
        Button_Subject                matlab.ui.control.Button
        DropDown_User                 matlab.ui.control.DropDown
        DropDown_Subject              matlab.ui.control.DropDown
        DropDown_Experiment           matlab.ui.control.DropDown
        Button_Parameters             matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        DropDown_Parameters           matlab.ui.control.DropDown
        Button_Experiment_2           matlab.ui.control.Button
        DropDown_Experiment_2         matlab.ui.control.DropDown
        TabGroup                      matlab.ui.container.TabGroup
        fMRITab                       matlab.ui.container.Tab
        Button_ScannerPulse           matlab.ui.control.Button
        EphysTab                      matlab.ui.container.Tab
        Button_OpenEPhys              matlab.ui.control.Button
        Button_TDT                    matlab.ui.control.Button
        Button_Photodiode             matlab.ui.control.Button
        Button_Optitrack              matlab.ui.control.Button
        Button_Stimulation            matlab.ui.control.Button
        DisplayTab                    matlab.ui.container.Tab
        Button_Sterescopic            matlab.ui.control.Button
        Button_DomeProjection         matlab.ui.control.Button
        Button_Blender                matlab.ui.control.Button
        Button_Unity                  matlab.ui.control.Button
        CalibrateTab                  matlab.ui.container.Tab
        Button_GammaCorrect           matlab.ui.control.Button
        Button_DisplayGrid            matlab.ui.control.Button
        Button_DebugMode              matlab.ui.control.Button
        Button_RewardCal              matlab.ui.control.Button
        Button_LumCal                 matlab.ui.control.Button
        Button_ScannerCal             matlab.ui.control.Button
        Button_Joystick               matlab.ui.control.Button
        Button_DataPixx_2             matlab.ui.control.Button
        Button_NationalInstruments_2  matlab.ui.control.Button
        HelpTab                       matlab.ui.container.Tab
        Button_HelpNIF                matlab.ui.control.Button
        Button_HelpGit                matlab.ui.control.Button
        Button_HelpReadTheDocs        matlab.ui.control.Button
        Button_HelpDataPixx           matlab.ui.control.Button
        Button_HelpPsychToolbox       matlab.ui.control.Button
        Button_HelpTDT                matlab.ui.control.Button
        Button_NationalInstruments    matlab.ui.control.Button
        Button_NationalInstruments_3  matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        ActionsTab                    matlab.ui.container.Tab
        Button_RunExperiment          matlab.ui.control.Button
        Button_StopExperiment         matlab.ui.control.Button
        Button_ManualReward           matlab.ui.control.Button
        Button_RunEyeCal              matlab.ui.control.Button
        Button_Penalty                matlab.ui.control.Button
        Button_PlayAudio              matlab.ui.control.Button
        Button_PlayMovie              matlab.ui.control.Button
        Button_LightsOut              matlab.ui.control.Button
        Button_Finish                 matlab.ui.control.Button
        SettingsTab                   matlab.ui.container.Tab
        Button_Display                matlab.ui.control.Button
        Button_DataPixx               matlab.ui.control.Button
        Button_EyeTracking            matlab.ui.control.Button
        Button_Reward                 matlab.ui.control.Button
        Button_ExpSettings            matlab.ui.control.Button
        Button_Save                   matlab.ui.control.Button
        Button_Transfer               matlab.ui.control.Button
        Button_GitHub                 matlab.ui.control.Button
        Button_Keyboard               matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        TabGroup3                     matlab.ui.container.TabGroup
        EyeTab                        matlab.ui.container.Tab
        Button_ExpSettings_4          matlab.ui.control.Button
        LeverTab                      matlab.ui.container.Tab
        Lever1LampLabel               matlab.ui.control.Label
        Lever1Lamp                    matlab.ui.control.Lamp
        Lever2LampLabel               matlab.ui.control.Label
        Lever2Lamp                    matlab.ui.control.Lamp
        UIAxes                        matlab.ui.control.UIAxes
        Button_ExpSettings_3          matlab.ui.control.Button
        RewardTab                     matlab.ui.container.Tab
        Button_ExpSettings_2          matlab.ui.control.Button
        SessionTextAreaLabel          matlab.ui.control.Label
        SessionTextArea               matlab.ui.control.TextArea
        ResetButton                   matlab.ui.control.Button
        RunTextAreaLabel              matlab.ui.control.Label
        RunTextArea                   matlab.ui.control.TextArea
        ResetButton_2                 matlab.ui.control.Button
        ManualmsSliderLabel           matlab.ui.control.Label
        ManualmsSlider                matlab.ui.control.Slider
        ManualmsSlider_2Label         matlab.ui.control.Label
        ManualmsSlider_2              matlab.ui.control.Slider
        SessionTab                    matlab.ui.container.Tab
        RunSpinnerLabel               matlab.ui.control.Label
        RunSpinner                    matlab.ui.control.Spinner
        BlockSpinnerLabel             matlab.ui.control.Label
        BlockSpinner                  matlab.ui.control.Spinner
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Params % The Params structure holds all NTB parameter values for running experiments
        OffOnColors
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            
            % Load default params file for this host
            [~, CompName]   = system('hostname');
            [RootDir]       = fileparts(mfilename('fullpath'));
            ParamsPrefix    = 'NTB';
            ParamsFile      = sprintf('%s_%s.mat', ParamsPrefix, deblank(CompName));
            CreateParamText = 'Create new';
            if ~exist(ParamsFile, 'file')
                ParamsFile = CreateParamText;
            else
                app.Params  = load(ParamsFile); 
            end
            ParamsDir       = fullfile(RootDir, 'NTB_Params');
            AllParamsPaths  = wildcardsearch(ParamsDir, sprintf('%s_*.mat', ParamsPrefix));
            for n = 1:numel(AllParamsPaths)
                [~,AllParamsFiles{n}]   = fileparts(AllParamsPaths{n});
                AllParamsFiles{n}       = [AllParamsFiles{n}, '.mat'];
            end
            AllParamsFiles{end+1} = CreateParamText;
            app.DropDown_Parameters.Items = AllParamsFiles;
            app.DropDown_Parameters.Value = ParamsFile;
            app.OffOnColors = {[0.9,0.9,0.9],[0,1,0]};
            
            % Set figure size based on screen size
            ScreenSize = get(0, 'screensize');
            set(app.NIFToolbarUIFigure, 'Position', [0, 0, ScreenSize(3), 230])
            
            % 
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NIFToolbarUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {227, 227};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {702, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: DropDown_User
        function NTB_Session_User(app, event)
            value = app.DropDown_User.Value;
            
           
        end

        % Value changed function: DropDown_Parameters
        function NTB_Session_Params(app, event)
            value = app.DropDown_Parameters.Value;
            
        end

        % Button pushed function: Button_Display
        function Button_DisplayPushed(app, event)
            app.Button_Display.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Display Settings','tag','NTB_DispSettings');
            if isempty(fh)
                app.Params.Display = NTB_DisplaySettings(app, app.Params.Display);
            else
                figure(fh);
            end
        end

        % Button pushed function: Button_Parameters
        function LoadParams(app, event)
            [file, path] = uigetfile('NTB_*.mat', 'Select params file');
            if file == 0
                return
            end
            ParamsFile  = fullfile(path, file);
            app.Params  = load(ParamsFile);    
            app.DropDown_Parameters.Items{end+1} = file;
            app.DropDown_Parameters.Value = file;

        end

        % Button pushed function: Button_DataPixx
        function Button_DataPixxPushed(app, event)
            app.Button_DataPixx.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Datapixx Settings','tag','NTB_Datapixx');
            if isempty(fh)
                app.Params.DPx = NTB_DatapixxSettings(app, app.Params.DPx);
                fprintf('NBT_DatapixxSettings app was opened\n');
            else
                figure(fh);
                fprintf('NBT_DatapixxSettings app was already open\n');
            end
        end

        % Button pushed function: Button_Reward
        function Button_RewardPushed(app, event)
            app.Button_Reward.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Reward Settings','tag','NTB_Reward');
            if isempty(fh)
                app.Params.Reward = NTB_RewardSettings(app, app.Params.Reward);
            else
                figure(fh);
            end
        end

        % Button pushed function: Button_EyeTracking
        function Button_EyeTrackingPushed(app, event)
            app.Button_EyeTracking.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Eye Settings','tag','NTB_Eye');
            if isempty(fh)
                app.Params.Eye = NTB_EyeSettings(app, app.Params.Eye);
            else
                figure(fh);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NIFToolbarUIFigure = uifigure('Visible', 'off');
            app.NIFToolbarUIFigure.Name = 'NIF Toolbar';
            app.NIFToolbarUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.NIFToolbarUIFigure.Tag = 'NTB';
            app.GridLayout = uigridlayout(app.NIFToolbarUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Button_User = uibutton(app.Panel, 'push');
            app.Button_User.Icon = 'Users.png';
            app.Button_User.Tooltip = {'Select User'};
            app.Button_User.Text = '';
            app.Button_Experiment = uibutton(app.Panel, 'push');
            app.Button_Experiment.Icon = 'Experiment.png';
            app.Button_Experiment.Text = '';
            app.Button_Subject = uibutton(app.Panel, 'push');
            app.Button_Subject.Icon = 'Subject.png';
            app.Button_Subject.Text = '';
            app.DropDown_User = uidropdown(app.Panel);
            app.DropDown_User.Items = {'Select User'};
            app.DropDown_User.ValueChangedFcn = createCallbackFcn(app, @NTB_Session_User, true);
            app.DropDown_User.Value = 'Select User';
            app.DropDown_Subject = uidropdown(app.Panel);
            app.DropDown_Subject.Items = {'Select Subject'};
            app.DropDown_Subject.Value = 'Select Subject';
            app.DropDown_Experiment = uidropdown(app.Panel);
            app.DropDown_Experiment.Items = {'Select Experiment'};
            app.DropDown_Experiment.Value = 'Select Experiment';
            app.Button_Parameters = uibutton(app.Panel, 'push');
            app.Button_Parameters.ButtonPushedFcn = createCallbackFcn(app, @LoadParams, true);
            app.Button_Parameters.Icon = 'Settings.png';
            app.Button_Parameters.Text = '';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.DropDown_Parameters = uidropdown(app.Panel);
            app.DropDown_Parameters.Items = {'Select Parameters'};
            app.DropDown_Parameters.ValueChangedFcn = createCallbackFcn(app, @NTB_Session_Params, true);
            app.DropDown_Parameters.Value = 'Select Parameters';
            app.Button_Experiment_2 = uibutton(app.Panel, 'push');
            app.Button_Experiment_2.Icon = 'Calendar.png';
            app.Button_Experiment_2.Text = '';
            app.DropDown_Experiment_2 = uidropdown(app.Panel);
            app.DropDown_Experiment_2.Items = {'Select Session'};
            app.DropDown_Experiment_2.Value = 'Select Session';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.fMRITab = uitab(app.TabGroup);
            app.fMRITab.Title = 'fMRI';
            app.Button_ScannerPulse = uibutton(app.fMRITab, 'push');
            app.Button_ScannerPulse.Icon = 'EPI.png';
            app.Button_ScannerPulse.Tooltip = {'Scanner pulse settings'};
            app.Button_ScannerPulse.Text = '';
            app.EphysTab = uitab(app.TabGroup);
            app.EphysTab.Title = 'Ephys';
            app.Button_OpenEPhys = uibutton(app.EphysTab, 'push');
            app.Button_OpenEPhys.Icon = 'OpenEphys.png';
            app.Button_OpenEPhys.Tooltip = {'Open OpenEphys Settings'};
            app.Button_OpenEPhys.Text = '';
            app.Button_TDT = uibutton(app.EphysTab, 'push');
            app.Button_TDT.Icon = 'TDT.png';
            app.Button_TDT.Tooltip = {'Open TDT Settings'};
            app.Button_TDT.Text = '';
            app.Button_Photodiode = uibutton(app.EphysTab, 'push');
            app.Button_Photodiode.Icon = 'Photodiode.png';
            app.Button_Photodiode.Tooltip = {'Open photodiode settings'};
            app.Button_Photodiode.Text = '';
            app.Button_Optitrack = uibutton(app.EphysTab, 'push');
            app.Button_Optitrack.Icon = 'Motive.png';
            app.Button_Optitrack.Tooltip = {'Open head tracking settings'};
            app.Button_Optitrack.Text = '';
            app.Button_Stimulation = uibutton(app.EphysTab, 'push');
            app.Button_Stimulation.Icon = 'Stimulation.png';
            app.Button_Stimulation.Tooltip = {'Open head tracking settings'};
            app.Button_Stimulation.Text = '';
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.Button_Sterescopic = uibutton(app.DisplayTab, 'push');
            app.Button_Sterescopic.Icon = 'Stereoscopic.png';
            app.Button_Sterescopic.Tooltip = {'Toggle stereoscopic 3D presentation'};
            app.Button_Sterescopic.Text = '';
            app.Button_DomeProjection = uibutton(app.DisplayTab, 'push');
            app.Button_DomeProjection.Icon = 'Dome.png';
            app.Button_DomeProjection.Tooltip = {'Hemispheric projection dispay settings'};
            app.Button_DomeProjection.Text = '';
            app.Button_Blender = uibutton(app.DisplayTab, 'push');
            app.Button_Blender.Icon = 'Blender.png';
            app.Button_Blender.Tooltip = {'Open Blender communication settings'};
            app.Button_Blender.Text = '';
            app.Button_Unity = uibutton(app.DisplayTab, 'push');
            app.Button_Unity.Icon = 'Unity.png';
            app.Button_Unity.Tooltip = {'Open Unity communication settings'};
            app.Button_Unity.Text = '';
            app.CalibrateTab = uitab(app.TabGroup);
            app.CalibrateTab.Title = 'Calibrate';
            app.Button_GammaCorrect = uibutton(app.CalibrateTab, 'push');
            app.Button_GammaCorrect.Icon = 'GammaCorrect.png';
            app.Button_GammaCorrect.Tooltip = {'Edit and apply Gamma correction olor look up table (CLUTs)'};
            app.Button_GammaCorrect.Text = '';
            app.Button_DisplayGrid = uibutton(app.CalibrateTab, 'push');
            app.Button_DisplayGrid.Icon = 'Cal.png';
            app.Button_DisplayGrid.Tooltip = {'Display calibration grid on subject''s display'};
            app.Button_DisplayGrid.Text = '';
            app.Button_DebugMode = uibutton(app.CalibrateTab, 'push');
            app.Button_DebugMode.Icon = 'Debug.png';
            app.Button_DebugMode.Tooltip = {'Toggle debug mode popups and warnings'};
            app.Button_DebugMode.Text = '';
            app.Button_RewardCal = uibutton(app.CalibrateTab, 'push');
            app.Button_RewardCal.Icon = 'Liquid.png';
            app.Button_RewardCal.Tooltip = {'Run liquid reward calibration sequence'};
            app.Button_RewardCal.Text = '';
            app.Button_LumCal = uibutton(app.CalibrateTab, 'push');
            app.Button_LumCal.Icon = 'Sleep.png';
            app.Button_LumCal.Tooltip = {'Run display luminance measurement'};
            app.Button_LumCal.Text = '';
            app.Button_ScannerCal = uibutton(app.CalibrateTab, 'push');
            app.Button_ScannerCal.Icon = 'EPI.png';
            app.Button_ScannerCal.Tooltip = {'Scanner pulse settings'};
            app.Button_ScannerCal.Text = '';
            app.Button_Joystick = uibutton(app.CalibrateTab, 'push');
            app.Button_Joystick.Icon = 'Joystick.png';
            app.Button_Joystick.Tooltip = {'Joystick / lever press settings'};
            app.Button_Joystick.Text = '';
            app.Button_DataPixx_2 = uibutton(app.CalibrateTab, 'push');
            app.Button_DataPixx_2.Icon = 'DataPixx.png';
            app.Button_DataPixx_2.Tooltip = {'Open DataPixx Settings GUI'};
            app.Button_DataPixx_2.Text = '';
            app.Button_NationalInstruments_2 = uibutton(app.CalibrateTab, 'push');
            app.Button_NationalInstruments_2.Icon = 'NationalInstruments.png';
            app.Button_NationalInstruments_2.Tooltip = {'Open National Instruments documentation'};
            app.Button_NationalInstruments_2.Text = '';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Button_HelpNIF = uibutton(app.HelpTab, 'push');
            app.Button_HelpNIF.Icon = 'NIFmagnetic.png';
            app.Button_HelpNIF.Tooltip = {'Open documentation'};
            app.Button_HelpNIF.Text = '';
            app.Button_HelpGit = uibutton(app.HelpTab, 'push');
            app.Button_HelpGit.Icon = 'Github.png';
            app.Button_HelpGit.Tooltip = {'Open documentation'};
            app.Button_HelpGit.Text = '';
            app.Button_HelpReadTheDocs = uibutton(app.HelpTab, 'push');
            app.Button_HelpReadTheDocs.Icon = 'Display.png';
            app.Button_HelpReadTheDocs.Tooltip = {'Open documentation'};
            app.Button_HelpReadTheDocs.Text = '';
            app.Button_HelpDataPixx = uibutton(app.HelpTab, 'push');
            app.Button_HelpDataPixx.Icon = 'DataPixx.png';
            app.Button_HelpDataPixx.Tooltip = {'Open documentation'};
            app.Button_HelpDataPixx.Text = '';
            app.Button_HelpPsychToolbox = uibutton(app.HelpTab, 'push');
            app.Button_HelpPsychToolbox.Icon = 'Display.png';
            app.Button_HelpPsychToolbox.Tooltip = {'Open documentation'};
            app.Button_HelpPsychToolbox.Text = '';
            app.Button_HelpTDT = uibutton(app.HelpTab, 'push');
            app.Button_HelpTDT.Icon = 'TDT.png';
            app.Button_HelpTDT.Tooltip = {'Open documentation'};
            app.Button_HelpTDT.Text = '';
            app.Button_NationalInstruments = uibutton(app.HelpTab, 'push');
            app.Button_NationalInstruments.Icon = 'NationalInstruments.png';
            app.Button_NationalInstruments.Tooltip = {'Open National Instruments documentation'};
            app.Button_NationalInstruments.Text = '';
            app.Button_NationalInstruments_3 = uibutton(app.HelpTab, 'push');
            app.Button_NationalInstruments_3.Icon = 'ReadTheDocs.png';
            app.Button_NationalInstruments_3.Tooltip = {'Open National Instruments documentation'};
            app.Button_NationalInstruments_3.Text = '';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.ActionsTab = uitab(app.TabGroup2);
            app.ActionsTab.Title = 'Actions';
            app.Button_RunExperiment = uibutton(app.ActionsTab, 'push');
            app.Button_RunExperiment.Icon = 'Play.png';
            app.Button_RunExperiment.Tooltip = {'Run the currently selected experiment'};
            app.Button_RunExperiment.Text = '';
            app.Button_StopExperiment = uibutton(app.ActionsTab, 'push');
            app.Button_StopExperiment.Icon = 'Exit.png';
            app.Button_StopExperiment.Tooltip = {'Stop the currently running experiment'};
            app.Button_StopExperiment.Text = '';
            app.Button_ManualReward = uibutton(app.ActionsTab, 'push');
            app.Button_ManualReward.Icon = 'Liquid.png';
            app.Button_ManualReward.Tooltip = {'Manual liquid reward delivery'};
            app.Button_ManualReward.Text = '';
            app.Button_RunEyeCal = uibutton(app.ActionsTab, 'push');
            app.Button_RunEyeCal.Icon = 'Eye.png';
            app.Button_RunEyeCal.Tooltip = {'Run eye tracking calibration'};
            app.Button_RunEyeCal.Text = '';
            app.Button_Penalty = uibutton(app.ActionsTab, 'push');
            app.Button_Penalty.Icon = 'Penalty.png';
            app.Button_Penalty.Tooltip = {'Give penalty'};
            app.Button_Penalty.Text = '';
            app.Button_PlayAudio = uibutton(app.ActionsTab, 'push');
            app.Button_PlayAudio.Icon = 'SpeakerOn.png';
            app.Button_PlayAudio.Tooltip = {'Play audio'};
            app.Button_PlayAudio.Text = '';
            app.Button_PlayMovie = uibutton(app.ActionsTab, 'push');
            app.Button_PlayMovie.Icon = 'Movie.png';
            app.Button_PlayMovie.Tooltip = {'Play a movie'};
            app.Button_PlayMovie.Text = '';
            app.Button_LightsOut = uibutton(app.ActionsTab, 'push');
            app.Button_LightsOut.Icon = 'Sleep.png';
            app.Button_LightsOut.Tooltip = {'Dim the screen for a timeout'};
            app.Button_LightsOut.Text = '';
            app.Button_Finish = uibutton(app.ActionsTab, 'push');
            app.Button_Finish.Icon = 'Finish.png';
            app.Button_Finish.Tooltip = {'Dim the screen for a timeout'};
            app.Button_Finish.Text = '';
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.Button_Display = uibutton(app.SettingsTab, 'push');
            app.Button_Display.ButtonPushedFcn = createCallbackFcn(app, @Button_DisplayPushed, true);
            app.Button_Display.Icon = 'Display.png';
            app.Button_Display.Tooltip = {'Open Display settings GUI'};
            app.Button_Display.Text = '';
            app.Button_DataPixx = uibutton(app.SettingsTab, 'push');
            app.Button_DataPixx.ButtonPushedFcn = createCallbackFcn(app, @Button_DataPixxPushed, true);
            app.Button_DataPixx.Icon = 'DataPixx.png';
            app.Button_DataPixx.Tooltip = {'Open DataPixx Settings GUI'};
            app.Button_DataPixx.Text = '';
            app.Button_EyeTracking = uibutton(app.SettingsTab, 'push');
            app.Button_EyeTracking.ButtonPushedFcn = createCallbackFcn(app, @Button_EyeTrackingPushed, true);
            app.Button_EyeTracking.Icon = 'Eye.png';
            app.Button_EyeTracking.Tooltip = {'Open EyeTracker Settings GUI'};
            app.Button_EyeTracking.Text = '';
            app.Button_Reward = uibutton(app.SettingsTab, 'push');
            app.Button_Reward.ButtonPushedFcn = createCallbackFcn(app, @Button_RewardPushed, true);
            app.Button_Reward.Icon = 'Liquid.png';
            app.Button_Reward.Tooltip = {'Open Reward Settings GUI'};
            app.Button_Reward.Text = '';
            app.Button_ExpSettings = uibutton(app.SettingsTab, 'push');
            app.Button_ExpSettings.Icon = 'Settings.png';
            app.Button_ExpSettings.Tooltip = {'Open settings GUI for currently selected experiment'};
            app.Button_ExpSettings.Text = '';
            app.Button_Save = uibutton(app.SettingsTab, 'push');
            app.Button_Save.Icon = 'Save.png';
            app.Button_Save.Tooltip = {'Save current settings'};
            app.Button_Save.Text = '';
            app.Button_Transfer = uibutton(app.SettingsTab, 'push');
            app.Button_Transfer.Icon = 'Transfer.png';
            app.Button_Transfer.Tooltip = {'Data transfer settings'};
            app.Button_Transfer.Text = '';
            app.Button_GitHub = uibutton(app.SettingsTab, 'push');
            app.Button_GitHub.Icon = 'Github.png';
            app.Button_GitHub.Tooltip = {'Install updates from GitHub repository'};
            app.Button_GitHub.Text = '';
            app.Button_Keyboard = uibutton(app.SettingsTab, 'push');
            app.Button_Keyboard.Icon = 'Keyboard.png';
            app.Button_Keyboard.Tooltip = {'Keyboard shortcut settings'};
            app.Button_Keyboard.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.EyeTab = uitab(app.TabGroup3);
            app.EyeTab.Title = 'Eye';
            app.Button_ExpSettings_4 = uibutton(app.EyeTab, 'push');
            app.Button_ExpSettings_4.Icon = 'Settings.png';
            app.Button_ExpSettings_4.Tooltip = {'Open settings GUI for currently selected experiment'};
            app.Button_ExpSettings_4.Text = '';
            app.LeverTab = uitab(app.TabGroup3);
            app.LeverTab.Title = 'Lever';
            app.Lever1LampLabel = uilabel(app.LeverTab);
            app.Lever1LampLabel.Text = 'Lever 1';
            app.Lever1Lamp = uilamp(app.LeverTab);
            app.Lever2LampLabel = uilabel(app.LeverTab);
            app.Lever2LampLabel.Text = 'Lever 2';
            app.Lever2Lamp = uilamp(app.LeverTab);
            app.UIAxes = uiaxes(app.LeverTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button_ExpSettings_3 = uibutton(app.LeverTab, 'push');
            app.Button_ExpSettings_3.Icon = 'Settings.png';
            app.Button_ExpSettings_3.Tooltip = {'Open settings GUI for currently selected experiment'};
            app.Button_ExpSettings_3.Text = '';
            app.RewardTab = uitab(app.TabGroup3);
            app.RewardTab.Title = 'Reward';
            app.Button_ExpSettings_2 = uibutton(app.RewardTab, 'push');
            app.Button_ExpSettings_2.Icon = 'Settings.png';
            app.Button_ExpSettings_2.Tooltip = {'Open settings GUI for currently selected experiment'};
            app.Button_ExpSettings_2.Text = '';
            app.SessionTextAreaLabel = uilabel(app.RewardTab);
            app.SessionTextAreaLabel.Text = 'Session';
            app.SessionTextArea = uitextarea(app.RewardTab);
            app.SessionTextArea.Value = {'245'};
            app.ResetButton = uibutton(app.RewardTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.RunTextAreaLabel = uilabel(app.RewardTab);
            app.RunTextAreaLabel.Text = 'Run';
            app.RunTextArea = uitextarea(app.RewardTab);
            app.RunTextArea.Value = {'34'};
            app.ResetButton_2 = uibutton(app.RewardTab, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.ManualmsSliderLabel = uilabel(app.RewardTab);
            app.ManualmsSliderLabel.Text = 'Manual (ms)';
            app.ManualmsSlider = uislider(app.RewardTab);
            app.ManualmsSlider_2Label = uilabel(app.RewardTab);
            app.ManualmsSlider_2Label.Text = 'Manual (ms)';
            app.ManualmsSlider_2 = uislider(app.RewardTab);
            app.SessionTab = uitab(app.TabGroup3);
            app.SessionTab.Title = 'Session';
            app.RunSpinnerLabel = uilabel(app.SessionTab);
            app.RunSpinnerLabel.Text = 'Run #';
            app.RunSpinner = uispinner(app.SessionTab);
            app.BlockSpinnerLabel = uilabel(app.SessionTab);
            app.BlockSpinnerLabel.Text = 'Block #';
            app.BlockSpinner = uispinner(app.SessionTab);
            % Show the figure after all components are created
            app.NIFToolbarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NIF_Toolbar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NIFToolbarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NIFToolbarUIFigure)
        end
    end
end