classdef NTB_DatapixxSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NTBDatapixxSettingsUIFigure     matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        VideoButton                     matlab.ui.control.Button
        AudioButton                     matlab.ui.control.Button
        DPxToolsInstalled               matlab.ui.control.CheckBox
        DPxconnected                    matlab.ui.control.CheckBox
        UseEyeLinkinterface             matlab.ui.control.CheckBox
        TDT2DB25                        matlab.ui.control.CheckBox
        Lamp_DPxInstalled               matlab.ui.control.Lamp
        Lamp_DPxConnected               matlab.ui.control.Lamp
        Lamp_TDTconnected               matlab.ui.control.Lamp
        Lamp_EyeLinkInterface           matlab.ui.control.Lamp
        OptionsPanel                    matlab.ui.container.Panel
        Button_Docs                     matlab.ui.control.Button
        Button_Save                     matlab.ui.control.Button
        Button_Load                     matlab.ui.control.Button
        Button_Close                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DAQTab                          matlab.ui.container.Tab
        AnalogINPanel                   matlab.ui.container.Panel
        ADCrateHzEditFieldLabel         matlab.ui.control.Label
        ADCrateHzEditField              matlab.ui.control.EditField
        AI_ch0                          matlab.ui.control.NumericEditField
        AIassign_ch0                    matlab.ui.control.DropDown
        AI_ch1                          matlab.ui.control.NumericEditField
        AIassign_ch1                    matlab.ui.control.DropDown
        AI_ch3                          matlab.ui.control.NumericEditField
        AIassign_ch3                    matlab.ui.control.DropDown
        AIassign_ch2                    matlab.ui.control.DropDown
        AI_ch2                          matlab.ui.control.NumericEditField
        AI_ch7                          matlab.ui.control.NumericEditField
        AIassign_ch7                    matlab.ui.control.DropDown
        AIassign_ch6                    matlab.ui.control.DropDown
        AI_ch6                          matlab.ui.control.NumericEditField
        AIassign_ch5                    matlab.ui.control.DropDown
        AIassign_ch4                    matlab.ui.control.DropDown
        AI_ch4                          matlab.ui.control.NumericEditField
        AI_ch5                          matlab.ui.control.NumericEditField
        AI_ch15                         matlab.ui.control.NumericEditField
        AI_ch14                         matlab.ui.control.NumericEditField
        AIassign_ch14                   matlab.ui.control.DropDown
        AIassign_ch15                   matlab.ui.control.DropDown
        AIassign_ch13                   matlab.ui.control.DropDown
        AI_ch13                         matlab.ui.control.NumericEditField
        AI_ch12                         matlab.ui.control.NumericEditField
        AIassign_ch12                   matlab.ui.control.DropDown
        AIassign_ch11                   matlab.ui.control.DropDown
        AI_ch11                         matlab.ui.control.NumericEditField
        AI_ch10                         matlab.ui.control.NumericEditField
        AIassign_ch10                   matlab.ui.control.DropDown
        AIassign_ch9                    matlab.ui.control.DropDown
        AI_ch9                          matlab.ui.control.NumericEditField
        AI_ch8                          matlab.ui.control.NumericEditField
        AIassign_ch8                    matlab.ui.control.DropDown
        DigitalINPanel                  matlab.ui.container.Panel
        DI_ch2                          matlab.ui.control.NumericEditField
        DI_ch4                          matlab.ui.control.NumericEditField
        DI_ch5                          matlab.ui.control.NumericEditField
        DI_ch6                          matlab.ui.control.NumericEditField
        DI_ch8                          matlab.ui.control.NumericEditField
        DI_ch9                          matlab.ui.control.NumericEditField
        DI_ch10                         matlab.ui.control.NumericEditField
        DIassign_ch2                    matlab.ui.control.DropDown
        DIassign_ch4                    matlab.ui.control.DropDown
        DIassign_ch5                    matlab.ui.control.DropDown
        DIassign_ch6                    matlab.ui.control.DropDown
        DIassign_ch8                    matlab.ui.control.DropDown
        DIassign_ch9                    matlab.ui.control.DropDown
        DIassign_ch10                   matlab.ui.control.DropDown
        DI_ch11                         matlab.ui.control.NumericEditField
        DIassign_ch11                   matlab.ui.control.DropDown
        DI_ch12                         matlab.ui.control.NumericEditField
        DIassign_ch12                   matlab.ui.control.DropDown
        DI_ch13                         matlab.ui.control.NumericEditField
        DIassign_ch13                   matlab.ui.control.DropDown
        DI_ch14                         matlab.ui.control.NumericEditField
        DIassign_ch14                   matlab.ui.control.DropDown
        DI_ch15                         matlab.ui.control.NumericEditField
        DIassign_ch15                   matlab.ui.control.DropDown
        DI_ch0                          matlab.ui.control.NumericEditField
        DIassign_ch0                    matlab.ui.control.DropDown
        DIassign_ch1                    matlab.ui.control.DropDown
        DI_ch1                          matlab.ui.control.NumericEditField
        DI_ch3                          matlab.ui.control.NumericEditField
        DIassign_ch3                    matlab.ui.control.DropDown
        DIassign_ch7                    matlab.ui.control.DropDown
        DI_ch7                          matlab.ui.control.NumericEditField
        DI_ch16                         matlab.ui.control.NumericEditField
        DI_ch18                         matlab.ui.control.NumericEditField
        DI_ch19                         matlab.ui.control.NumericEditField
        DI_ch20                         matlab.ui.control.NumericEditField
        DIassign_ch16                   matlab.ui.control.DropDown
        DIassign_ch18                   matlab.ui.control.DropDown
        DIassign_ch19                   matlab.ui.control.DropDown
        DIassign_ch20                   matlab.ui.control.DropDown
        DI_ch21                         matlab.ui.control.NumericEditField
        DIassign_ch21                   matlab.ui.control.DropDown
        DI_ch22                         matlab.ui.control.NumericEditField
        DIassign_ch22                   matlab.ui.control.DropDown
        DI_ch23                         matlab.ui.control.NumericEditField
        DIassign_ch23                   matlab.ui.control.DropDown
        DIassign_ch17                   matlab.ui.control.DropDown
        DI_ch17                         matlab.ui.control.NumericEditField
        DigitalOUTPanel                 matlab.ui.container.Panel
        DO_ch2                          matlab.ui.control.NumericEditField
        DO_ch4                          matlab.ui.control.NumericEditField
        DO_ch5                          matlab.ui.control.NumericEditField
        DO_ch6                          matlab.ui.control.NumericEditField
        DO_ch8                          matlab.ui.control.NumericEditField
        DO_ch9                          matlab.ui.control.NumericEditField
        DO_ch10                         matlab.ui.control.NumericEditField
        DOassign_ch2                    matlab.ui.control.DropDown
        DOassign_ch4                    matlab.ui.control.DropDown
        DOassign_ch5                    matlab.ui.control.DropDown
        DOassign_ch6                    matlab.ui.control.DropDown
        DOassign_ch8                    matlab.ui.control.DropDown
        DOassign_ch9                    matlab.ui.control.DropDown
        DOassign_ch10                   matlab.ui.control.DropDown
        DO_ch11                         matlab.ui.control.NumericEditField
        DOassign_ch11                   matlab.ui.control.DropDown
        DO_ch12                         matlab.ui.control.NumericEditField
        DOassign_ch12                   matlab.ui.control.DropDown
        DO_ch13                         matlab.ui.control.NumericEditField
        DOassign_ch13                   matlab.ui.control.DropDown
        DO_ch14                         matlab.ui.control.NumericEditField
        DOassign_ch14                   matlab.ui.control.DropDown
        DO_ch15                         matlab.ui.control.NumericEditField
        DOassign_ch15                   matlab.ui.control.DropDown
        DO_ch0                          matlab.ui.control.NumericEditField
        DOassign_ch0                    matlab.ui.control.DropDown
        DOassign_ch1                    matlab.ui.control.DropDown
        DO_ch1                          matlab.ui.control.NumericEditField
        DO_ch3                          matlab.ui.control.NumericEditField
        DOassign_ch3                    matlab.ui.control.DropDown
        DOassign_ch7                    matlab.ui.control.DropDown
        DO_ch7                          matlab.ui.control.NumericEditField
        DO_ch16                         matlab.ui.control.NumericEditField
        DO_ch18                         matlab.ui.control.NumericEditField
        DO_ch19                         matlab.ui.control.NumericEditField
        DO_ch20                         matlab.ui.control.NumericEditField
        DOassign_ch16                   matlab.ui.control.DropDown
        DOassign_ch18                   matlab.ui.control.DropDown
        DOassign_ch19                   matlab.ui.control.DropDown
        DOassign_ch20                   matlab.ui.control.DropDown
        DO_ch21                         matlab.ui.control.NumericEditField
        DOassign_ch21                   matlab.ui.control.DropDown
        DO_ch22                         matlab.ui.control.NumericEditField
        DOassign_ch22                   matlab.ui.control.DropDown
        DO_ch23                         matlab.ui.control.NumericEditField
        DOassign_ch23                   matlab.ui.control.DropDown
        DOassign_ch17                   matlab.ui.control.DropDown
        DO_ch17                         matlab.ui.control.NumericEditField
        AnalogOUTPanel                  matlab.ui.container.Panel
        DACrateHzEditFieldLabel         matlab.ui.control.Label
        DACrateHzEditField              matlab.ui.control.EditField
        AO_ch0                          matlab.ui.control.NumericEditField
        AOassign_ch0                    matlab.ui.control.DropDown
        AO_ch1                          matlab.ui.control.NumericEditField
        AOassign_ch1                    matlab.ui.control.DropDown
        AO_ch3                          matlab.ui.control.NumericEditField
        AOassign_ch3                    matlab.ui.control.DropDown
        AOassign_ch2                    matlab.ui.control.DropDown
        AO_ch2                          matlab.ui.control.NumericEditField
        PsychDPxTab                     matlab.ui.container.Tab
        PsychDPxVerbosityDropDownLabel  matlab.ui.control.Label
        PsychDPxVerbosityDropDown       matlab.ui.control.DropDown
        DummymodeCheckBox               matlab.ui.control.CheckBox
        LogonsettimestampsDropDownLabel  matlab.ui.control.Label
        LogonsettimestampsDropDown      matlab.ui.control.DropDown
        DatapixxvideomodeDropDownLabel  matlab.ui.control.Label
        DatapixxvideomodeDropDown       matlab.ui.control.DropDown
        VideostereobluelineCheckBox     matlab.ui.control.CheckBox
        VideoscanningbacklightCheckBox  matlab.ui.control.CheckBox
        VideohorizontalsplitDropDownLabel  matlab.ui.control.Label
        VideohorizontalsplitDropDown    matlab.ui.control.DropDown
        VideoverticalstereoDropDownLabel  matlab.ui.control.Label
        VideoverticalstereoDropDown     matlab.ui.control.DropDown
        TestTab                         matlab.ui.container.Tab
        ConnectButton                   matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        AnalogTab                       matlab.ui.container.Tab
        VoltagerangeVSpinnerLabel       matlab.ui.control.Label
        VoltagerangeVSpinner            matlab.ui.control.Spinner
        WaveformDropDownLabel           matlab.ui.control.Label
        WaveformDropDown                matlab.ui.control.DropDown
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        DigitalTab                      matlab.ui.container.Tab
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        Lamp_8                          matlab.ui.control.Lamp
        Lamp_9                          matlab.ui.control.Lamp
        Lamp_10                         matlab.ui.control.Lamp
        Lamp_11                         matlab.ui.control.Lamp
        Lamp_12                         matlab.ui.control.Lamp
        Lamp_13                         matlab.ui.control.Lamp
        Lamp_14                         matlab.ui.control.Lamp
        Lamp_15                         matlab.ui.control.Lamp
        Lamp_16                         matlab.ui.control.Lamp
        Lamp_17                         matlab.ui.control.Lamp
        Lamp_18                         matlab.ui.control.Lamp
        Lamp_19                         matlab.ui.control.Lamp
        Lamp_20                         matlab.ui.control.Lamp
        Lamp_21                         matlab.ui.control.Lamp
        Lamp_22                         matlab.ui.control.Lamp
        Lamp_23                         matlab.ui.control.Lamp
        Lamp_24                         matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        DigitalINLabel                  matlab.ui.control.Label
        DigitalOUTLabel                 matlab.ui.control.Label
        Button                          matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        Button_5                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        Button_7                        matlab.ui.control.StateButton
        Button_8                        matlab.ui.control.StateButton
        Button_9                        matlab.ui.control.StateButton
        Button_10                       matlab.ui.control.StateButton
        Button_11                       matlab.ui.control.StateButton
        Button_12                       matlab.ui.control.StateButton
        Button_13                       matlab.ui.control.StateButton
        Button_14                       matlab.ui.control.StateButton
        Button_15                       matlab.ui.control.StateButton
        Button_16                       matlab.ui.control.StateButton
        Button_25                       matlab.ui.control.StateButton
        Button_26                       matlab.ui.control.StateButton
        Button_27                       matlab.ui.control.StateButton
        Button_28                       matlab.ui.control.StateButton
        Button_29                       matlab.ui.control.StateButton
        Button_30                       matlab.ui.control.StateButton
        Button_31                       matlab.ui.control.StateButton
        Button_32                       matlab.ui.control.StateButton
        ResetButton_3                   matlab.ui.control.Button
        DigitalINplotPanel              matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        DigitalINchannelDropDownLabel   matlab.ui.control.Label
        DigitalINchannelDropDown        matlab.ui.control.DropDown
        TimeperiodsEditFieldLabel       matlab.ui.control.Label
        TimeperiodsEditField            matlab.ui.control.NumericEditField
        ResetButton                     matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        DPx                 % DataPixx parameters field
        NTBapp
        OffOnColors         % Channel status colors
        RedGreen            % Button status colors
        ChOffOnColor        % Channel status colors
    end
    
    methods (Access = private)
        
        %================= Update GUI values
        function results = UpdateValues(app)
            
            %===== Update top panel settings
            app.OffOnColors                 = app.NTBapp.OffOnColors;
            app.RedGreen                    = {[1,0,0],[0,1,0]};
            app.VideoButton.BackgroundColor = app.OffOnColors{app.DPx.Settings.UseVideo+1};
            app.AudioButton.BackgroundColor = app.OffOnColors{app.DPx.Settings.UseAudio+1};
            if ~exist('Datapixx.m','file')
                app.DPx.Settings.Installed = 0;
                app.DPx.Settings.Connected = 0;
            else
                app.DPx.Settings.Installed = 1;
                try Datapixx('Open')
                    app.DPx.Settings.Connected = 1;
                catch
                    app.DPx.Settings.Connected = 0;
                end
            end
            app.DPxconnected.Value          = app.DPx.Settings.Connected;
            app.DPxToolsInstalled.Value     = app.DPx.Settings.Installed;
            app.TDT2DB25.Value              = app.DPx.Settings.TDTconnected;
            app.UseEyeLinkinterface.Value   = app.DPx.Settings.EyeLinkInterface;  
            app.Lamp_DPxConnected.Color     = app.RedGreen{app.DPx.Settings.Connected+1};
            app.Lamp_DPxInstalled.Color     = app.RedGreen{app.DPx.Settings.Installed+1};
            app.Lamp_TDTconnected.Color     = app.RedGreen{app.DPx.Settings.TDTconnected+1};
            app.Lamp_EyeLinkInterface.Color = app.RedGreen{app.DPx.Settings.EyeLinkInterface+1};
            
            %===== Update channel pannels
            app.ADCrateHzEditField.Value    = num2str(app.DPx.AnalogIn.Rate);
            app.DACrateHzEditField.Value    = num2str(app.DPx.AnalogOut.Rate);
            app.ChOffOnColor                = {[0.65,0.65,0.65],[0,1,0]};
            for ch = app.DPx.AnalogIn.Ch
                ChH                 = eval(sprintf('app.AI_ch%d;', ch));
                ChAllocated         = ~strcmp(app.DPx.AnalogIn.Options{app.DPx.AnalogIn.Assign(ch+1)}, 'None');
                ChH.BackgroundColor = app.ChOffOnColor{ChAllocated+1};
                ChOpt               = eval(sprintf('app.AIassign_ch%d;', ch));
                ChOpt.Items         = app.DPx.AnalogIn.Options;
                ChOpt.Value         = app.DPx.AnalogIn.Options{app.DPx.AnalogIn.Assign(ch+1)};
            end
            for ch = app.DPx.AnalogOut.Ch
                ChH                 = eval(sprintf('app.AO_ch%d;', ch));
                ChAllocated         = ~strcmp(app.DPx.AnalogOut.Options{app.DPx.AnalogOut.Assign(ch+1)}, 'None');
                ChH.BackgroundColor = app.ChOffOnColor{ChAllocated+1};
                ChOpt               = eval(sprintf('app.AOassign_ch%d;', ch));
                ChOpt.Items         = app.DPx.AnalogOut.Options;
                ChOpt.Value         = app.DPx.AnalogOut.Options{app.DPx.AnalogOut.Assign(ch+1)};
            end
            for ch = app.DPx.DigitalIn.Ch
                ChH                 = eval(sprintf('app.DI_ch%d;', ch));
                ChAllocated         = ~strcmp(app.DPx.DigitalIn.Options{app.DPx.DigitalIn.Assign(ch+1)}, 'None');
                ChH.BackgroundColor = app.ChOffOnColor{ChAllocated+1};
                ChOpt               = eval(sprintf('app.DIassign_ch%d;', ch));
                ChOpt.Items         = app.DPx.DigitalIn.Options;
                ChOpt.Value         = app.DPx.DigitalIn.Options{app.DPx.DigitalIn.Assign(ch+1)};
            end
            for ch = app.DPx.DigitalOut.Ch
                ChH                 = eval(sprintf('app.DO_ch%d;', ch));
                ChAllocated         = ~strcmp(app.DPx.DigitalOut.Options{app.DPx.DigitalOut.Assign(ch+1)}, 'None');
                ChH.BackgroundColor = app.ChOffOnColor{ChAllocated+1};
                ChOpt               = eval(sprintf('app.DOassign_ch%d;', ch));
                ChOpt.Items         = app.DPx.DigitalOut.Options;
                ChOpt.Value         = app.DPx.DigitalOut.Options{app.DPx.DigitalOut.Assign(ch+1)};
            end
            
            %===== Update PsychDPx tab
            if app.DPx.Settings.Connected == 0
                app.DummymodeCheckBox.Value = true;
            end


        end
        
        % Check channel option assigment
        function results = CheckChAssign(app, panel, panelName, ch)
            value = eval(sprintf('app.%sassign_ch%d.Value;', panel, ch));
            if strcmp(value, 'None')
                eval(sprintf('app.%s_ch%d.BackgroundColor = app.ChOffOnColor{1};', panel, ch));
            elseif strcmp(value, 'Add new')
                AddOption(app, panel, panelName, ch);
            else
                eval(sprintf('app.%s_ch%d.BackgroundColor = app.ChOffOnColor{2};', panel, ch));
            end
        end
        
        % Add new option to channel assignments
        function results = AddOption(app, panel, panelName, ch)
            Msg = sprintf('Add new channel assignment option to %s panel?', panelName);
            %NewOption = uiconfirm(app.NTBDatapixxSettingsUIFigure, Msg, 'Add option', 'Options',{'Add','Cancel'});
            NewOption = inputdlg(Msg,'Add new option', 1, {''});
            if isempty(NewOption)
                return;
            end
            eval(sprintf('app.DPx.%s.Options{end+1} = ''%s'';', panelName, NewOption{1}));
            UpdateValues(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NTBapp, DPx)
            app.DPx         = DPx;
            app.NTBapp      = NTBapp;
            UpdateValues(app);
            if app.DPx.Settings.Connected == 0
                message = 'Datapixx interface not connected! Please check USB connection and that Datapixx is turned on.';
                uialert(app.NTBDatapixxSettingsUIFigure, message,'Warning','Icon','warning');
            end
            if app.DPx.Settings.Installed == 0
                message = 'Datapixx Toolbox not detected on the Matlab path! Please check version and path info and follow installation instructons.';
                uialert(app.NTBDatapixxSettingsUIFigure, message,'Warning','Icon','warning');
            end
            
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            DataPixxHelpURL = 'http://www.vpixx.com/manuals/psychtoolbox/html/intro.html';
            web(DataPixxHelpURL);
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            app.DPx.Settings.UseVideo = ~app.DPx.Settings.UseVideo;
            app.VideoButton.BackgroundColor = app.OffOnColors{app.DPx.Settings.UseVideo+1};
        end

        % Button pushed function: AudioButton
        function AudioButtonPushed(app, event)
            app.DPx.Settings.UseAudio = ~app.DPx.Settings.UseAudio;
            app.AudioButton.BackgroundColor = app.OffOnColors{app.DPx.Settings.UseAudio+1};
        end

        % Value changed function: TDT2DB25
        function TDT2DB25ValueChanged(app, event)
            value = app.TDT2DB25.Value;
            app.DPx.TDTonDOUT = value;
            app.Lamp_TDTconnected.Color = app.RedGreen{value+1};
            if app.DPx.TDTonDOUT == 1
                app.DPx.DigitalOut.Assign(10:17) = find(~cellfun(@isempty, strfind(app.DPx.DigitalOut.Options, 'TDT port B')));
                app.DPx.DigitalOut.Assign(18:24) = find(~cellfun(@isempty, strfind(app.DPx.DigitalOut.Options, 'TDT port C')));
                for ch = 9:23
                    eval(sprintf('app.DO_ch%d.BackgroundColor = app.ChOffOnColor{2};', ch));
                    DOch = eval(sprintf('app.DOassign_ch%d;', ch));
                    DOch.Value      = app.DPx.DigitalOut.Options{app.DPx.DigitalOut.Assign(ch+1)};
                    DOch.Enable     = 'off';
                    DOch.Tooltip    = 'Digital outs 9-23 in use for TDT communciation';
                end
                
            elseif app.DPx.TDTonDOUT == 0
                for ch = 9:23
                    eval(sprintf('app.DO_ch%d.BackgroundColor = app.ChOffOnColor{1};', ch));
                    DOch = eval(sprintf('app.DOassign_ch%d;', ch));
                    DOch.Value      = app.DPx.DigitalOut.Options{app.DPx.DigitalOut.Assign(ch+1)};
                    DOch.Enable     = 'on';
                    DOch.Tooltip    = '';
                end
            end


        end

        % Value changed function: UseEyeLinkinterface
        function UseEyeLinkinterfaceValueChanged(app, event)
            value = app.UseEyeLinkinterface.Value;
            app.DPx.UseInterface = value;
            app.Lamp_EyeLinkInterface.Color = app.RedGreen{value+1};
            
            if app.DPx.UseInterface == 1
                app.DPx.AnalogIn.Assign(1:6) = 1:6;
                for ch = 1:6
                    eval(sprintf('app.AI_ch%d.BackgroundColor = app.ChOffOnColor{2};', ch));
                    AIch = eval(sprintf('app.AIassign_ch%d;', ch));
                    AIch.Value      = app.DPx.AnalogIn.Options{app.DPx.AnalogIn.Assign(ch+1)};
                    AIch.Enable     = 'off';
                    AIch.Tooltip    = 'Analog inputs 0-5 in use for eye signals';
                end
            elseif app.DPx.UseInterface == 0
                for ch = 1:6
                    eval(sprintf('app.AI_ch%d.BackgroundColor = app.ChOffOnColor{1};', ch));
                    AIch = eval(sprintf('app.AIassign_ch%d;', ch));
                    AIch.Value      = app.DPx.AnalogIn.Options{app.DPx.AnalogIn.Assign(ch+1)};
                    AIch.Enable     = 'on';
                    AIch.Tooltip    = '';
                end
            end
            
        end

        % Button pushed function: Button_Save
        function Button_SavePushed(app, event)
            ParamsFile  = app.NTBapp.Params.File;
            selection   = uiconfirm(app.NTBDisplaySettingsUIFigure, sprintf('Save Datapixx settings to parameter file %s?', ParamsFile), 'Save', 'Options',{'Save','Cancel'}, 'Icon', 'Save.png');
            if strcmp(selection, 'Save')
                DPx     = app.DPx;
                save(ParamsFile, 'DPx','-append');
                uiconfirm(app.NTBDisplaySettingsUIFigure, sprintf('Current Datapixx parameters were written to %s.', ParamsFile), 'Datapixx parameters saved', 'Icon', 'success');
            end
            
        end

        % Button pushed function: Button_Load
        function Button_LoadPushed(app, event)
            selection   = uiconfirm(app.NTBDisplaySettingsUIFigure, 'Load Datapixx settings from a different parameter file?', 'Load parameters', 'Options',{'Load','Cancel'}, 'Icon', 'Transfer.png');
            if strcmp(selection, 'Load')
                [file, path] = uigetfile('NTB*.mat', 'Select params file');
                ParamsFile = fullfile(path, file);
                TempParams = load(ParamsFile);
                app.DPx = TempParams.DPx;
                UpdateValues(app);
            end
        end

        % Button pushed function: Button_Docs
        function Button_DocsPushed(app, event)
            
        end

        % Button pushed function: Button_Close
        function Button_ClosePushed(app, event)
            app.NTBapp.Button_Datapixx.BackgroundColor = app.NTBapp.OffOnColors{1};
            delete(app);
            
        end

        % Value changed function: AIassign_ch0, AIassign_ch1, 
        % AIassign_ch10, AIassign_ch11, AIassign_ch12, 
        % AIassign_ch13, AIassign_ch14, AIassign_ch15, 
        % AIassign_ch2, AIassign_ch3, AIassign_ch4, AIassign_ch5, 
        % AIassign_ch6, AIassign_ch7, AIassign_ch8, AIassign_ch9, 
        % AOassign_ch0, AOassign_ch1, AOassign_ch2, AOassign_ch3, 
        % DIassign_ch0, DIassign_ch1, DIassign_ch10, DIassign_ch11, 
        % DIassign_ch12, DIassign_ch13, DIassign_ch14, 
        % DIassign_ch15, DIassign_ch16, DIassign_ch17, 
        % DIassign_ch18, DIassign_ch19, DIassign_ch2, 
        % DIassign_ch20, DIassign_ch21, DIassign_ch22, 
        % DIassign_ch23, DIassign_ch3, DIassign_ch4, DIassign_ch5, 
        % DIassign_ch6, DIassign_ch7, DIassign_ch8, DIassign_ch9, 
        % DOassign_ch0, DOassign_ch1, DOassign_ch10, DOassign_ch11, 
        % DOassign_ch12, DOassign_ch13, DOassign_ch14, 
        % DOassign_ch15, DOassign_ch16, DOassign_ch17, 
        % DOassign_ch18, DOassign_ch19, DOassign_ch2, 
        % DOassign_ch20, DOassign_ch21, DOassign_ch22, 
        % DOassign_ch23, DOassign_ch3, DOassign_ch4, DOassign_ch5, 
        % DOassign_ch6, DOassign_ch7, DOassign_ch8, DOassign_ch9
        function AIassign_ch0ValueChanged(app, event)
            Source      = event.Source.Tag;
            Channel     = str2double(Source(strfind(Source, '_')+1:end));
            Panel       = Source(1:strfind(Source, '_')-1);
            PanelAbrev  = {'AI','AO','DI','DO'};
            PanelNames  = {'AnalogIn','AnalogOut','DigitalIn','DigitalOut'};
            PanelIndx   = find(~cellfun(@isempty, strfind(PanelAbrev, Panel)));
            eval(sprintf('app.DPx.%s.Assign(%d) = find(~cellfun(@isempty, strfind(app.DPx.%s.Options, event.Value)));', PanelNames{PanelIndx},Channel+1,PanelNames{PanelIndx}));
            CheckChAssign(app, Panel, PanelNames{PanelIndx}, Channel);
        end

        % Close request function: NTBDatapixxSettingsUIFigure
        function NTBDatapixxSettingsUIFigureCloseRequest(app, event)
            app.NTBapp.Button_DataPixx.BackgroundColor = app.NTBapp.OffOnColors{1};
            delete(app);
            
        end

        % Value changed function: ADCrateHzEditField
        function ADCrateHzEditFieldValueChanged(app, event)
            value = app.ADCrateHzEditField.Value;
            
        end

        % Value changed function: DACrateHzEditField
        function DACrateHzEditFieldValueChanged(app, event)
            value = app.DACrateHzEditField.Value;
            
        end

        % Value changed function: DummymodeCheckBox
        function DummymodeCheckBoxValueChanged(app, event)
            value = app.DummymodeCheckBox.Value;
            PsychDataPixx('SetDummyMode', value); 
        end

        % Value changed function: PsychDPxVerbosityDropDown
        function PsychDPxVerbosityDropDownValueChanged(app, event)
            value = app.PsychDPxVerbosityDropDown.Value;
            Mode = find(cellfun(@isempty, strfind(app.PsychDPxVerbosityDropDown.Items, value)))-1;
            PsychDataPixx('Verbosity', Mode);
        end

        % Value changed function: LogonsettimestampsDropDown
        function LogonsettimestampsDropDownValueChanged(app, event)
            value = app.LogonsettimestampsDropDown.Value;
            Mode = find(cellfun(@isempty, strfind(app.LogonsettimestampsDropDown.Items, value)))-1;
            PsychDataPixx('LogOnsetTimestamps', Mode);
        end

        % Value changed function: VideostereobluelineCheckBox
        function VideostereobluelineCheckBoxValueChanged(app, event)
            value = app.VideostereobluelineCheckBox.Value;
            if value == 1
                PsychDataPixx('EnableVideoStereoBlueline');
            elseif value == 0
                PsychDataPixx('DisableVideoStereoBlueline');
            end
        end

        % Value changed function: VideoscanningbacklightCheckBox
        function VideoscanningbacklightCheckBoxValueChanged(app, event)
            value = app.VideoscanningbacklightCheckBox.Value;
            if value == 1
                PsychDataPixx('EnableVideoScanningBacklight');
            elseif value == 0
                PsychDataPixx('DisableVideoScanningBacklight');
            end
        end

        % Value changed function: DatapixxvideomodeDropDown
        function DatapixxvideomodeDropDownValueChanged(app, event)
            value = app.DatapixxvideomodeDropDown.Value;
            
        end

        % Value changed function: VideohorizontalsplitDropDown
        function VideohorizontalsplitDropDownValueChanged(app, event)
            value = app.VideohorizontalsplitDropDown.Value;
            
        end

        % Value changed function: VideoverticalstereoDropDown
        function VideoverticalstereoDropDownValueChanged(app, event)
            value = app.VideoverticalstereoDropDown.Value;
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmpi(selectedTab.Title, 'PsychDPx') && app.DPx.Settings.Installed == 0
                app.TabGroup.SelectedTab = event.OldValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NTBDatapixxSettingsUIFigure = uifigure('Visible', 'off');
            app.NTBDatapixxSettingsUIFigure.Name = 'NTB Datapixx Settings';
            app.NTBDatapixxSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @NTBDatapixxSettingsUIFigureCloseRequest, true);
            app.NTBDatapixxSettingsUIFigure.Tag = 'NTB_Datapixx';
            app.Panel = uipanel(app.NTBDatapixxSettingsUIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Logo_VPixx.png';
            app.VideoButton = uibutton(app.Panel, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Icon = 'Display.png';
            app.VideoButton.Text = 'Video';
            app.AudioButton = uibutton(app.Panel, 'push');
            app.AudioButton.ButtonPushedFcn = createCallbackFcn(app, @AudioButtonPushed, true);
            app.AudioButton.Icon = 'SpeakerOn.png';
            app.AudioButton.Text = 'Audio';
            app.DPxToolsInstalled = uicheckbox(app.Panel);
            app.DPxToolsInstalled.Enable = 'off';
            app.DPxToolsInstalled.Text = 'DataPixx tools installed?';
            app.DPxconnected = uicheckbox(app.Panel);
            app.DPxconnected.Enable = 'off';
            app.DPxconnected.Text = 'DataPixx box connected?';
            app.UseEyeLinkinterface = uicheckbox(app.Panel);
            app.UseEyeLinkinterface.ValueChangedFcn = createCallbackFcn(app, @UseEyeLinkinterfaceValueChanged, true);
            app.UseEyeLinkinterface.Text = 'Use EyeLink interface box?';
            app.TDT2DB25 = uicheckbox(app.Panel);
            app.TDT2DB25.ValueChangedFcn = createCallbackFcn(app, @TDT2DB25ValueChanged, true);
            app.TDT2DB25.Text = 'TDT connected via DB25?';
            app.Lamp_DPxInstalled = uilamp(app.Panel);
            app.Lamp_DPxConnected = uilamp(app.Panel);
            app.Lamp_TDTconnected = uilamp(app.Panel);
            app.Lamp_EyeLinkInterface = uilamp(app.Panel);
            app.OptionsPanel = uipanel(app.NTBDatapixxSettingsUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.Button_Docs = uibutton(app.OptionsPanel, 'push');
            app.Button_Docs.ButtonPushedFcn = createCallbackFcn(app, @Button_DocsPushed, true);
            app.Button_Docs.Icon = 'ReadTheDocs.png';
            app.Button_Docs.Tooltip = {'Open the NTB documentation webpage for DisplaySettings.'};
            app.Button_Docs.Text = '';
            app.Button_Save = uibutton(app.OptionsPanel, 'push');
            app.Button_Save.ButtonPushedFcn = createCallbackFcn(app, @Button_SavePushed, true);
            app.Button_Save.Icon = 'Save.png';
            app.Button_Save.Tooltip = {'Save current display settings to Params file.'};
            app.Button_Save.Text = '';
            app.Button_Load = uibutton(app.OptionsPanel, 'push');
            app.Button_Load.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPushed, true);
            app.Button_Load.Icon = 'Transfer.png';
            app.Button_Load.Tooltip = {'Load display settings from a different parameter file.'};
            app.Button_Load.Text = '';
            app.Button_Close = uibutton(app.OptionsPanel, 'push');
            app.Button_Close.ButtonPushedFcn = createCallbackFcn(app, @Button_ClosePushed, true);
            app.Button_Close.Icon = 'Exit.png';
            app.Button_Close.Tooltip = {'Exit Display Settings GUI without updating Display parameters.'};
            app.Button_Close.Text = '';
            app.TabGroup = uitabgroup(app.NTBDatapixxSettingsUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DAQTab = uitab(app.TabGroup);
            app.DAQTab.Title = 'DAQ';
            app.AnalogINPanel = uipanel(app.DAQTab);
            app.AnalogINPanel.Title = 'Analog IN';
            app.ADCrateHzEditFieldLabel = uilabel(app.AnalogINPanel);
            app.ADCrateHzEditFieldLabel.Text = 'ADC rate (Hz)';
            app.ADCrateHzEditField = uieditfield(app.AnalogINPanel, 'text');
            app.ADCrateHzEditField.ValueChangedFcn = createCallbackFcn(app, @ADCrateHzEditFieldValueChanged, true);
            app.ADCrateHzEditField.Value = '1000';
            app.AI_ch0 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch0.Editable = 'off';
            app.AIassign_ch0 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch0.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch0.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch0.Tag = 'AI_0';
            app.AIassign_ch0.Value = 'None';
            app.AI_ch1 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch1.Tag = 'AI_1';
            app.AI_ch1.Editable = 'off';
            app.AI_ch1.Value = 1;
            app.AIassign_ch1 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch1.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch1.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch1.Tag = 'AI_1';
            app.AIassign_ch1.Value = 'None';
            app.AI_ch3 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch3.Tag = 'AI_3';
            app.AI_ch3.Editable = 'off';
            app.AI_ch3.Value = 3;
            app.AIassign_ch3 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch3.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch3.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch3.Tag = 'AI_3';
            app.AIassign_ch3.Value = 'None';
            app.AIassign_ch2 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch2.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch2.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch2.Tag = 'AI_2';
            app.AIassign_ch2.Value = 'None';
            app.AI_ch2 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch2.Editable = 'off';
            app.AI_ch2.Value = 2;
            app.AI_ch7 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch7.Editable = 'off';
            app.AI_ch7.Value = 7;
            app.AIassign_ch7 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch7.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch7.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch7.Tag = 'AI_7';
            app.AIassign_ch7.Value = 'None';
            app.AIassign_ch6 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch6.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch6.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch6.Tag = 'AI_6';
            app.AIassign_ch6.Value = 'None';
            app.AI_ch6 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch6.Editable = 'off';
            app.AI_ch6.Value = 6;
            app.AIassign_ch5 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch5.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch5.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch5.Tag = 'AI_5';
            app.AIassign_ch5.Value = 'None';
            app.AIassign_ch4 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch4.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch4.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch4.Tag = 'AI_4';
            app.AIassign_ch4.Value = 'None';
            app.AI_ch4 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch4.Editable = 'off';
            app.AI_ch4.Value = 4;
            app.AI_ch5 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch5.Editable = 'off';
            app.AI_ch5.Value = 5;
            app.AI_ch15 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch15.Editable = 'off';
            app.AI_ch15.Value = 15;
            app.AI_ch14 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch14.Editable = 'off';
            app.AI_ch14.Value = 14;
            app.AIassign_ch14 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch14.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch14.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch14.Tag = 'AI_14';
            app.AIassign_ch14.Value = 'None';
            app.AIassign_ch15 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch15.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch15.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch15.Tag = 'AI_15';
            app.AIassign_ch15.Value = 'None';
            app.AIassign_ch13 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch13.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch13.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch13.Tag = 'AI_13';
            app.AIassign_ch13.Value = 'None';
            app.AI_ch13 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch13.Editable = 'off';
            app.AI_ch13.Value = 13;
            app.AI_ch12 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch12.Editable = 'off';
            app.AI_ch12.Value = 12;
            app.AIassign_ch12 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch12.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch12.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch12.Tag = 'AI_12';
            app.AIassign_ch12.Value = 'None';
            app.AIassign_ch11 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch11.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch11.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch11.Tag = 'AI_11';
            app.AIassign_ch11.Value = 'None';
            app.AI_ch11 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch11.Editable = 'off';
            app.AI_ch11.Value = 11;
            app.AI_ch10 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch10.Editable = 'off';
            app.AI_ch10.Value = 10;
            app.AIassign_ch10 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch10.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch10.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch10.Tag = 'AI_10';
            app.AIassign_ch10.Value = 'None';
            app.AIassign_ch9 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch9.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch9.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch9.Tag = 'AI_9';
            app.AIassign_ch9.Value = 'None';
            app.AI_ch9 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch9.Editable = 'off';
            app.AI_ch9.Value = 9;
            app.AI_ch8 = uieditfield(app.AnalogINPanel, 'numeric');
            app.AI_ch8.Editable = 'off';
            app.AI_ch8.Value = 8;
            app.AIassign_ch8 = uidropdown(app.AnalogINPanel);
            app.AIassign_ch8.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AIassign_ch8.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AIassign_ch8.Tag = 'AI_8';
            app.AIassign_ch8.Value = 'None';
            app.DigitalINPanel = uipanel(app.DAQTab);
            app.DigitalINPanel.Title = 'Digital IN';
            app.DI_ch2 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch2.Editable = 'off';
            app.DI_ch2.Value = 2;
            app.DI_ch4 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch4.Editable = 'off';
            app.DI_ch4.Value = 4;
            app.DI_ch5 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch5.Editable = 'off';
            app.DI_ch5.Value = 5;
            app.DI_ch6 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch6.Editable = 'off';
            app.DI_ch6.Value = 6;
            app.DI_ch8 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch8.Editable = 'off';
            app.DI_ch8.Value = 8;
            app.DI_ch9 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch9.Editable = 'off';
            app.DI_ch9.Value = 9;
            app.DI_ch10 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch10.Editable = 'off';
            app.DI_ch10.Value = 10;
            app.DIassign_ch2 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch2.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch2.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch2.Tag = 'DI_2';
            app.DIassign_ch2.Value = 'None';
            app.DIassign_ch4 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch4.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch4.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch4.Tag = 'DI_4';
            app.DIassign_ch4.Value = 'None';
            app.DIassign_ch5 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch5.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch5.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch5.Tag = 'DI_5';
            app.DIassign_ch5.Value = 'None';
            app.DIassign_ch6 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch6.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch6.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch6.Tag = 'DI_5';
            app.DIassign_ch6.Value = 'None';
            app.DIassign_ch8 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch8.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch8.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch8.Tag = 'DI_7';
            app.DIassign_ch8.Value = 'None';
            app.DIassign_ch9 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch9.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch9.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch9.Tag = 'DI_9';
            app.DIassign_ch9.Value = 'None';
            app.DIassign_ch10 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch10.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch10.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch10.Tag = 'DI_10';
            app.DIassign_ch10.Value = 'None';
            app.DI_ch11 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch11.Editable = 'off';
            app.DI_ch11.Value = 11;
            app.DIassign_ch11 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch11.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch11.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch11.Tag = 'DI_11';
            app.DIassign_ch11.Value = 'None';
            app.DI_ch12 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch12.Editable = 'off';
            app.DI_ch12.Value = 12;
            app.DIassign_ch12 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch12.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch12.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch12.Tag = 'DI_12';
            app.DIassign_ch12.Value = 'None';
            app.DI_ch13 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch13.Editable = 'off';
            app.DI_ch13.Value = 13;
            app.DIassign_ch13 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch13.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch13.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch13.Tag = 'DI_13';
            app.DIassign_ch13.Value = 'None';
            app.DI_ch14 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch14.Editable = 'off';
            app.DI_ch14.Value = 14;
            app.DIassign_ch14 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch14.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch14.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch14.Tag = 'DI_14';
            app.DIassign_ch14.Value = 'None';
            app.DI_ch15 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch15.Editable = 'off';
            app.DI_ch15.Value = 15;
            app.DIassign_ch15 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch15.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch15.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch15.Tag = 'DI_15';
            app.DIassign_ch15.Value = 'None';
            app.DI_ch0 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch0.Editable = 'off';
            app.DIassign_ch0 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch0.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch0.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch0.Tag = 'DI_0';
            app.DIassign_ch0.Value = 'None';
            app.DIassign_ch1 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch1.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch1.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch1.Tag = 'DI_1';
            app.DIassign_ch1.Value = 'None';
            app.DI_ch1 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch1.Editable = 'off';
            app.DI_ch1.Value = 1;
            app.DI_ch3 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch3.Editable = 'off';
            app.DI_ch3.Value = 3;
            app.DIassign_ch3 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch3.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch3.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch3.Tag = 'DI_3';
            app.DIassign_ch3.Value = 'None';
            app.DIassign_ch7 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch7.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch7.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch7.Tag = 'DI_6';
            app.DIassign_ch7.Value = 'None';
            app.DI_ch7 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch7.Editable = 'off';
            app.DI_ch7.Value = 7;
            app.DI_ch16 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch16.Editable = 'off';
            app.DI_ch16.Value = 16;
            app.DI_ch18 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch18.Editable = 'off';
            app.DI_ch18.Value = 18;
            app.DI_ch19 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch19.Editable = 'off';
            app.DI_ch19.Value = 19;
            app.DI_ch20 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch20.Editable = 'off';
            app.DI_ch20.Value = 20;
            app.DIassign_ch16 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch16.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch16.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch16.Tag = 'DI_16';
            app.DIassign_ch16.Value = 'None';
            app.DIassign_ch18 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch18.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch18.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch18.Tag = 'DI_18';
            app.DIassign_ch18.Value = 'None';
            app.DIassign_ch19 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch19.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch19.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch19.Tag = 'DI_19';
            app.DIassign_ch19.Value = 'None';
            app.DIassign_ch20 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch20.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch20.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch20.Tag = 'DI_20';
            app.DIassign_ch20.Value = 'None';
            app.DI_ch21 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch21.Editable = 'off';
            app.DI_ch21.Value = 21;
            app.DIassign_ch21 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch21.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch21.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch21.Tag = 'DI_21';
            app.DIassign_ch21.Value = 'None';
            app.DI_ch22 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch22.Editable = 'off';
            app.DI_ch22.Value = 22;
            app.DIassign_ch22 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch22.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch22.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch22.Tag = 'DI_22';
            app.DIassign_ch22.Value = 'None';
            app.DI_ch23 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch23.Editable = 'off';
            app.DI_ch23.Value = 23;
            app.DIassign_ch23 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch23.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch23.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch23.Tag = 'DI_23';
            app.DIassign_ch23.Value = 'None';
            app.DIassign_ch17 = uidropdown(app.DigitalINPanel);
            app.DIassign_ch17.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.DIassign_ch17.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DIassign_ch17.Tag = 'DI_17';
            app.DIassign_ch17.Value = 'None';
            app.DI_ch17 = uieditfield(app.DigitalINPanel, 'numeric');
            app.DI_ch17.Editable = 'off';
            app.DI_ch17.Value = 17;
            app.DigitalOUTPanel = uipanel(app.DAQTab);
            app.DigitalOUTPanel.Title = 'Digital OUT';
            app.DO_ch2 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch2.Editable = 'off';
            app.DO_ch2.Value = 2;
            app.DO_ch4 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch4.Editable = 'off';
            app.DO_ch4.Value = 4;
            app.DO_ch5 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch5.Editable = 'off';
            app.DO_ch5.Value = 5;
            app.DO_ch6 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch6.Editable = 'off';
            app.DO_ch6.Value = 6;
            app.DO_ch8 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch8.Editable = 'off';
            app.DO_ch8.Value = 8;
            app.DO_ch9 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch9.Editable = 'off';
            app.DO_ch9.Value = 9;
            app.DO_ch10 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch10.Editable = 'off';
            app.DO_ch10.Value = 10;
            app.DOassign_ch2 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch2.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch2.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch2.Tag = 'DO_2';
            app.DOassign_ch2.Value = 'None';
            app.DOassign_ch4 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch4.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch4.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch4.Tag = 'DO_4';
            app.DOassign_ch4.Value = 'None';
            app.DOassign_ch5 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch5.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch5.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch5.Tag = 'DO_5';
            app.DOassign_ch5.Value = 'None';
            app.DOassign_ch6 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch6.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch6.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch6.Tag = 'DO_6';
            app.DOassign_ch6.Value = 'None';
            app.DOassign_ch8 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch8.Items = {'LReward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch8.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch8.Tag = 'DO_8';
            app.DOassign_ch8.Value = 'None';
            app.DOassign_ch9 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch9.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch9.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch9.Tag = 'DO_9';
            app.DOassign_ch9.Value = 'None';
            app.DOassign_ch10 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch10.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch10.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch10.Tag = 'DO_10';
            app.DOassign_ch10.Value = 'None';
            app.DO_ch11 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch11.Editable = 'off';
            app.DO_ch11.Value = 11;
            app.DOassign_ch11 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch11.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch11.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch11.Tag = 'DO_11';
            app.DOassign_ch11.Value = 'None';
            app.DO_ch12 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch12.Editable = 'off';
            app.DO_ch12.Value = 12;
            app.DOassign_ch12 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch12.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch12.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch12.Tag = 'DO_12';
            app.DOassign_ch12.Value = 'None';
            app.DO_ch13 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch13.Editable = 'off';
            app.DO_ch13.Value = 13;
            app.DOassign_ch13 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch13.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch13.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch13.Tag = 'DO_13';
            app.DOassign_ch13.Value = 'None';
            app.DO_ch14 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch14.Editable = 'off';
            app.DO_ch14.Value = 14;
            app.DOassign_ch14 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch14.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch14.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch14.Tag = 'DO_14';
            app.DOassign_ch14.Value = 'None';
            app.DO_ch15 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch15.Editable = 'off';
            app.DO_ch15.Value = 15;
            app.DOassign_ch15 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch15.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch15.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch15.Tag = 'DO_15';
            app.DOassign_ch15.Value = 'None';
            app.DO_ch0 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch0.Editable = 'off';
            app.DOassign_ch0 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch0.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch0.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch0.Tag = 'DO_0';
            app.DOassign_ch0.Value = 'None';
            app.DOassign_ch1 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch1.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch1.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch1.Tag = 'DO_1';
            app.DOassign_ch1.Value = 'None';
            app.DO_ch1 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch1.Editable = 'off';
            app.DO_ch1.Value = 1;
            app.DO_ch3 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch3.Editable = 'off';
            app.DO_ch3.Value = 3;
            app.DOassign_ch3 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch3.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch3.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch3.Tag = 'DO_3';
            app.DOassign_ch3.Value = 'None';
            app.DOassign_ch7 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch7.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch7.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch7.Tag = 'DO_7';
            app.DOassign_ch7.Value = 'None';
            app.DO_ch7 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch7.Editable = 'off';
            app.DO_ch7.Value = 7;
            app.DO_ch16 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch16.Editable = 'off';
            app.DO_ch16.Value = 16;
            app.DO_ch18 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch18.Editable = 'off';
            app.DO_ch18.Value = 18;
            app.DO_ch19 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch19.Editable = 'off';
            app.DO_ch19.Value = 19;
            app.DO_ch20 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch20.Editable = 'off';
            app.DO_ch20.Value = 20;
            app.DOassign_ch16 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch16.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch16.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch16.Tag = 'DO_16';
            app.DOassign_ch16.Value = 'None';
            app.DOassign_ch18 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch18.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch18.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch18.Tag = 'DO_18';
            app.DOassign_ch18.Value = 'None';
            app.DOassign_ch19 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch19.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch19.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch19.Tag = 'DO_19';
            app.DOassign_ch19.Value = 'None';
            app.DOassign_ch20 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch20.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch20.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch20.Tag = 'DO_20';
            app.DOassign_ch20.Value = 'None';
            app.DO_ch21 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch21.Editable = 'off';
            app.DO_ch21.Value = 21;
            app.DOassign_ch21 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch21.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch21.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch21.Tag = 'DO_21';
            app.DOassign_ch21.Value = 'None';
            app.DO_ch22 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch22.Editable = 'off';
            app.DO_ch22.Value = 22;
            app.DOassign_ch22 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch22.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch22.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch22.Tag = 'DO_22';
            app.DOassign_ch22.Value = 'None';
            app.DO_ch23 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch23.Editable = 'off';
            app.DO_ch23.Value = 23;
            app.DOassign_ch23 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch23.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch23.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch23.Tag = 'DO_23';
            app.DOassign_ch23.Value = 'None';
            app.DOassign_ch17 = uidropdown(app.DigitalOUTPanel);
            app.DOassign_ch17.Items = {'Reward', 'TDT port A', 'TDT port B', 'TDT port C', 'None', 'Add new'};
            app.DOassign_ch17.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.DOassign_ch17.Tag = 'DO_17';
            app.DOassign_ch17.Value = 'None';
            app.DO_ch17 = uieditfield(app.DigitalOUTPanel, 'numeric');
            app.DO_ch17.Editable = 'off';
            app.DO_ch17.Value = 17;
            app.AnalogOUTPanel = uipanel(app.DAQTab);
            app.AnalogOUTPanel.Title = 'Analog OUT';
            app.DACrateHzEditFieldLabel = uilabel(app.AnalogOUTPanel);
            app.DACrateHzEditFieldLabel.Text = 'DAC rate (Hz)';
            app.DACrateHzEditField = uieditfield(app.AnalogOUTPanel, 'text');
            app.DACrateHzEditField.ValueChangedFcn = createCallbackFcn(app, @DACrateHzEditFieldValueChanged, true);
            app.DACrateHzEditField.Value = '1000';
            app.AO_ch0 = uieditfield(app.AnalogOUTPanel, 'numeric');
            app.AO_ch0.Editable = 'off';
            app.AOassign_ch0 = uidropdown(app.AnalogOUTPanel);
            app.AOassign_ch0.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AOassign_ch0.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AOassign_ch0.Tag = 'AO_0';
            app.AOassign_ch0.Value = 'None';
            app.AO_ch1 = uieditfield(app.AnalogOUTPanel, 'numeric');
            app.AO_ch1.Editable = 'off';
            app.AO_ch1.Value = 1;
            app.AOassign_ch1 = uidropdown(app.AnalogOUTPanel);
            app.AOassign_ch1.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AOassign_ch1.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AOassign_ch1.Tag = 'AO_1';
            app.AOassign_ch1.Value = 'None';
            app.AO_ch3 = uieditfield(app.AnalogOUTPanel, 'numeric');
            app.AO_ch3.Editable = 'off';
            app.AO_ch3.Value = 3;
            app.AOassign_ch3 = uidropdown(app.AnalogOUTPanel);
            app.AOassign_ch3.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AOassign_ch3.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AOassign_ch3.Tag = 'AO_3';
            app.AOassign_ch3.Value = 'None';
            app.AOassign_ch2 = uidropdown(app.AnalogOUTPanel);
            app.AOassign_ch2.Items = {'Left eye X', 'Left eye Y', 'Left eye pupil', 'Right eye X', 'Right eye Y', 'Right eye pupil', 'Lever 1', 'Lever 2', 'Photodiode', 'Scanner TTL', 'None', 'Add new', '', ''};
            app.AOassign_ch2.ValueChangedFcn = createCallbackFcn(app, @AIassign_ch0ValueChanged, true);
            app.AOassign_ch2.Tag = 'AO_2';
            app.AOassign_ch2.Value = 'None';
            app.AO_ch2 = uieditfield(app.AnalogOUTPanel, 'numeric');
            app.AO_ch2.Editable = 'off';
            app.AO_ch2.Value = 2;
            app.PsychDPxTab = uitab(app.TabGroup);
            app.PsychDPxTab.Title = 'PsychDPx';
            app.PsychDPxVerbosityDropDownLabel = uilabel(app.PsychDPxTab);
            app.PsychDPxVerbosityDropDownLabel.Text = 'PsychDPx Verbosity';
            app.PsychDPxVerbosityDropDown = uidropdown(app.PsychDPxTab);
            app.PsychDPxVerbosityDropDown.Items = {'No output', 'Errors only', 'Warnings', 'Additional info', 'Max info', '', ''};
            app.PsychDPxVerbosityDropDown.ValueChangedFcn = createCallbackFcn(app, @PsychDPxVerbosityDropDownValueChanged, true);
            app.PsychDPxVerbosityDropDown.Value = 'No output';
            app.DummymodeCheckBox = uicheckbox(app.PsychDPxTab);
            app.DummymodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DummymodeCheckBoxValueChanged, true);
            app.DummymodeCheckBox.Tooltip = {'Enabled when Datapixx ro Datapixx tools are not detected.'};
            app.DummymodeCheckBox.Text = 'Dummy mode?';
            app.LogonsettimestampsDropDownLabel = uilabel(app.PsychDPxTab);
            app.LogonsettimestampsDropDownLabel.Text = 'Log onset timestamps';
            app.LogonsettimestampsDropDown = uidropdown(app.PsychDPxTab);
            app.LogonsettimestampsDropDown.Items = {'Disable all', 'Log next flip', 'Log all flips', '', ''};
            app.LogonsettimestampsDropDown.ValueChangedFcn = createCallbackFcn(app, @LogonsettimestampsDropDownValueChanged, true);
            app.LogonsettimestampsDropDown.Value = 'Disable all';
            app.DatapixxvideomodeDropDownLabel = uilabel(app.PsychDPxTab);
            app.DatapixxvideomodeDropDownLabel.Text = 'Datapixx video mode';
            app.DatapixxvideomodeDropDown = uidropdown(app.PsychDPxTab);
            app.DatapixxvideomodeDropDown.Items = {'C24', 'L48', 'M16', 'C48', 'L48D', 'M16D', 'C36D', 'RB24', '', ''};
            app.DatapixxvideomodeDropDown.ValueChangedFcn = createCallbackFcn(app, @DatapixxvideomodeDropDownValueChanged, true);
            app.DatapixxvideomodeDropDown.Tooltip = {'The Datapixx inputs digital DVI data, and outputs analog video. The video "mode"'; 'determines how the digital data is converted to analog RGB.'};
            app.DatapixxvideomodeDropDown.Value = 'C24';
            app.VideostereobluelineCheckBox = uicheckbox(app.PsychDPxTab);
            app.VideostereobluelineCheckBox.ValueChangedFcn = createCallbackFcn(app, @VideostereobluelineCheckBoxValueChanged, true);
            app.VideostereobluelineCheckBox.Text = 'Video stereo blueline';
            app.VideoscanningbacklightCheckBox = uicheckbox(app.PsychDPxTab);
            app.VideoscanningbacklightCheckBox.ValueChangedFcn = createCallbackFcn(app, @VideoscanningbacklightCheckBoxValueChanged, true);
            app.VideoscanningbacklightCheckBox.Text = 'Video scanning backlight';
            app.VideohorizontalsplitDropDownLabel = uilabel(app.PsychDPxTab);
            app.VideohorizontalsplitDropDownLabel.Text = 'Video horizontal split';
            app.VideohorizontalsplitDropDown = uidropdown(app.PsychDPxTab);
            app.VideohorizontalsplitDropDown.Items = {'Mirror', 'Split', 'Auto', ''};
            app.VideohorizontalsplitDropDown.ValueChangedFcn = createCallbackFcn(app, @VideohorizontalsplitDropDownValueChanged, true);
            app.VideohorizontalsplitDropDown.Tooltip = {'The Datapixx inputs digital DVI data, and outputs analog video. The video "mode"'; 'determines how the digital data is converted to analog RGB.'};
            app.VideohorizontalsplitDropDown.Value = 'Mirror';
            app.VideoverticalstereoDropDownLabel = uilabel(app.PsychDPxTab);
            app.VideoverticalstereoDropDownLabel.Text = 'Video vertical stereo';
            app.VideoverticalstereoDropDown = uidropdown(app.PsychDPxTab);
            app.VideoverticalstereoDropDown.Items = {'No stereo', 'Stereo', 'Auto', ''};
            app.VideoverticalstereoDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoverticalstereoDropDownValueChanged, true);
            app.VideoverticalstereoDropDown.Tooltip = {'The Datapixx inputs digital DVI data, and outputs analog video. The video "mode"'; 'determines how the digital data is converted to analog RGB.'};
            app.VideoverticalstereoDropDown.Value = 'No stereo';
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.ConnectButton = uibutton(app.TestTab, 'push');
            app.ConnectButton.Text = 'Connect';
            app.TabGroup2 = uitabgroup(app.TestTab);
            app.AnalogTab = uitab(app.TabGroup2);
            app.AnalogTab.Title = 'Analog';
            app.VoltagerangeVSpinnerLabel = uilabel(app.AnalogTab);
            app.VoltagerangeVSpinnerLabel.Text = 'Voltage range (V)';
            app.VoltagerangeVSpinner = uispinner(app.AnalogTab);
            app.VoltagerangeVSpinner.Limits = [-10 10];
            app.WaveformDropDownLabel = uilabel(app.AnalogTab);
            app.WaveformDropDownLabel.Text = 'Waveform';
            app.WaveformDropDown = uidropdown(app.AnalogTab);
            app.WaveformDropDown.Items = {'Sine', 'Square', 'Saw-tooth'};
            app.WaveformDropDown.Value = 'Sine';
            app.FrequencyHzEditFieldLabel = uilabel(app.AnalogTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.AnalogTab, 'numeric');
            app.UIAxes2 = uiaxes(app.AnalogTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.DigitalTab = uitab(app.TabGroup2);
            app.DigitalTab.Title = 'Digital';
            app.Lamp = uilamp(app.DigitalTab);
            app.Lamp_2 = uilamp(app.DigitalTab);
            app.Lamp_3 = uilamp(app.DigitalTab);
            app.Lamp_4 = uilamp(app.DigitalTab);
            app.Lamp_5 = uilamp(app.DigitalTab);
            app.Lamp_6 = uilamp(app.DigitalTab);
            app.Lamp_7 = uilamp(app.DigitalTab);
            app.Lamp_8 = uilamp(app.DigitalTab);
            app.Lamp_9 = uilamp(app.DigitalTab);
            app.Lamp_10 = uilamp(app.DigitalTab);
            app.Lamp_11 = uilamp(app.DigitalTab);
            app.Lamp_12 = uilamp(app.DigitalTab);
            app.Lamp_13 = uilamp(app.DigitalTab);
            app.Lamp_14 = uilamp(app.DigitalTab);
            app.Lamp_15 = uilamp(app.DigitalTab);
            app.Lamp_16 = uilamp(app.DigitalTab);
            app.Lamp_17 = uilamp(app.DigitalTab);
            app.Lamp_18 = uilamp(app.DigitalTab);
            app.Lamp_19 = uilamp(app.DigitalTab);
            app.Lamp_20 = uilamp(app.DigitalTab);
            app.Lamp_21 = uilamp(app.DigitalTab);
            app.Lamp_22 = uilamp(app.DigitalTab);
            app.Lamp_23 = uilamp(app.DigitalTab);
            app.Lamp_24 = uilamp(app.DigitalTab);
            app.Label = uilabel(app.DigitalTab);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.DigitalTab);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.DigitalTab);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.DigitalTab);
            app.Label_4.Text = '3';
            app.DigitalINLabel = uilabel(app.DigitalTab);
            app.DigitalINLabel.Text = 'Digital IN';
            app.DigitalOUTLabel = uilabel(app.DigitalTab);
            app.DigitalOUTLabel.Text = 'Digital OUT';
            app.Button = uibutton(app.DigitalTab, 'state');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.DigitalTab, 'state');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.DigitalTab, 'state');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.DigitalTab, 'state');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.DigitalTab, 'state');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.DigitalTab, 'state');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.DigitalTab, 'state');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.DigitalTab, 'state');
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.DigitalTab, 'state');
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.DigitalTab, 'state');
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.DigitalTab, 'state');
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.DigitalTab, 'state');
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.DigitalTab, 'state');
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.DigitalTab, 'state');
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.DigitalTab, 'state');
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.DigitalTab, 'state');
            app.Button_16.Text = '';
            app.Button_25 = uibutton(app.DigitalTab, 'state');
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.DigitalTab, 'state');
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.DigitalTab, 'state');
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.DigitalTab, 'state');
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.DigitalTab, 'state');
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.DigitalTab, 'state');
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.DigitalTab, 'state');
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.DigitalTab, 'state');
            app.Button_32.Text = '';
            app.ResetButton_3 = uibutton(app.DigitalTab, 'push');
            app.ResetButton_3.Text = 'Reset';
            app.DigitalINplotPanel = uipanel(app.DigitalTab);
            app.DigitalINplotPanel.Title = 'Digital IN plot';
            app.UIAxes = uiaxes(app.DigitalINplotPanel);
            title(app.UIAxes, 'Digital IN')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'State')
            app.UIAxes.YTick = [0 1];
            app.DigitalINchannelDropDownLabel = uilabel(app.DigitalINplotPanel);
            app.DigitalINchannelDropDownLabel.Text = 'Digital IN channel';
            app.DigitalINchannelDropDown = uidropdown(app.DigitalINplotPanel);
            app.DigitalINchannelDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.DigitalINchannelDropDown.Value = '0';
            app.TimeperiodsEditFieldLabel = uilabel(app.DigitalINplotPanel);
            app.TimeperiodsEditFieldLabel.Text = 'Time period (s)';
            app.TimeperiodsEditField = uieditfield(app.DigitalINplotPanel, 'numeric');
            app.ResetButton = uibutton(app.TestTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.ResetButton_2 = uibutton(app.TestTab, 'push');
            app.ResetButton_2.Text = 'Reset';
            % Show the figure after all components are created
            app.NTBDatapixxSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NTB_DatapixxSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NTBDatapixxSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NTBDatapixxSettingsUIFigure)
        end
    end
end