classdef NTB_EyeCalSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NTBEyeCalSettingsUIFigure       matlab.ui.Figure
        DataPanel                       matlab.ui.container.Panel
        RunningLamp                     matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        OptionsPanel                    matlab.ui.container.Panel
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.StateButton
        Button_Docs_2                   matlab.ui.control.Button
        Button_Load                     matlab.ui.control.Button
        Button_Save                     matlab.ui.control.Button
        Button_Docs                     matlab.ui.control.Button
        AppearancePanel                 matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        TargetsTab                      matlab.ui.container.Tab
        TargetcontrastSlider            matlab.ui.control.Slider
        TargetcontrastLabel             matlab.ui.control.Label
        TargetcolorLabel                matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        TargetimagedirectoryEditField   matlab.ui.control.EditField
        TargetimagedirectoryEditFieldLabel  matlab.ui.control.Label
        TargetdiameterDVAEditField      matlab.ui.control.NumericEditField
        TargetdiameterDVAEditFieldLabel  matlab.ui.control.Label
        TargetstimulusDropDown          matlab.ui.control.DropDown
        TargetstimulusDropDownLabel     matlab.ui.control.Label
        GeometryTab                     matlab.ui.container.Tab
        DepthamountcmSpinner            matlab.ui.control.Spinner
        DepthamountcmSpinnerLabel       matlab.ui.control.Label
        TargetdepthsDropDown            matlab.ui.control.DropDown
        TargetdepthsDropDownLabel       matlab.ui.control.Label
        TargetspacingSpinner            matlab.ui.control.Spinner
        TargetspacingSpinnerLabel       matlab.ui.control.Label
        NotargetlocationsDropDown       matlab.ui.control.DropDown
        NotargetlocationsDropDownLabel  matlab.ui.control.Label
        TargetlayoutDropDown            matlab.ui.control.DropDown
        TargetlayoutDropDownLabel       matlab.ui.control.Label
        TimingTab                       matlab.ui.container.Tab
        IntertargetintervalmsEditField  matlab.ui.control.NumericEditField
        IntertargetintervalmsEditFieldLabel  matlab.ui.control.Label
        TrialsperrunEditField           matlab.ui.control.NumericEditField
        TrialsperrunEditFieldLabel      matlab.ui.control.Label
        StimpertrialEditField           matlab.ui.control.NumericEditField
        StimpertrialEditFieldLabel      matlab.ui.control.Label
        FixradiusDVAEditField           matlab.ui.control.NumericEditField
        FixradiusDVAEditFieldLabel      matlab.ui.control.Label
        FixdurationEditField            matlab.ui.control.NumericEditField
        FixdurationEditFieldLabel       matlab.ui.control.Label
        TargetdurationmsEditField       matlab.ui.control.NumericEditField
        TargetdurationmsEditFieldLabel  matlab.ui.control.Label
        RewardTab                       matlab.ui.container.Tab
        FixwindowradiusDVAEditField     matlab.ui.control.NumericEditField
        FixwindowradiusDVAEditFieldLabel  matlab.ui.control.Label
        MinprportionEditField           matlab.ui.control.NumericEditField
        MinprportionEditFieldLabel      matlab.ui.control.Label
        MaxbreakmsEditField             matlab.ui.control.NumericEditField
        MaxbreakmsEditFieldLabel        matlab.ui.control.Label
        RewardintervalmsEditField       matlab.ui.control.NumericEditField
        RewardintervalmsEditFieldLabel  matlab.ui.control.Label
        TTLdurmsEditField               matlab.ui.control.NumericEditField
        TTLdurmsEditFieldLabel          matlab.ui.control.Label
        RewardscheduleDropDown          matlab.ui.control.DropDown
        RewardscheduleDropDownLabel     matlab.ui.control.Label
        PlotsTab                        matlab.ui.container.Tab
        InvalidButton                   matlab.ui.control.Button
        ValidButton                     matlab.ui.control.Button
        DisplaycolorsLabel              matlab.ui.control.Label
        ShowdistributionmapCheckBox     matlab.ui.control.CheckBox
        MarkerdiameterpxEditField       matlab.ui.control.NumericEditField
        MarkerdiameterpxEditFieldLabel  matlab.ui.control.Label
        TimetoaveragemsEditField        matlab.ui.control.NumericEditField
        TimetoaveragemsEditFieldLabel   matlab.ui.control.Label
        AverageCheckBox                 matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        EyeCalibrationSettingsPanel     matlab.ui.container.Panel
        CalibrationmodeDropDown         matlab.ui.control.DropDown
        CalibrationmodeDropDownLabel    matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        AnalogTab                       matlab.ui.container.Tab
        PupilmissingVEditField          matlab.ui.control.NumericEditField
        PupilmissingVEditFieldLabel     matlab.ui.control.Label
        SamplerateHzEditField           matlab.ui.control.NumericEditField
        SamplerateHzEditFieldLabel      matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        LefteyeTab                      matlab.ui.container.Tab
        LeftEye_GainP                   matlab.ui.control.EditField
        LeftEye_InvertP                 matlab.ui.control.CheckBox
        LeftEye_InvertY                 matlab.ui.control.CheckBox
        LeftEye_InvertX                 matlab.ui.control.CheckBox
        InvertedLabel                   matlab.ui.control.Label
        LeftEye_GainY                   matlab.ui.control.EditField
        LeftEye_GainX                   matlab.ui.control.EditField
        LeftEye_OffsetP                 matlab.ui.control.EditField
        LeftEye_OffsetY                 matlab.ui.control.EditField
        LeftEye_OffsetX                 matlab.ui.control.EditField
        GainDVAVLabel                   matlab.ui.control.Label
        OffsetVLabel                    matlab.ui.control.Label
        PLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        LeftEye_ChP                     matlab.ui.control.DropDown
        LeftEye_ChY                     matlab.ui.control.DropDown
        LeftEye_ChX                     matlab.ui.control.DropDown
        ADCchannelDropDownLabel         matlab.ui.control.Label
        RighteyeTab                     matlab.ui.container.Tab
        RightEye_ChX_2                  matlab.ui.control.DropDown
        ADCchannelDropDownLabel_2       matlab.ui.control.Label
        RightEye_GainP_2                matlab.ui.control.EditField
        RightEye_InvertP_2              matlab.ui.control.CheckBox
        RightEye_InvertY_2              matlab.ui.control.CheckBox
        RightEye_InvertX_2              matlab.ui.control.CheckBox
        InvertedLabel_2                 matlab.ui.control.Label
        RightEye_GainY_2                matlab.ui.control.EditField
        RightEye_GainX_2                matlab.ui.control.EditField
        RightEye_OffsetP_2              matlab.ui.control.EditField
        RightEye_OffsetY_2              matlab.ui.control.EditField
        RightEye_OffsetX                matlab.ui.control.EditField
        GainDVAVLabel_2                 matlab.ui.control.Label
        OffsetVLabel_2                  matlab.ui.control.Label
        PLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        RightEye_ChP                    matlab.ui.control.DropDown
        RightEye_ChY                    matlab.ui.control.DropDown
        VersionTab                      matlab.ui.container.Tab
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        VoltagerangeVLabel              matlab.ui.control.Label
        EyeusedDropDown                 matlab.ui.control.DropDown
        EyeusedDropDownLabel            matlab.ui.control.Label
        TCPTab                          matlab.ui.container.Tab
        EditField_4                     matlab.ui.control.NumericEditField
        SubnetmaskLabel                 matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        IPaddressLabel                  matlab.ui.control.Label
        EyetrackerDropDown              matlab.ui.control.DropDown
        EyetrackerLabel                 matlab.ui.control.Label
        EyesignalsourceDropDown         matlab.ui.control.DropDown
        SignalformatLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        Params %
        Eye
        NTBapp
        OffOnColors         % Channel status colors
        RedGreen            % Button status colors
        ChOffOnColor        % Channel status colors
    end
    
    methods (Access = private)
        
        %================= Update GUI values
        function results = UpdateValues(app)
            
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NTBapp, Eye)
            app.Eye         = NTBapp.Params.Eye;    % Add eye struct to app object
            app.NTBapp      = NTBapp;  
            UpdateValues(app);
        end

        % Value changed function: EyesignalsourceDropDown
        function EyesignalsourceDropDownValueChanged(app, event)
            value = app.EyesignalsourceDropDown.Value;
            app.Eye.Source = value;
            app.TabGroup.SelectedTab = eval(sprintf('app.%sTab', value));
        end

        % Value changed function: EyeusedDropDown
        function EyeusedDropDownValueChanged(app, event)
            value = app.EyeusedDropDown.Value;
            app.Eye.SelectedEye = value;
            app.TabGroup2.SelectedTab = eval(sprintf('app.%seyeTab', value));
        end

        % Close request function: NTBEyeCalSettingsUIFigure
        function NTBEyeCalSettingsUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: CalibrationmodeDropDown
        function CalibrationmodeDropDownValueChanged(app, event)
            value = app.CalibrationmodeDropDown.Value;
            app.Eye.CalMode = value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NTBEyeCalSettingsUIFigure = uifigure('Visible', 'off');
            app.NTBEyeCalSettingsUIFigure.Name = 'NTB Eye Cal Settings';
            app.NTBEyeCalSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @NTBEyeCalSettingsUIFigureCloseRequest, true);
            app.NTBEyeCalSettingsUIFigure.Tag = 'NTB_EyeCal';
            app.EyeCalibrationSettingsPanel = uipanel(app.NTBEyeCalSettingsUIFigure);
            app.EyeCalibrationSettingsPanel.Title = 'Eye Calibration Settings';
            app.SignalformatLabel = uilabel(app.EyeCalibrationSettingsPanel);
            app.SignalformatLabel.Text = 'Eye signal source:';
            app.EyesignalsourceDropDown = uidropdown(app.EyeCalibrationSettingsPanel);
            app.EyesignalsourceDropDown.Items = {'Analog', 'TCP'};
            app.EyesignalsourceDropDown.ValueChangedFcn = createCallbackFcn(app, @EyesignalsourceDropDownValueChanged, true);
            app.EyesignalsourceDropDown.Value = 'Analog';
            app.TabGroup = uitabgroup(app.EyeCalibrationSettingsPanel);
            app.AnalogTab = uitab(app.TabGroup);
            app.AnalogTab.Title = 'Analog';
            app.EyeusedDropDownLabel = uilabel(app.AnalogTab);
            app.EyeusedDropDownLabel.Text = 'Eye used:';
            app.EyeusedDropDown = uidropdown(app.AnalogTab);
            app.EyeusedDropDown.Items = {'Left', 'Right', 'Both'};
            app.EyeusedDropDown.ValueChangedFcn = createCallbackFcn(app, @EyeusedDropDownValueChanged, true);
            app.EyeusedDropDown.Value = 'Left';
            app.VoltagerangeVLabel = uilabel(app.AnalogTab);
            app.VoltagerangeVLabel.Text = 'Voltage range (V):';
            app.EditField = uieditfield(app.AnalogTab, 'numeric');
            app.EditField.Value = -10;
            app.EditField_2 = uieditfield(app.AnalogTab, 'numeric');
            app.EditField_2.Value = 10;
            app.TabGroup2 = uitabgroup(app.AnalogTab);
            app.LefteyeTab = uitab(app.TabGroup2);
            app.LefteyeTab.Title = 'Left eye';
            app.ADCchannelDropDownLabel = uilabel(app.LefteyeTab);
            app.ADCchannelDropDownLabel.Text = 'ADC channel';
            app.LeftEye_ChX = uidropdown(app.LefteyeTab);
            app.LeftEye_ChX.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.LeftEye_ChX.Value = '0';
            app.LeftEye_ChY = uidropdown(app.LefteyeTab);
            app.LeftEye_ChY.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.LeftEye_ChY.Value = '0';
            app.LeftEye_ChP = uidropdown(app.LefteyeTab);
            app.LeftEye_ChP.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.LeftEye_ChP.Value = '0';
            app.XLabel = uilabel(app.LefteyeTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.LefteyeTab);
            app.YLabel.Text = 'Y';
            app.PLabel = uilabel(app.LefteyeTab);
            app.PLabel.Text = 'P';
            app.OffsetVLabel = uilabel(app.LefteyeTab);
            app.OffsetVLabel.Text = 'Offset (V)';
            app.GainDVAVLabel = uilabel(app.LefteyeTab);
            app.GainDVAVLabel.Text = 'Gain (DVA/V)';
            app.LeftEye_OffsetX = uieditfield(app.LefteyeTab, 'text');
            app.LeftEye_OffsetY = uieditfield(app.LefteyeTab, 'text');
            app.LeftEye_OffsetP = uieditfield(app.LefteyeTab, 'text');
            app.LeftEye_GainX = uieditfield(app.LefteyeTab, 'text');
            app.LeftEye_GainY = uieditfield(app.LefteyeTab, 'text');
            app.InvertedLabel = uilabel(app.LefteyeTab);
            app.InvertedLabel.Text = 'Inverted';
            app.LeftEye_InvertX = uicheckbox(app.LefteyeTab);
            app.LeftEye_InvertX.Text = '';
            app.LeftEye_InvertY = uicheckbox(app.LefteyeTab);
            app.LeftEye_InvertY.Text = '';
            app.LeftEye_InvertP = uicheckbox(app.LefteyeTab);
            app.LeftEye_InvertP.Text = '';
            app.LeftEye_GainP = uieditfield(app.LefteyeTab, 'text');
            app.RighteyeTab = uitab(app.TabGroup2);
            app.RighteyeTab.Title = 'Right eye';
            app.RightEye_ChY = uidropdown(app.RighteyeTab);
            app.RightEye_ChY.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.RightEye_ChY.Value = '0';
            app.RightEye_ChP = uidropdown(app.RighteyeTab);
            app.RightEye_ChP.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.RightEye_ChP.Value = '0';
            app.XLabel_2 = uilabel(app.RighteyeTab);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.RighteyeTab);
            app.YLabel_2.Text = 'Y';
            app.PLabel_2 = uilabel(app.RighteyeTab);
            app.PLabel_2.Text = 'P';
            app.OffsetVLabel_2 = uilabel(app.RighteyeTab);
            app.OffsetVLabel_2.Text = 'Offset (V)';
            app.GainDVAVLabel_2 = uilabel(app.RighteyeTab);
            app.GainDVAVLabel_2.Text = 'Gain (DVA/V)';
            app.RightEye_OffsetX = uieditfield(app.RighteyeTab, 'text');
            app.RightEye_OffsetY_2 = uieditfield(app.RighteyeTab, 'text');
            app.RightEye_OffsetP_2 = uieditfield(app.RighteyeTab, 'text');
            app.RightEye_GainX_2 = uieditfield(app.RighteyeTab, 'text');
            app.RightEye_GainY_2 = uieditfield(app.RighteyeTab, 'text');
            app.InvertedLabel_2 = uilabel(app.RighteyeTab);
            app.InvertedLabel_2.Text = 'Inverted';
            app.RightEye_InvertX_2 = uicheckbox(app.RighteyeTab);
            app.RightEye_InvertX_2.Text = '';
            app.RightEye_InvertY_2 = uicheckbox(app.RighteyeTab);
            app.RightEye_InvertY_2.Text = '';
            app.RightEye_InvertP_2 = uicheckbox(app.RighteyeTab);
            app.RightEye_InvertP_2.Text = '';
            app.RightEye_GainP_2 = uieditfield(app.RighteyeTab, 'text');
            app.ADCchannelDropDownLabel_2 = uilabel(app.RighteyeTab);
            app.ADCchannelDropDownLabel_2.Text = 'ADC channel';
            app.RightEye_ChX_2 = uidropdown(app.RighteyeTab);
            app.RightEye_ChX_2.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.RightEye_ChX_2.Value = '0';
            app.VersionTab = uitab(app.TabGroup2);
            app.VersionTab.Title = 'Version';
            app.SamplerateHzEditFieldLabel = uilabel(app.AnalogTab);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz):';
            app.SamplerateHzEditField = uieditfield(app.AnalogTab, 'numeric');
            app.SamplerateHzEditField.Limits = [1 10000];
            app.SamplerateHzEditField.RoundFractionalValues = 'on';
            app.SamplerateHzEditField.Value = 1000;
            app.PupilmissingVEditFieldLabel = uilabel(app.AnalogTab);
            app.PupilmissingVEditFieldLabel.Text = 'Pupil missing (V):';
            app.PupilmissingVEditField = uieditfield(app.AnalogTab, 'numeric');
            app.TCPTab = uitab(app.TabGroup);
            app.TCPTab.Title = 'TCP';
            app.EyetrackerLabel = uilabel(app.TCPTab);
            app.EyetrackerLabel.Text = 'Eye tracker:';
            app.EyetrackerDropDown = uidropdown(app.TCPTab);
            app.EyetrackerDropDown.Items = {'EyeLink', 'Arrington', 'iView'};
            app.EyetrackerDropDown.Value = 'EyeLink';
            app.IPaddressLabel = uilabel(app.TCPTab);
            app.IPaddressLabel.Text = 'IP address:';
            app.EditField_3 = uieditfield(app.TCPTab, 'numeric');
            app.SubnetmaskLabel = uilabel(app.TCPTab);
            app.SubnetmaskLabel.Text = 'Subnet mask:';
            app.EditField_4 = uieditfield(app.TCPTab, 'numeric');
            app.CalibrationmodeDropDownLabel = uilabel(app.EyeCalibrationSettingsPanel);
            app.CalibrationmodeDropDownLabel.Text = 'Calibration mode:';
            app.CalibrationmodeDropDown = uidropdown(app.EyeCalibrationSettingsPanel);
            app.CalibrationmodeDropDown.Items = {'Mouse simulation', 'Manual calibration', 'Auto calibration', 'Fix training'};
            app.CalibrationmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibrationmodeDropDownValueChanged, true);
            app.CalibrationmodeDropDown.Value = 'Mouse simulation';
            app.AppearancePanel = uipanel(app.NTBEyeCalSettingsUIFigure);
            app.AppearancePanel.Title = 'Appearance';
            app.UIAxes = uiaxes(app.AppearancePanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TabGroup3 = uitabgroup(app.AppearancePanel);
            app.TargetsTab = uitab(app.TabGroup3);
            app.TargetsTab.Title = 'Targets';
            app.TargetstimulusDropDownLabel = uilabel(app.TargetsTab);
            app.TargetstimulusDropDownLabel.Text = 'Target stimulus';
            app.TargetstimulusDropDown = uidropdown(app.TargetsTab);
            app.TargetstimulusDropDown.Items = {'Dots', 'Gabors', 'Motion pattern', 'Images', 'Movies'};
            app.TargetstimulusDropDown.Value = 'Dots';
            app.TargetdiameterDVAEditFieldLabel = uilabel(app.TargetsTab);
            app.TargetdiameterDVAEditFieldLabel.Text = 'Target diameter (DVA)';
            app.TargetdiameterDVAEditField = uieditfield(app.TargetsTab, 'numeric');
            app.TargetimagedirectoryEditFieldLabel = uilabel(app.TargetsTab);
            app.TargetimagedirectoryEditFieldLabel.Text = 'Target image directory';
            app.TargetimagedirectoryEditField = uieditfield(app.TargetsTab, 'text');
            app.Button = uibutton(app.TargetsTab, 'push');
            app.Button.Text = '';
            app.TargetcolorLabel = uilabel(app.TargetsTab);
            app.TargetcolorLabel.Text = 'Target color';
            app.TargetcontrastLabel = uilabel(app.TargetsTab);
            app.TargetcontrastLabel.Text = 'Target contrast (%)';
            app.TargetcontrastSlider = uislider(app.TargetsTab);
            app.TargetcontrastSlider.Value = 100;
            app.GeometryTab = uitab(app.TabGroup3);
            app.GeometryTab.Title = 'Geometry';
            app.TargetlayoutDropDownLabel = uilabel(app.GeometryTab);
            app.TargetlayoutDropDownLabel.Text = 'Target layout:';
            app.TargetlayoutDropDown = uidropdown(app.GeometryTab);
            app.TargetlayoutDropDown.Items = {'Grid', 'Radial', ''};
            app.TargetlayoutDropDown.Value = 'Grid';
            app.NotargetlocationsDropDownLabel = uilabel(app.GeometryTab);
            app.NotargetlocationsDropDownLabel.Text = 'No. target locations';
            app.NotargetlocationsDropDown = uidropdown(app.GeometryTab);
            app.NotargetlocationsDropDown.Items = {'9', '21 ', '48'};
            app.NotargetlocationsDropDown.Value = '9';
            app.TargetspacingSpinnerLabel = uilabel(app.GeometryTab);
            app.TargetspacingSpinnerLabel.Text = 'Target spacing ():';
            app.TargetspacingSpinner = uispinner(app.GeometryTab);
            app.TargetspacingSpinner.Limits = [0 40];
            app.TargetspacingSpinner.Value = 5;
            app.TargetdepthsDropDownLabel = uilabel(app.GeometryTab);
            app.TargetdepthsDropDownLabel.Text = 'Target depths:';
            app.TargetdepthsDropDown = uidropdown(app.GeometryTab);
            app.TargetdepthsDropDown.Items = {'In-plane only', 'Near', 'Far', 'Near + Far'};
            app.TargetdepthsDropDown.Value = 'In-plane only';
            app.DepthamountcmSpinnerLabel = uilabel(app.GeometryTab);
            app.DepthamountcmSpinnerLabel.Text = 'Depth amount (cm):';
            app.DepthamountcmSpinner = uispinner(app.GeometryTab);
            app.DepthamountcmSpinner.Limits = [0 40];
            app.TimingTab = uitab(app.TabGroup3);
            app.TimingTab.Title = 'Timing';
            app.TargetdurationmsEditFieldLabel = uilabel(app.TimingTab);
            app.TargetdurationmsEditFieldLabel.Text = 'Target duration (ms)';
            app.TargetdurationmsEditField = uieditfield(app.TimingTab, 'numeric');
            app.TargetdurationmsEditField.Limits = [0 Inf];
            app.TargetdurationmsEditField.Value = 1000;
            app.FixdurationEditFieldLabel = uilabel(app.TimingTab);
            app.FixdurationEditFieldLabel.Text = 'Fix duration (%)';
            app.FixdurationEditField = uieditfield(app.TimingTab, 'numeric');
            app.FixdurationEditField.Limits = [0 100];
            app.FixdurationEditField.Value = 80;
            app.FixradiusDVAEditFieldLabel = uilabel(app.TimingTab);
            app.FixradiusDVAEditFieldLabel.Text = 'Fix radius (DVA)';
            app.FixradiusDVAEditField = uieditfield(app.TimingTab, 'numeric');
            app.FixradiusDVAEditField.Limits = [0 100];
            app.FixradiusDVAEditField.Value = 4;
            app.StimpertrialEditFieldLabel = uilabel(app.TimingTab);
            app.StimpertrialEditFieldLabel.Text = 'Stim per trial';
            app.StimpertrialEditField = uieditfield(app.TimingTab, 'numeric');
            app.StimpertrialEditField.Limits = [1 Inf];
            app.StimpertrialEditField.Value = 2;
            app.TrialsperrunEditFieldLabel = uilabel(app.TimingTab);
            app.TrialsperrunEditFieldLabel.Text = 'Trials per run';
            app.TrialsperrunEditField = uieditfield(app.TimingTab, 'numeric');
            app.TrialsperrunEditField.Limits = [1 Inf];
            app.TrialsperrunEditField.Value = 100;
            app.IntertargetintervalmsEditFieldLabel = uilabel(app.TimingTab);
            app.IntertargetintervalmsEditFieldLabel.Text = 'Inter-target interval (ms)';
            app.IntertargetintervalmsEditField = uieditfield(app.TimingTab, 'numeric');
            app.IntertargetintervalmsEditField.Limits = [0 Inf];
            app.IntertargetintervalmsEditField.Value = 1000;
            app.RewardTab = uitab(app.TabGroup3);
            app.RewardTab.Title = 'Reward';
            app.RewardscheduleDropDownLabel = uilabel(app.RewardTab);
            app.RewardscheduleDropDownLabel.Text = 'Reward schedule';
            app.RewardscheduleDropDown = uidropdown(app.RewardTab);
            app.RewardscheduleDropDown.Items = {'Per trial', 'Per target', 'Per unit time', 'Continuous'};
            app.RewardscheduleDropDown.Value = 'Per unit time';
            app.TTLdurmsEditFieldLabel = uilabel(app.RewardTab);
            app.TTLdurmsEditFieldLabel.Text = 'TTL dur (ms)';
            app.TTLdurmsEditField = uieditfield(app.RewardTab, 'numeric');
            app.TTLdurmsEditField.Limits = [0 10000];
            app.TTLdurmsEditField.Value = 100;
            app.RewardintervalmsEditFieldLabel = uilabel(app.RewardTab);
            app.RewardintervalmsEditFieldLabel.Text = 'Reward interval (ms)';
            app.RewardintervalmsEditField = uieditfield(app.RewardTab, 'numeric');
            app.RewardintervalmsEditField.Limits = [0 Inf];
            app.RewardintervalmsEditField.Value = 500;
            app.MaxbreakmsEditFieldLabel = uilabel(app.RewardTab);
            app.MaxbreakmsEditFieldLabel.Text = 'Max. break (ms)';
            app.MaxbreakmsEditField = uieditfield(app.RewardTab, 'numeric');
            app.MaxbreakmsEditField.Limits = [0 Inf];
            app.MaxbreakmsEditField.Value = 150;
            app.MinprportionEditFieldLabel = uilabel(app.RewardTab);
            app.MinprportionEditFieldLabel.Text = 'Min. prportion';
            app.MinprportionEditField = uieditfield(app.RewardTab, 'numeric');
            app.MinprportionEditField.Limits = [0 1];
            app.MinprportionEditField.Value = 0.8;
            app.FixwindowradiusDVAEditFieldLabel = uilabel(app.RewardTab);
            app.FixwindowradiusDVAEditFieldLabel.Text = 'Fix window radius (DVA)';
            app.FixwindowradiusDVAEditField = uieditfield(app.RewardTab, 'numeric');
            app.FixwindowradiusDVAEditField.Value = 2;
            app.PlotsTab = uitab(app.TabGroup3);
            app.PlotsTab.Title = 'Plots';
            app.AverageCheckBox = uicheckbox(app.PlotsTab);
            app.AverageCheckBox.Text = 'Average';
            app.AverageCheckBox.Value = true;
            app.TimetoaveragemsEditFieldLabel = uilabel(app.PlotsTab);
            app.TimetoaveragemsEditFieldLabel.Text = 'Time to average (ms)';
            app.TimetoaveragemsEditField = uieditfield(app.PlotsTab, 'numeric');
            app.MarkerdiameterpxEditFieldLabel = uilabel(app.PlotsTab);
            app.MarkerdiameterpxEditFieldLabel.Text = 'Marker diameter (px)';
            app.MarkerdiameterpxEditField = uieditfield(app.PlotsTab, 'numeric');
            app.MarkerdiameterpxEditField.Limits = [0 Inf];
            app.MarkerdiameterpxEditField.Value = 20;
            app.ShowdistributionmapCheckBox = uicheckbox(app.PlotsTab);
            app.ShowdistributionmapCheckBox.Text = 'Show distribution map';
            app.DisplaycolorsLabel = uilabel(app.PlotsTab);
            app.DisplaycolorsLabel.Text = 'Display colors';
            app.ValidButton = uibutton(app.PlotsTab, 'push');
            app.ValidButton.Text = 'Valid';
            app.InvalidButton = uibutton(app.PlotsTab, 'push');
            app.InvalidButton.Text = 'Invalid';
            app.OptionsPanel = uipanel(app.NTBEyeCalSettingsUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.Button_Docs = uibutton(app.OptionsPanel, 'push');
            app.Button_Docs.Icon = 'ReadTheDocs.png';
            app.Button_Docs.Tooltip = {'Open the NTB documentation webpage for DisplaySettings.'};
            app.Button_Docs.Text = '';
            app.Button_Save = uibutton(app.OptionsPanel, 'push');
            app.Button_Save.Icon = 'Save.png';
            app.Button_Save.Tooltip = {'Save current display settings to Params file.'};
            app.Button_Save.Text = '';
            app.Button_Load = uibutton(app.OptionsPanel, 'push');
            app.Button_Load.Icon = 'Transfer.png';
            app.Button_Load.Tooltip = {'Load display settings from a different parameter file.'};
            app.Button_Load.Text = '';
            app.Button_Docs_2 = uibutton(app.OptionsPanel, 'push');
            app.Button_Docs_2.Icon = 'Exit.png';
            app.Button_Docs_2.Tooltip = {'Exit Display Settings GUI without updating Display parameters.'};
            app.Button_Docs_2.Text = '';
            app.Button_2 = uibutton(app.OptionsPanel, 'state');
            app.Button_2.Icon = 'Play.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.OptionsPanel, 'push');
            app.Button_3.Icon = 'Exit.png';
            app.Button_3.Text = '';
            app.DataPanel = uipanel(app.NTBEyeCalSettingsUIFigure);
            app.DataPanel.Title = 'Data';
            app.UIAxes2 = uiaxes(app.DataPanel);
            title(app.UIAxes2, 'Raw signal')
            ylabel(app.UIAxes2, 'V')
            app.UIAxes2.XTickLabel = '';
            app.UIAxes3 = uiaxes(app.DataPanel);
            title(app.UIAxes3, 'Eye position')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Pos (DVA)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.DataPanel);
            title(app.UIAxes4, 'Fixation distribution')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.RunningLampLabel = uilabel(app.DataPanel);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.DataPanel);
            % Show the figure after all components are created
            app.NTBEyeCalSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NTB_EyeCalSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NTBEyeCalSettingsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.NTBEyeCalSettingsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NTBEyeCalSettingsUIFigure)
        end
    end
end