classdef NTB_GitHub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NTBGitHubSettingsUIFigure       matlab.ui.Figure
        Button_GitHub                   matlab.ui.control.Button
        Button_ReadTheDocs              matlab.ui.control.Button
        LoggedinasLabel                 matlab.ui.control.Label
        HostnameLabel                   matlab.ui.control.Label
        GitHubusernameLabel             matlab.ui.control.Label
        LocalrepositoryEditFieldLabel   matlab.ui.control.Label
        LocalrepositoryEditField        matlab.ui.control.EditField
        GitHubrepositoryEditFieldLabel  matlab.ui.control.Label
        GitHubrepositoryEditField       matlab.ui.control.EditField
        DropDown_GitUsername            matlab.ui.control.DropDown
        EditField_Hostname              matlab.ui.control.EditField
        EditField_Username              matlab.ui.control.EditField
        Button_SelectLocalRepo          matlab.ui.control.Button
        Button_SelectGitRepo            matlab.ui.control.Button
        Button_GitPush                  matlab.ui.control.Button
        Button_GitPull                  matlab.ui.control.Button
        UseSSHKeyCheckBox               matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
        function results = CheckGitDir(app, GitDir)
            if ~isfolder(GitDir)
                uialert(app.NTBGit , sprintf('The directory ''%s'' was not detected!', GitDir), 'Directory not found');
                return;
            end
            Content     = dir(GitDir);
            IsGitRepo   = any(contains({Content.name}, '.git'));
            if IsGitRepo
                app.LocalrepositoryEditField.Value = GitDir;
                app.LocalrepositoryEditField.BackgroundColor = [0.9,0.9,0.9];
                app.Button_GitPull.Enable = 1;
            else
                app.LocalrepositoryEditField.BackgroundColor = [1,0,0];
                uialert(app.NTBGit , 'Selected directory is not initialized as a Git repository!', 'Not a Git repository');
                app.Button_GitPull.Enable = 0;
                app.Button_GitPush.Enable = 0;
            end
            
            
        end
        
        function results = MakeOSpath(app, path)
            
            SepIndx = strfind(path, filesep);
            
            ShareNames      = {'PROJECTS',''};
            ShareLetters    = {};
            if ~ismac && any(strfind(lower(path), 'volumes'))
                NewPath = path(SepIndx(2):end);
            else
                NewPath = path;
            end
            if IsWin
                ShareName = path(SepIndx(2)+1:SepIndx(3)-1);
                if strcmpi(ShareName, 'PROJECTS')
                    Prefix = ['P:/'];
                else
                    fprintf('')
                end
            end
              
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Get user and system names
            [~,Hostname] = system('hostname');
            if ismac
                Username = getenv('USER');
            elseif IsWin
                Username = getenv('username');
            end
            app.EditField_Username.Value = Username;
            app.EditField_Hostname.Value = Hostname;
            
            % Default to 'NIF user' without push access
            app.DropDown_GitUsername.Value = 'None';
            app.Button_GitPush.Enable = 0;
            GitDir;
            CheckGitDir(GitDir);


        end

        % Value changed function: DropDown_GitUsername
        function DropDown_GitUsernameValueChanged(app, event)
            value = app.DropDown_GitUsername.Value;
            
        end

        % Value changed function: LocalrepositoryEditField
        function LocalrepositoryEditFieldValueChanged(app, event)
            value = app.LocalrepositoryEditField.Value;
            
        end

        % Button pushed function: Button_SelectLocalRepo
        function Button_SelectLocalRepoPushed(app, event)
            
            GitDir      = uigetdir('Select the local Git repository to pull to');
            if GitDir ~= 0
               CheckGitDir(GitDir);
            end
        end

        % Value changed function: GitHubrepositoryEditField
        function GitHubrepositoryEditFieldValueChanged(app, event)
            value = app.GitHubrepositoryEditField.Value;
            
        end

        % Button pushed function: Button_SelectGitRepo
        function Button_SelectGitRepoPushed(app, event)
            
        end

        % Value changed function: UseSSHKeyCheckBox
        function UseSSHKeyCheckBoxValueChanged(app, event)
            value = app.UseSSHKeyCheckBox.Value;
            
        end

        % Button pushed function: Button_GitPull
        function Button_GitPullPushed(app, event)
            
            % Check write permissions
            
            
            Msg = sprintf('Are you sure you want to pull updates from the GitHub repository ''%s'' to local repository ''%s''?', app.GitHubrepositoryEditField.Value, app.LocalrepositoryEditField.Value);
            
            
        end

        % Button pushed function: Button_GitPush
        function Button_GitPushPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NTBGitHubSettingsUIFigure = uifigure('Visible', 'off');
            app.NTBGitHubSettingsUIFigure.Name = 'NTB GitHub Settings';
            app.NTBGitHubSettingsUIFigure.Tag = 'NTB_GitHub';
            app.Button_GitHub = uibutton(app.NTBGitHubSettingsUIFigure, 'push');
            app.Button_GitHub.Icon = 'Github.png';
            app.Button_GitHub.Tooltip = {'Open GitHub repository in browser'};
            app.Button_GitHub.Text = '';
            app.Button_ReadTheDocs = uibutton(app.NTBGitHubSettingsUIFigure, 'push');
            app.Button_ReadTheDocs.Icon = 'ReadTheDocs.png';
            app.Button_ReadTheDocs.Tooltip = {'Go to NIF Toolbar documentation'};
            app.Button_ReadTheDocs.Text = '';
            app.LoggedinasLabel = uilabel(app.NTBGitHubSettingsUIFigure);
            app.LoggedinasLabel.Text = 'Logged in as:';
            app.HostnameLabel = uilabel(app.NTBGitHubSettingsUIFigure);
            app.HostnameLabel.Text = 'Host name:';
            app.GitHubusernameLabel = uilabel(app.NTBGitHubSettingsUIFigure);
            app.GitHubusernameLabel.Text = 'GitHub username:';
            app.LocalrepositoryEditFieldLabel = uilabel(app.NTBGitHubSettingsUIFigure);
            app.LocalrepositoryEditFieldLabel.Text = 'Local repository';
            app.LocalrepositoryEditField = uieditfield(app.NTBGitHubSettingsUIFigure, 'text');
            app.LocalrepositoryEditField.ValueChangedFcn = createCallbackFcn(app, @LocalrepositoryEditFieldValueChanged, true);
            app.LocalrepositoryEditField.Value = '/PROJECTS/murphyap_NIF/NIF_Code/NIF-Toolbar';
            app.GitHubrepositoryEditFieldLabel = uilabel(app.NTBGitHubSettingsUIFigure);
            app.GitHubrepositoryEditFieldLabel.Text = 'GitHub repository';
            app.GitHubrepositoryEditField = uieditfield(app.NTBGitHubSettingsUIFigure, 'text');
            app.GitHubrepositoryEditField.ValueChangedFcn = createCallbackFcn(app, @GitHubrepositoryEditFieldValueChanged, true);
            app.GitHubrepositoryEditField.Value = 'git@github.com:Phenomenal-Cat/NIF-Toolbar.git';
            app.DropDown_GitUsername = uidropdown(app.NTBGitHubSettingsUIFigure);
            app.DropDown_GitUsername.Items = {'None', 'Phenomenal-Cat', 'aclark4life', 'yud-nih', '', ''};
            app.DropDown_GitUsername.ValueChangedFcn = createCallbackFcn(app, @DropDown_GitUsernameValueChanged, true);
            app.DropDown_GitUsername.Value = 'None';
            app.EditField_Hostname = uieditfield(app.NTBGitHubSettingsUIFigure, 'text');
            app.EditField_Hostname.Editable = 'off';
            app.EditField_Username = uieditfield(app.NTBGitHubSettingsUIFigure, 'text');
            app.EditField_Username.Editable = 'off';
            app.Button_SelectLocalRepo = uibutton(app.NTBGitHubSettingsUIFigure, 'push');
            app.Button_SelectLocalRepo.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectLocalRepoPushed, true);
            app.Button_SelectLocalRepo.Text = '...';
            app.Button_SelectGitRepo = uibutton(app.NTBGitHubSettingsUIFigure, 'push');
            app.Button_SelectGitRepo.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectGitRepoPushed, true);
            app.Button_SelectGitRepo.Text = '...';
            app.Button_GitPush = uibutton(app.NTBGitHubSettingsUIFigure, 'push');
            app.Button_GitPush.ButtonPushedFcn = createCallbackFcn(app, @Button_GitPushPushed, true);
            app.Button_GitPush.Icon = 'Git_Push.png';
            app.Button_GitPush.Enable = 'off';
            app.Button_GitPush.Tooltip = {'Push changes to GitHub repository'};
            app.Button_GitPush.Text = '';
            app.Button_GitPull = uibutton(app.NTBGitHubSettingsUIFigure, 'push');
            app.Button_GitPull.ButtonPushedFcn = createCallbackFcn(app, @Button_GitPullPushed, true);
            app.Button_GitPull.Icon = 'Git_Pull.png';
            app.Button_GitPull.Tooltip = {'Pull updates from GitHub repository'};
            app.Button_GitPull.Text = '';
            app.UseSSHKeyCheckBox = uicheckbox(app.NTBGitHubSettingsUIFigure);
            app.UseSSHKeyCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSSHKeyCheckBoxValueChanged, true);
            app.UseSSHKeyCheckBox.Enable = 'off';
            app.UseSSHKeyCheckBox.Tooltip = {'Use SSH (mandatory)'};
            app.UseSSHKeyCheckBox.Text = 'Use SSH Key?';
            app.UseSSHKeyCheckBox.Value = true;
            % Show the figure after all components are created
            app.NTBGitHubSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NTB_GitHub

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NTBGitHubSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NTBGitHubSettingsUIFigure)
        end
    end
end