classdef NTB_ImageSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NTBImageSettingsUIFigure        matlab.ui.Figure
        OptionsPanel                    matlab.ui.container.Panel
        Button_Docs                     matlab.ui.control.Button
        Button_Save                     matlab.ui.control.Button
        Button_Load                     matlab.ui.control.Button
        Button_Close                    matlab.ui.control.Button
        Button_LoadTex                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SelectionTab                    matlab.ui.container.Tab
        ImageDirectoryButton            matlab.ui.control.Button
        ImageformatDropDownLabel        matlab.ui.control.Label
        ImageformatDropDown             matlab.ui.control.DropDown
        SubdirectoriesDropDownLabel     matlab.ui.control.Label
        SubdirectoriesDropDown          matlab.ui.control.DropDown
        ConditionsDropDownLabel         matlab.ui.control.Label
        ConditionsDropDown              matlab.ui.control.DropDown
        BackgroundDirButton             matlab.ui.control.Button
        SBS3D                           matlab.ui.control.CheckBox
        SBS3DformatLabel                matlab.ui.control.Label
        ImagedirectoryEditFieldLabel_2  matlab.ui.control.Label
        Imagedirectory                  matlab.ui.control.EditField
        BackgrounddirEditFieldLabel     matlab.ui.control.Label
        Backgrounddir                   matlab.ui.control.EditField
        BackgroundsDropDownLabel        matlab.ui.control.Label
        BackgroundsDropDown             matlab.ui.control.DropDown
        TransformsTab                   matlab.ui.container.Tab
        Fullscreen                      matlab.ui.control.CheckBox
        PresentfullscreenLabel          matlab.ui.control.Label
        StimuluswidthLabel              matlab.ui.control.Label
        StimulusWidth                   matlab.ui.control.NumericEditField
        UsealphachannelLabel            matlab.ui.control.Label
        UseAlphaChannel                 matlab.ui.control.CheckBox
        ApplymaskDropDownLabel          matlab.ui.control.Label
        ApplymaskDropDown               matlab.ui.control.DropDown
        ImageRotation                   matlab.ui.control.NumericEditField
        ImageRotationFieldLabel         matlab.ui.control.Label
        ImagecontrastSliderLabel        matlab.ui.control.Label
        ImagecontrastSlider             matlab.ui.control.Slider
        ColorDropDownLabel              matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        StimulusUnits                   matlab.ui.control.DropDown
        NormalizeluminanceDropDownLabel  matlab.ui.control.Label
        NormalizeluminanceDropDown      matlab.ui.control.DropDown
        PresentationTab                 matlab.ui.container.Tab
        StimulusorderLabel              matlab.ui.control.Label
        StimulusorderDropDown           matlab.ui.control.DropDown
        TrialsperrunEditFieldLabel      matlab.ui.control.Label
        TrialsperrunEditField           matlab.ui.control.NumericEditField
        StimpertrialEditFieldLabel      matlab.ui.control.Label
        StimpertrialEditField           matlab.ui.control.NumericEditField
        StimdurationmsEditFieldLabel    matlab.ui.control.Label
        StimdurationmsEditField         matlab.ui.control.NumericEditField
        InterstimintervalmsEditFieldLabel  matlab.ui.control.Label
        InterstimintervalmsEditField    matlab.ui.control.NumericEditField
        IntertrialintervalmsEditFieldLabel  matlab.ui.control.Label
        IntertrialintervalmsEditField   matlab.ui.control.NumericEditField
        TemporaljittermeanmsEditFieldLabel  matlab.ui.control.Label
        TemporaljittermeanmsEditField   matlab.ui.control.NumericEditField
        fMRITab                         matlab.ui.container.Tab
        BlocksperrunEditFieldLabel      matlab.ui.control.Label
        BlocksperrunEditField           matlab.ui.control.NumericEditField
        StimperblockEditFieldLabel      matlab.ui.control.Label
        StimperblockEditField           matlab.ui.control.NumericEditField
        StimdurationmsEditField_2Label  matlab.ui.control.Label
        StimdurationmsEditField_2       matlab.ui.control.NumericEditField
        InterstimintervalmsEditField_2Label  matlab.ui.control.Label
        InterstimintervalmsEditField_2  matlab.ui.control.NumericEditField
        CheckBox_4                      matlab.ui.control.CheckBox
        SyncstimtoTTLLabel              matlab.ui.control.Label
        AddfixationblocksDropDownLabel  matlab.ui.control.Label
        AddfixationblocksDropDown       matlab.ui.control.DropDown
        FixationTab                     matlab.ui.container.Tab
        FixationrequiredLabel           matlab.ui.control.Label
        CheckBox_5                      matlab.ui.control.CheckBox
        FixwindowsizeEditFieldLabel     matlab.ui.control.Label
        FixwindowsizeEditField          matlab.ui.control.NumericEditField
        FixwindowsizeEditField_2        matlab.ui.control.NumericEditField
        StimuliPanel                    matlab.ui.container.Panel
        NoimagesEditFieldLabel          matlab.ui.control.Label
        NoimagesEditField               matlab.ui.control.NumericEditField
        ImagerespxEditFieldLabel        matlab.ui.control.Label
        ImageResX                       matlab.ui.control.NumericEditField
        ImageResY                       matlab.ui.control.NumericEditField
        PreviewimageDropDownLabel       matlab.ui.control.Label
        PreviewimageDropDown            matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Image                 % Image parameters field
        NTBapp
        Preview
    end
    
    methods (Access = private)
        
        %========= Update image set info
        function results = UpdateImages(app)

            switch app.Image.Source.SubDirOpt
                case 'Ignore'
                    Recursive = 0;
                    app.Image.Source.AllImFiles = regexpdir(app.Image.Source.ImDir, sprintf('^.*%s$', app.Image.Source.Format), Recursive);
                    app.Image.Source.AllImFiles = app.Image.Source.AllImFiles(2:end);
                    app.Image.Source.Conditions = {''};
                    
                case 'Load all'
                    app.Image.Source.AllImFiles = wildcardsearch(app.Image.Source.ImDir, ['*.',app.Image.Source.Format]);
                    app.Image.Source.Conditions = {''};
                    
                case 'Use as conditions'
                    app.Image.Source.AllImFiles = wildcardsearch(app.Image.Source.ImDir, ['*.',app.Image.Source.Format]);
                    SubDirs                     = dir(app.Image.Source.ImageDir);
                    app.Image.Source.Conditions = {SubDirs([SubDirs.isdir]).name};

            end
            if isempty(app.Image.Source.AllImFiles)
                fprintf('No %s images found in %s!\n', app.Image.Source.Format, app.Image.Source.ImDir);
                app.NoimagesEditField.Value = 0;
                
                return;
            end
            app.ConditionsDropDown.Items = app.Image.Source.Conditions;
            app.ConditionsDropDown.Value = app.Image.Source.Conditions{1};
            app.NoimagesEditField.Value = numel(app.Image.Source.AllImFiles);
            [P, Filenames, N] = cellfun(@fileparts, app.Image.Source.AllImFiles, 'UniformOutput', false);
            app.PreviewimageDropDown.Items = Filenames;
            app.PreviewimageDropDown.Value = Filenames{1};
            
            UpdateImagePreview(app);
            
        end
        
        %========== Update image preview axes
        function UpdateImagePreview(app)
            if ~exist(app.Image.Source.AllImFiles{app.Preview.ImIndx},'file')
                fprintf('Image file does nto exist!\n');
                return;
            end
            [Im, cmap, alp] = imread(app.Image.Source.AllImFiles{app.Preview.ImIndx});
            app.Image.Source.Resolution = [size(Im, 1), size(Im, 2)];
            app.ImageResX.Value = app.Image.Source.Resolution(1);
            app.ImageResY.Value = app.Image.Source.Resolution(2);
            switch app.Image.Transform.ColorMode
                case 'Greyscale'
                    Im = rgb2gray(Im);
                    Im = repmat(Im, [1,1,3]);
                case 'Hue inverted'
                    hsv = rgb2hsv(Im);
                    Hue = hsv(:,:,1);
                    Hue = Hue+0.5;
                    Hue(Hue>1) = Hue(Hue>1)-1;
                    hsv(:,:,1) = Hue;
                    Im = hsv2rgb(hsv);
            end
            if ~isfield(app.Preview, 'ImH')
                app.Preview.ImH = image(app.UIAxes, Im);
                app.UIAxes.Box = 'off';
                app.UIAxes.DataAspectRatio = [1,1,1];
                app.UIAxes.BackgroundColor = [0.5,0.5,0.5];
                app.UIAxes.Color           = [0.5,0.5,0.5];
            else
                set(app.Preview.ImH, 'Cdata', Im);
            end 
            if ~isempty(alp) && app.Image.Transform.UseAlpha == 1
                set(app.Preview.ImH, 'AlphaData', alp*app.Image.Transform.Contrast)
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NTBapp, Image)
            app.Image           = Image;
            app.NTBapp          = NTBapp;
            app.Preview.ImIndx  = 1;
            
            %========= Source tab
            app.Imagedirectory.Value            = app.Image.Source.ImDir;
            app.Backgrounddir.Value             = app.Image.Source.BackgroundDir;
            app.ImageformatDropDown.Value       = app.Image.Source.Format;
            app.SubdirectoriesDropDown.Value    = app.Image.Source.SubDirOpt;
            app.SBS3D.Value                     = app.Image.Source.SBS;
            if isdir(app.Image.Source.ImDir)
                UpdateImages(app);
            end
            
            %========= Transforms tab
            app.Fullscreen.Value                = app.Image.Transform.Fullscreen;
            app.StimulusWidth.Value             = app.Image.Transform.Diameter;
            app.UseAlphaChannel.Value           = app.Image.Transform.UseAlpha;
            app.ColorDropDown.Value             = app.Image.Transform.ColorMode;
            app.ApplymaskDropDown.Value         = app.Image.Transform.MaskType;
            app.ImageRotation.Value             = app.Image.Transform.Rotation;
            app.ImagecontrastSlider.Value       = app.Image.Transform.Contrast;
            
            %========= Presentation tab
            
            
            %========= fMRI tab


            %========= Options menu
            if ~isprop(app, 'Run') || app.Run.WinOpen == 0
                app.Button_LoadTex.Enable = 0;
            elseif app.Run.WinOpen == 1
                app.Button_LoadTex.Enable = 1;
            end
            
        end

        % Value changed function: Imagedirectory
        function ImagedirectoryValueChanged(app, event)
            value = app.Imagedirectory.Value;
            if ~isdir(value)
                ImageDirectoryButtonPushed(app);
            else
                UpdateImages(app);
            end
        end

        % Button pushed function: ImageDirectoryButton
        function ImageDirectoryButtonPushed(app, event)
            path = uigetdir(app.Image.Source.ImDir, 'Select image directory');
            if path == 0
                return
            end
            app.Image.Source.ImDir      = path;
            app.Imagedirectory.Value    = path;
            UpdateImages(app);
        end

        % Value changed function: Backgrounddir
        function BackgrounddirValueChanged(app, event)
            value = app.Backgrounddir.Value;
            
        end

        % Button pushed function: BackgroundDirButton
        function BackgroundDirButtonPushed(app, event)
            
        end

        % Value changed function: ImageformatDropDown
        function ImageformatDropDownValueChanged(app, event)
            value = app.ImageformatDropDown.Value;
            app.Image.Source.Format = value;
            UpdateImages(app);
        end

        % Value changed function: SubdirectoriesDropDown
        function SubdirectoriesDropDownValueChanged(app, event)
            value = app.SubdirectoriesDropDown.Value;
            app.Image.Source.SubDirOpt = value;
            UpdateImages(app);
        end

        % Value changed function: PreviewimageDropDown
        function PreviewimageDropDownValueChanged(app, event)
            value = app.PreviewimageDropDown.Value;
            app.Preview.ImIndx = find(contains(app.PreviewimageDropDown.Items, value));
            UpdateImagePreview(app);
            
        end

        % Value changed function: SBS3D
        function SBS3DValueChanged(app, event)
            value = app.SBS3D.Value;
            app.Image.Source.SBS = value;
        end

        % Value changed function: ConditionsDropDown
        function ConditionsDropDownValueChanged(app, event)
            value = app.ConditionsDropDown.Value;
            
        end

        % Value changed function: Fullscreen
        function FullscreenValueChanged(app, event)
            value = app.Fullscreen.Value;
            app.Image.Transform.Fullscreen = value;
            if value == 1
                app.StimulusWidth.Enable = 0;
            elseif value == 0
                app.StimulusWidth.Enable = 1;
            end
            UpdateImagePreview(app);
        end

        % Value changed function: StimulusWidth
        function StimulusWidthValueChanged(app, event)
            value = app.StimulusWidth.Value;
            UpdateImagePreview(app);
        end

        % Value changed function: UseAlphaChannel
        function UseAlphaChannelValueChanged(app, event)
            value = app.UseAlphaChannel.Value;
            app.Image.Transform.UseAlpha = value;
            UpdateImagePreview(app);
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            app.Image.Transform.ColorMode = value;
            UpdateImagePreview(app);
        end

        % Value changed function: ApplymaskDropDown
        function ApplymaskDropDownValueChanged(app, event)
            value = app.ApplymaskDropDown.Value;
            app.Image.Transform.MaskType = value;
            UpdateImagePreview(app);
        end

        % Value changed function: ImageRotation
        function ImageRotationValueChanged(app, event)
            value = app.ImageRotation.Value;
            app.Image.Transform.Rotation = value;
            UpdateImagePreview(app);
        end

        % Value changed function: ImagecontrastSlider
        function ImagecontrastSliderValueChanged(app, event)
            value = app.ImagecontrastSlider.Value;
            app.Image.Transform.Contrast = value;
            UpdateImagePreview(app);
        end

        % Value changed function: StimulusUnits
        function StimulusUnitsValueChanged(app, event)
            value = app.StimulusUnits.Value;
            app.Image.Transform.SizeUnits = value;
            
            
            switch app.Image.Transform.SizeUnits
                case '(°)'
                    app.StimulusWidth.Value = app.Image.Transform.StimWidthDVA;
                case '(px)'
                    app.StimulusWidth.Value = app.Image.Transform.StimWidthPx;
                case '(cm)'
                    app.StimulusWidth.Value = app.Image.Transform.StimWidthCm;
                case '(%)'
                    app.StimulusWidth.Value = app.Image.Transform.StimWidthPerc;
            end
            app.StimulusWidth.Value = NewValue;
        end

        % Button pushed function: Button_Save
        function Button_SavePushed(app, event)
            ParamsFile  = app.NTBapp.Params.File;
            selection   = uiconfirm(app.NTBImageSettingsUIFigure, sprintf('Save Image settings to parameter file %s?', ParamsFile), 'Save', 'Options',{'Save','Cancel'}, 'Icon', 'Save.png');
            if strcmp(selection, 'Save')
                Image     = app.Image;
                save(ParamsFile, 'Image','-append');
                uiconfirm(app.NTBImageSettingsUIFigure, sprintf('Current image parameters were written to %s.', ParamsFile), 'Image parameters saved', 'Icon', 'success');
            end
        end

        % Button pushed function: Button_Load
        function Button_LoadPushed(app, event)
            
        end

        % Button pushed function: Button_Docs
        function Button_DocsPushed(app, event)
            
        end

        % Button pushed function: Button_Close
        function Button_ClosePushed(app, event)
            
        end

        % Button pushed function: Button_LoadTex
        function Button_LoadTexPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NTBImageSettingsUIFigure = uifigure('Visible', 'off');
            app.NTBImageSettingsUIFigure.Name = 'NTB Image Settings';
            app.NTBImageSettingsUIFigure.Tag = 'NTB_ImageSettings';
            app.OptionsPanel = uipanel(app.NTBImageSettingsUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.Button_Docs = uibutton(app.OptionsPanel, 'push');
            app.Button_Docs.ButtonPushedFcn = createCallbackFcn(app, @Button_DocsPushed, true);
            app.Button_Docs.Icon = 'ReadTheDocs.png';
            app.Button_Docs.Tooltip = {'Open the NTB documentation webpage for Image Settings.'};
            app.Button_Docs.Text = '';
            app.Button_Save = uibutton(app.OptionsPanel, 'push');
            app.Button_Save.ButtonPushedFcn = createCallbackFcn(app, @Button_SavePushed, true);
            app.Button_Save.Icon = 'Save.png';
            app.Button_Save.Tooltip = {'Save current Image settings to Params file.'};
            app.Button_Save.Text = '';
            app.Button_Load = uibutton(app.OptionsPanel, 'push');
            app.Button_Load.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPushed, true);
            app.Button_Load.Icon = 'Transfer.png';
            app.Button_Load.Tooltip = {'Load Image settings from a different parameter file.'};
            app.Button_Load.Text = '';
            app.Button_Close = uibutton(app.OptionsPanel, 'push');
            app.Button_Close.ButtonPushedFcn = createCallbackFcn(app, @Button_ClosePushed, true);
            app.Button_Close.Icon = 'Exit.png';
            app.Button_Close.Tooltip = {'Exit Image Settings GUI without updating Image parameters.'};
            app.Button_Close.Text = '';
            app.Button_LoadTex = uibutton(app.OptionsPanel, 'push');
            app.Button_LoadTex.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadTexPushed, true);
            app.Button_LoadTex.Icon = 'Slideshow.png';
            app.Button_LoadTex.Tooltip = {'Load selected images to PTB textures.'};
            app.Button_LoadTex.Text = '';
            app.TabGroup = uitabgroup(app.NTBImageSettingsUIFigure);
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            app.ImageDirectoryButton = uibutton(app.SelectionTab, 'push');
            app.ImageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ImageDirectoryButtonPushed, true);
            app.ImageDirectoryButton.Tooltip = {'Click to open image directory selection window'};
            app.ImageDirectoryButton.Text = '...';
            app.ImageformatDropDownLabel = uilabel(app.SelectionTab);
            app.ImageformatDropDownLabel.Text = 'Image format';
            app.ImageformatDropDown = uidropdown(app.SelectionTab);
            app.ImageformatDropDown.Items = {'PNG', 'JPG', 'BMP', 'Any'};
            app.ImageformatDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageformatDropDownValueChanged, true);
            app.ImageformatDropDown.Tooltip = {'File format of images to load'};
            app.ImageformatDropDown.Value = 'PNG';
            app.SubdirectoriesDropDownLabel = uilabel(app.SelectionTab);
            app.SubdirectoriesDropDownLabel.Text = 'Subdirectories';
            app.SubdirectoriesDropDown = uidropdown(app.SelectionTab);
            app.SubdirectoriesDropDown.Items = {'Ignore', 'Load all', 'Use as conditions'};
            app.SubdirectoriesDropDown.ValueChangedFcn = createCallbackFcn(app, @SubdirectoriesDropDownValueChanged, true);
            app.SubdirectoriesDropDown.Tooltip = {'Choose how to treat subdirectories found inside the image driectory'};
            app.SubdirectoriesDropDown.Value = 'Ignore';
            app.ConditionsDropDownLabel = uilabel(app.SelectionTab);
            app.ConditionsDropDownLabel.Text = 'Conditions';
            app.ConditionsDropDown = uidropdown(app.SelectionTab);
            app.ConditionsDropDown.Items = {};
            app.ConditionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionsDropDownValueChanged, true);
            app.ConditionsDropDown.Tooltip = {'If ''Use as conditions'' was selected for ''Subdirectories'' above, this drowndown menu lists the names of all subdirectories found in the image directory'};
            app.ConditionsDropDown.Value = {};
            app.BackgroundDirButton = uibutton(app.SelectionTab, 'push');
            app.BackgroundDirButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundDirButtonPushed, true);
            app.BackgroundDirButton.Tooltip = {'Click to open background image directory selection window'};
            app.BackgroundDirButton.Text = '...';
            app.SBS3D = uicheckbox(app.SelectionTab);
            app.SBS3D.ValueChangedFcn = createCallbackFcn(app, @SBS3DValueChanged, true);
            app.SBS3D.Tooltip = {'Are the images in a side-by-side stereoscopic 3D format?'};
            app.SBS3D.Text = '';
            app.SBS3DformatLabel = uilabel(app.SelectionTab);
            app.SBS3DformatLabel.Text = 'SBS 3D format?';
            app.ImagedirectoryEditFieldLabel_2 = uilabel(app.SelectionTab);
            app.ImagedirectoryEditFieldLabel_2.Text = 'Image directory';
            app.Imagedirectory = uieditfield(app.SelectionTab, 'text');
            app.Imagedirectory.ValueChangedFcn = createCallbackFcn(app, @ImagedirectoryValueChanged, true);
            app.Imagedirectory.Tooltip = {'Full path of the directory to load images from'};
            app.BackgrounddirEditFieldLabel = uilabel(app.SelectionTab);
            app.BackgrounddirEditFieldLabel.Text = 'Background dir';
            app.Backgrounddir = uieditfield(app.SelectionTab, 'text');
            app.Backgrounddir.ValueChangedFcn = createCallbackFcn(app, @BackgrounddirValueChanged, true);
            app.Backgrounddir.Tooltip = {'Full path of the directory to load background images from'};
            app.BackgroundsDropDownLabel = uilabel(app.SelectionTab);
            app.BackgroundsDropDownLabel.Text = 'Backgrounds';
            app.BackgroundsDropDown = uidropdown(app.SelectionTab);
            app.BackgroundsDropDown.Items = {'Randomize per trial', 'Randomize per block', 'Unique per image'};
            app.BackgroundsDropDown.Tooltip = {'How background images should be used'};
            app.BackgroundsDropDown.Value = 'Randomize per trial';
            app.TransformsTab = uitab(app.TabGroup);
            app.TransformsTab.Title = 'Transforms';
            app.Fullscreen = uicheckbox(app.TransformsTab);
            app.Fullscreen.ValueChangedFcn = createCallbackFcn(app, @FullscreenValueChanged, true);
            app.Fullscreen.Tooltip = {'Present the images at fullscreen size? (Images will be resized to fit the screen if necessary, but without changing aspect ratio)'};
            app.Fullscreen.Text = '';
            app.PresentfullscreenLabel = uilabel(app.TransformsTab);
            app.PresentfullscreenLabel.Text = 'Present fullscreen?';
            app.StimuluswidthLabel = uilabel(app.TransformsTab);
            app.StimuluswidthLabel.Text = 'Stimulus width';
            app.StimulusWidth = uieditfield(app.TransformsTab, 'numeric');
            app.StimulusWidth.Limits = [0 Inf];
            app.StimulusWidth.ValueChangedFcn = createCallbackFcn(app, @StimulusWidthValueChanged, true);
            app.StimulusWidth.Tooltip = {'Dimension (degrees of visual angle) to present the stimulus at if fullscreen was not selected'};
            app.StimulusWidth.Value = 10;
            app.UsealphachannelLabel = uilabel(app.TransformsTab);
            app.UsealphachannelLabel.Text = 'Use alpha channel?';
            app.UseAlphaChannel = uicheckbox(app.TransformsTab);
            app.UseAlphaChannel.ValueChangedFcn = createCallbackFcn(app, @UseAlphaChannelValueChanged, true);
            app.UseAlphaChannel.Text = '';
            app.UseAlphaChannel.Value = true;
            app.ApplymaskDropDownLabel = uilabel(app.TransformsTab);
            app.ApplymaskDropDownLabel.Text = 'Apply mask';
            app.ApplymaskDropDown = uidropdown(app.TransformsTab);
            app.ApplymaskDropDown.Items = {'None', 'Circular', 'Cosine', 'Gaussian'};
            app.ApplymaskDropDown.ValueChangedFcn = createCallbackFcn(app, @ApplymaskDropDownValueChanged, true);
            app.ApplymaskDropDown.Value = 'None';
            app.ImageRotation = uieditfield(app.TransformsTab, 'numeric');
            app.ImageRotation.Limits = [0 Inf];
            app.ImageRotation.ValueChangedFcn = createCallbackFcn(app, @ImageRotationValueChanged, true);
            app.ImageRotationFieldLabel = uilabel(app.TransformsTab);
            app.ImageRotationFieldLabel.Text = 'Image rotation (°)';
            app.ImagecontrastSliderLabel = uilabel(app.TransformsTab);
            app.ImagecontrastSliderLabel.Text = 'Image contrast';
            app.ImagecontrastSlider = uislider(app.TransformsTab);
            app.ImagecontrastSlider.Limits = [0 1];
            app.ImagecontrastSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastSliderValueChanged, true);
            app.ImagecontrastSlider.Value = 1;
            app.ColorDropDownLabel = uilabel(app.TransformsTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.TransformsTab);
            app.ColorDropDown.Items = {'Original', 'Greyscale', 'Hue inverted'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'Original';
            app.StimulusUnits = uidropdown(app.TransformsTab);
            app.StimulusUnits.Items = {'(°)', '(cm)', '(px)', '(%)'};
            app.StimulusUnits.ValueChangedFcn = createCallbackFcn(app, @StimulusUnitsValueChanged, true);
            app.StimulusUnits.Value = '(°)';
            app.NormalizeluminanceDropDownLabel = uilabel(app.TransformsTab);
            app.NormalizeluminanceDropDownLabel.Text = 'Normalize luminance';
            app.NormalizeluminanceDropDown = uidropdown(app.TransformsTab);
            app.NormalizeluminanceDropDown.Items = {'None', 'Stim set average', '50%'};
            app.NormalizeluminanceDropDown.Value = 'None';
            app.PresentationTab = uitab(app.TabGroup);
            app.PresentationTab.Title = 'Presentation';
            app.StimulusorderLabel = uilabel(app.PresentationTab);
            app.StimulusorderLabel.Text = 'Stimulus order';
            app.StimulusorderDropDown = uidropdown(app.PresentationTab);
            app.StimulusorderDropDown.Items = {'Randomized', 'fMRI block design'};
            app.StimulusorderDropDown.Value = 'fMRI block design';
            app.TrialsperrunEditFieldLabel = uilabel(app.PresentationTab);
            app.TrialsperrunEditFieldLabel.Text = {'Trials per run'; ''};
            app.TrialsperrunEditField = uieditfield(app.PresentationTab, 'numeric');
            app.TrialsperrunEditField.Limits = [1 Inf];
            app.TrialsperrunEditField.Value = 100;
            app.StimpertrialEditFieldLabel = uilabel(app.PresentationTab);
            app.StimpertrialEditFieldLabel.Text = {'Stim. per trial'; ''};
            app.StimpertrialEditField = uieditfield(app.PresentationTab, 'numeric');
            app.StimpertrialEditField.Limits = [1 Inf];
            app.StimpertrialEditField.Value = 5;
            app.StimdurationmsEditFieldLabel = uilabel(app.PresentationTab);
            app.StimdurationmsEditFieldLabel.Text = {'Stim.duration (ms)'; ''};
            app.StimdurationmsEditField = uieditfield(app.PresentationTab, 'numeric');
            app.StimdurationmsEditField.Limits = [1 Inf];
            app.StimdurationmsEditField.Value = 300;
            app.InterstimintervalmsEditFieldLabel = uilabel(app.PresentationTab);
            app.InterstimintervalmsEditFieldLabel.Text = {'Inter-stim interval (ms)'; ''};
            app.InterstimintervalmsEditField = uieditfield(app.PresentationTab, 'numeric');
            app.InterstimintervalmsEditField.Limits = [0 Inf];
            app.InterstimintervalmsEditField.Value = 300;
            app.IntertrialintervalmsEditFieldLabel = uilabel(app.PresentationTab);
            app.IntertrialintervalmsEditFieldLabel.Text = {'Inter-trial interval (ms)'; ''};
            app.IntertrialintervalmsEditField = uieditfield(app.PresentationTab, 'numeric');
            app.IntertrialintervalmsEditField.Limits = [0 Inf];
            app.IntertrialintervalmsEditField.Value = 2000;
            app.TemporaljittermeanmsEditFieldLabel = uilabel(app.PresentationTab);
            app.TemporaljittermeanmsEditFieldLabel.Text = 'Temporal jitter (mean ms)';
            app.TemporaljittermeanmsEditField = uieditfield(app.PresentationTab, 'numeric');
            app.TemporaljittermeanmsEditField.Limits = [0 Inf];
            app.TemporaljittermeanmsEditField.Value = 100;
            app.fMRITab = uitab(app.TabGroup);
            app.fMRITab.Title = 'fMRI';
            app.BlocksperrunEditFieldLabel = uilabel(app.fMRITab);
            app.BlocksperrunEditFieldLabel.Text = {'Blocks per run'; ''};
            app.BlocksperrunEditField = uieditfield(app.fMRITab, 'numeric');
            app.BlocksperrunEditField.Limits = [1 Inf];
            app.BlocksperrunEditField.Value = 10;
            app.StimperblockEditFieldLabel = uilabel(app.fMRITab);
            app.StimperblockEditFieldLabel.Text = 'Stim. per block';
            app.StimperblockEditField = uieditfield(app.fMRITab, 'numeric');
            app.StimperblockEditField.Limits = [1 Inf];
            app.StimperblockEditField.Value = 20;
            app.StimdurationmsEditField_2Label = uilabel(app.fMRITab);
            app.StimdurationmsEditField_2Label.Text = {'Stim.duration (ms)'; ''};
            app.StimdurationmsEditField_2 = uieditfield(app.fMRITab, 'numeric');
            app.StimdurationmsEditField_2.Limits = [1 Inf];
            app.StimdurationmsEditField_2.Value = 1000;
            app.InterstimintervalmsEditField_2Label = uilabel(app.fMRITab);
            app.InterstimintervalmsEditField_2Label.Text = {'Inter-stim interval (ms)'; ''};
            app.InterstimintervalmsEditField_2 = uieditfield(app.fMRITab, 'numeric');
            app.InterstimintervalmsEditField_2.Limits = [0 Inf];
            app.InterstimintervalmsEditField_2.Value = 1000;
            app.CheckBox_4 = uicheckbox(app.fMRITab);
            app.CheckBox_4.Text = '';
            app.SyncstimtoTTLLabel = uilabel(app.fMRITab);
            app.SyncstimtoTTLLabel.Text = 'Sync stim to TTL?';
            app.AddfixationblocksDropDownLabel = uilabel(app.fMRITab);
            app.AddfixationblocksDropDownLabel.Text = 'Add fixation blocks';
            app.AddfixationblocksDropDown = uidropdown(app.fMRITab);
            app.AddfixationblocksDropDown.Items = {'First and last', 'Alternating', 'None'};
            app.AddfixationblocksDropDown.Value = 'First and last';
            app.FixationTab = uitab(app.TabGroup);
            app.FixationTab.Title = 'Fixation';
            app.FixationrequiredLabel = uilabel(app.FixationTab);
            app.FixationrequiredLabel.Text = 'Fixation required?';
            app.CheckBox_5 = uicheckbox(app.FixationTab);
            app.CheckBox_5.Text = '';
            app.FixwindowsizeEditFieldLabel = uilabel(app.FixationTab);
            app.FixwindowsizeEditFieldLabel.Text = 'Fix window size';
            app.FixwindowsizeEditField = uieditfield(app.FixationTab, 'numeric');
            app.FixwindowsizeEditField_2 = uieditfield(app.FixationTab, 'numeric');
            app.StimuliPanel = uipanel(app.NTBImageSettingsUIFigure);
            app.StimuliPanel.Title = 'Stimuli';
            app.NoimagesEditFieldLabel = uilabel(app.StimuliPanel);
            app.NoimagesEditFieldLabel.Text = 'No. images';
            app.NoimagesEditField = uieditfield(app.StimuliPanel, 'numeric');
            app.NoimagesEditField.Editable = 'off';
            app.ImagerespxEditFieldLabel = uilabel(app.StimuliPanel);
            app.ImagerespxEditFieldLabel.Text = 'Image res (px)';
            app.ImageResX = uieditfield(app.StimuliPanel, 'numeric');
            app.ImageResX.Editable = 'off';
            app.ImageResY = uieditfield(app.StimuliPanel, 'numeric');
            app.ImageResY.Editable = 'off';
            app.PreviewimageDropDownLabel = uilabel(app.StimuliPanel);
            app.PreviewimageDropDownLabel.Text = 'Preview image';
            app.PreviewimageDropDown = uidropdown(app.StimuliPanel);
            app.PreviewimageDropDown.Items = {};
            app.PreviewimageDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewimageDropDownValueChanged, true);
            app.PreviewimageDropDown.Value = {};
            app.UIAxes = uiaxes(app.StimuliPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.NTBImageSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NTB_ImageSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NTBImageSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NTBImageSettingsUIFigure)
        end
    end
end