classdef NTB_MovieSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NTBMovieSettingsUIFigure       matlab.ui.Figure
        OptionsPanel                   matlab.ui.container.Panel
        Button_Docs                    matlab.ui.control.Button
        Button_Save                    matlab.ui.control.Button
        Button_Load                    matlab.ui.control.Button
        Button_Close                   matlab.ui.control.Button
        Button_LoadTex                 matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        SelectionTab                   matlab.ui.container.Tab
        ImageDirectoryButton           matlab.ui.control.Button
        MovieformatDropDownLabel       matlab.ui.control.Label
        MovieformatDropDown            matlab.ui.control.DropDown
        SubdirectoriesDropDownLabel    matlab.ui.control.Label
        SubdirectoriesDropDown         matlab.ui.control.DropDown
        ConditionsDropDownLabel        matlab.ui.control.Label
        ConditionsDropDown             matlab.ui.control.DropDown
        SBS3D                          matlab.ui.control.CheckBox
        SBS3DformatLabel               matlab.ui.control.Label
        MoviedirectoryLabel            matlab.ui.control.Label
        Imagedirectory                 matlab.ui.control.EditField
        DomeformatLabel                matlab.ui.control.Label
        SBS3D_2                        matlab.ui.control.CheckBox
        TransformsTab                  matlab.ui.container.Tab
        Fullscreen                     matlab.ui.control.CheckBox
        PresentfullscreenLabel         matlab.ui.control.Label
        StimuluswidthLabel             matlab.ui.control.Label
        StimulusWidth                  matlab.ui.control.NumericEditField
        ImageRotation                  matlab.ui.control.NumericEditField
        ImageRotationFieldLabel        matlab.ui.control.Label
        ImagecontrastSliderLabel       matlab.ui.control.Label
        ImagecontrastSlider            matlab.ui.control.Slider
        ColorDropDownLabel             matlab.ui.control.Label
        ColorDropDown                  matlab.ui.control.DropDown
        StimulusUnits                  matlab.ui.control.DropDown
        PresentationTab                matlab.ui.container.Tab
        StimulusorderLabel             matlab.ui.control.Label
        StimulusorderDropDown          matlab.ui.control.DropDown
        TrialsperrunEditFieldLabel     matlab.ui.control.Label
        TrialsperrunEditField          matlab.ui.control.NumericEditField
        StimpertrialEditFieldLabel     matlab.ui.control.Label
        StimpertrialEditField          matlab.ui.control.NumericEditField
        StimdurationsEditFieldLabel    matlab.ui.control.Label
        StimdurationsEditField         matlab.ui.control.NumericEditField
        InterstimintervalsEditFieldLabel  matlab.ui.control.Label
        InterstimintervalsEditField    matlab.ui.control.NumericEditField
        IntertrialintervalsEditFieldLabel  matlab.ui.control.Label
        IntertrialintervalsEditField   matlab.ui.control.NumericEditField
        TemporaljittermeanmsEditFieldLabel  matlab.ui.control.Label
        TemporaljittermeanmsEditField  matlab.ui.control.NumericEditField
        FixationTab                    matlab.ui.container.Tab
        FixationrequiredLabel          matlab.ui.control.Label
        CheckBox_5                     matlab.ui.control.CheckBox
        FixwindowsizeEditFieldLabel    matlab.ui.control.Label
        FixwindowsizeEditField         matlab.ui.control.NumericEditField
        FixwindowsizeEditField_2       matlab.ui.control.NumericEditField
        StimuliPanel                   matlab.ui.container.Panel
        NofilesEditFieldLabel          matlab.ui.control.Label
        NofilesEditField               matlab.ui.control.NumericEditField
        MovrespxLabel                  matlab.ui.control.Label
        ImageResX                      matlab.ui.control.NumericEditField
        ImageResY                      matlab.ui.control.NumericEditField
        PreviewmovieDropDownLabel      matlab.ui.control.Label
        PreviewmovieDropDown           matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Movie                 % Movie parameters field
        NTBapp
        Preview
    end
    
    methods (Access = private)
        
        %========= Update Movie set info
        function results = UpdateMovies(app)

            switch app.Movie.Source.SubDirOpt
                case 'Ignore'
                    Recursive = 0;
                    app.Movie.Source.AllImFiles = regexpdir(app.Movie.Source.MovDir, sprintf('^.*%s$', app.Movie.Source.Format), Recursive);
                    app.Movie.Source.AllImFiles = app.Movie.Source.AllImFiles(2:end);
                    app.Movie.Source.Conditions = {''};
                    
                case 'Load all'
                    app.Movie.Source.AllImFiles = wildcardsearch(app.Movie.Source.MovDir, ['*.',app.Movie.Source.Format]);
                    app.Movie.Source.Conditions = {''};
                    
                case 'Use as conditions'
                    app.Movie.Source.AllImFiles = wildcardsearch(app.Movie.Source.MovDir, ['*.',app.Movie.Source.Format]);
                    SubDirs                     = dir(app.Movie.Source.MovieDir);
                    app.Movie.Source.Conditions = {SubDirs([SubDirs.isdir]).name};

            end
            if isempty(app.Movie.Source.AllImFiles)
                fprintf('No %s Movies found in %s!\n', app.Movie.Source.Format, app.Movie.Source.MovDir);
                app.NoMoviesEditField.Value = 0;
                
                return;
            end
            app.ConditionsDropDown.Items = app.Movie.Source.Conditions;
            app.ConditionsDropDown.Value = app.Movie.Source.Conditions{1};
            app.NoMoviesEditField.Value = numel(app.Movie.Source.AllImFiles);
            [P, Filenames, N] = cellfun(@fileparts, app.Movie.Source.AllImFiles, 'UniformOutput', false);
            app.PreviewMovieDropDown.Items = Filenames;
            app.PreviewMovieDropDown.Value = Filenames{1};
            
            UpdateMoviePreview(app);
            
        end
        
        %========== Update Movie preview axes
        function UpdateMoviePreview(app)
            if ~exist(app.Movie.Source.AllImFiles{app.Preview.ImIndx},'file')
                fprintf('Movie file does nto exist!\n');
                return;
            end
            [Im, cmap, alp] = imread(app.Movie.Source.AllImFiles{app.Preview.ImIndx});
            app.Movie.Source.Resolution = [size(Im, 1), size(Im, 2)];
            app.MovieResX.Value = app.Movie.Source.Resolution(1);
            app.MovieResY.Value = app.Movie.Source.Resolution(2);
            switch app.Movie.Transform.ColorMode
                case 'Greyscale'
                    Im = rgb2gray(Im);
                    Im = repmat(Im, [1,1,3]);
                case 'Hue inverted'
                    hsv = rgb2hsv(Im);
                    Hue = hsv(:,:,1);
                    Hue = Hue+0.5;
                    Hue(Hue>1) = Hue(Hue>1)-1;
                    hsv(:,:,1) = Hue;
                    Im = hsv2rgb(hsv);
            end
            if ~isfield(app.Preview, 'ImH')
                app.Preview.ImH = Movie(app.UIAxes, Im);
                app.UIAxes.Box = 'off';
                app.UIAxes.DataAspectRatio = [1,1,1];
                app.UIAxes.BackgroundColor = [0.5,0.5,0.5];
                app.UIAxes.Color           = [0.5,0.5,0.5];
            else
                set(app.Preview.ImH, 'Cdata', Im);
            end 
            if ~isempty(alp) && app.Movie.Transform.UseAlpha == 1
                set(app.Preview.ImH, 'AlphaData', alp*app.Movie.Transform.Contrast)
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NTBapp)
            app.Movie           = NTBapp.Params.Movie;
            app.NTBapp          = NTBapp;
            app.Preview.ImIndx  = 1;
            
            %========= Source tab
            app.Moviedirectory.Value            = app.Movie.Source.MovDir;
            app.MovieformatDropDown.Value       = app.Movie.Source.Format;
            app.SubdirectoriesDropDown.Value    = app.Movie.Source.SubDirOpt;
            app.SBS3D.Value                     = app.Movie.Source.SBS;
            if isdir(app.Movie.Source.MovDir)
                UpdateMovies(app);
            end
            
            %========= Transforms tab
            app.Fullscreen.Value                = app.Movie.Transform.Fullscreen;
            app.StimulusWidth.Value             = app.Movie.Transform.Diameter;
            app.ColorDropDown.Value             = app.Movie.Transform.ColorMode;
            app.MovieRotation.Value             = app.Movie.Transform.Rotation;
            app.MoviecontrastSlider.Value       = app.Movie.Transform.Contrast;
            
            %========= Presentation tab


            %========= Options menu
            if ~isprop(app, 'Run') || app.Run.WinOpen == 0
                app.Button_LoadTex.Enable = 0;
            elseif app.Run.WinOpen == 1
                app.Button_LoadTex.Enable = 1;
            end
            
        end

        % Value changed function: Imagedirectory
        function ImagedirectoryValueChanged(app, event)
            value = app.Moviedirectory.Value;
            if ~isdir(value)
                MovieDirectoryButtonPushed(app);
            else
                UpdateMovies(app);
            end
        end

        % Button pushed function: ImageDirectoryButton
        function ImageDirectoryButtonPushed(app, event)
            path = uigetdir(app.Movie.Source.MovDir, 'Select Movie directory');
            if path == 0
                return
            end
            app.Movie.Source.MovDir      = path;
            app.Moviedirectory.Value    = path;
            UpdateMovies(app);
        end

        % Value changed function: MovieformatDropDown
        function MovieformatDropDownValueChanged(app, event)
            value = app.MovieformatDropDown.Value;
            app.Movie.Source.Format = value;
            UpdateMovies(app);
        end

        % Value changed function: SubdirectoriesDropDown
        function SubdirectoriesDropDownValueChanged(app, event)
            value = app.SubdirectoriesDropDown.Value;
            app.Movie.Source.SubDirOpt = value;
            UpdateMovies(app);
        end

        % Value changed function: PreviewmovieDropDown
        function PreviewmovieDropDownValueChanged(app, event)
            value = app.PreviewMovieDropDown.Value;
            app.Preview.ImIndx = find(contains(app.PreviewMovieDropDown.Items, value));
            UpdateMoviePreview(app);
            
        end

        % Value changed function: SBS3D
        function SBS3DValueChanged(app, event)
            value = app.SBS3D.Value;
            app.Movie.Source.SBS = value;
        end

        % Value changed function: ConditionsDropDown
        function ConditionsDropDownValueChanged(app, event)
            value = app.ConditionsDropDown.Value;
            
        end

        % Value changed function: Fullscreen
        function FullscreenValueChanged(app, event)
            value = app.Fullscreen.Value;
            app.Movie.Transform.Fullscreen = value;
            if value == 1
                app.StimulusWidth.Enable = 0;
            elseif value == 0
                app.StimulusWidth.Enable = 1;
            end
            UpdateMoviePreview(app);
        end

        % Value changed function: StimulusWidth
        function StimulusWidthValueChanged(app, event)
            value = app.StimulusWidth.Value;
            UpdateMoviePreview(app);
        end

        % Callback function
        function UseAlphaChannelValueChanged(app, event)
            value = app.UseAlphaChannel.Value;
            app.Movie.Transform.UseAlpha = value;
            UpdateMoviePreview(app);
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            app.Movie.Transform.ColorMode = value;
            UpdateMoviePreview(app);
        end

        % Callback function
        function ApplymaskDropDownValueChanged(app, event)
            value = app.ApplymaskDropDown.Value;
            app.Movie.Transform.MaskType = value;
            UpdateMoviePreview(app);
        end

        % Value changed function: ImageRotation
        function ImageRotationValueChanged(app, event)
            value = app.MovieRotation.Value;
            app.Movie.Transform.Rotation = value;
            UpdateMoviePreview(app);
        end

        % Value changed function: ImagecontrastSlider
        function ImagecontrastSliderValueChanged(app, event)
            value = app.MoviecontrastSlider.Value;
            app.Movie.Transform.Contrast = value;
            UpdateMoviePreview(app);
        end

        % Value changed function: StimulusUnits
        function StimulusUnitsValueChanged(app, event)
            value = app.StimulusUnits.Value;
            app.Movie.Transform.SizeUnits = value;
            
            
            switch app.Movie.Transform.SizeUnits
                case '(°)'
                    app.StimulusWidth.Value = app.Movie.Transform.StimWidthDVA;
                case '(px)'
                    app.StimulusWidth.Value = app.Movie.Transform.StimWidthPx;
                case '(cm)'
                    app.StimulusWidth.Value = app.Movie.Transform.StimWidthCm;
                case '(%)'
                    app.StimulusWidth.Value = app.Movie.Transform.StimWidthPerc;
            end
            app.StimulusWidth.Value = NewValue;
        end

        % Button pushed function: Button_Save
        function Button_SavePushed(app, event)
            ParamsFile  = app.NTBapp.Params.File;
            selection   = uiconfirm(app.NTBMovieSettingsUIFigure, sprintf('Save Movie settings to parameter file %s?', ParamsFile), 'Save', 'Options',{'Save','Cancel'}, 'Icon', 'Save.png');
            if strcmp(selection, 'Save')
                Movie     = app.Movie;
                save(ParamsFile, 'Movie','-append');
                uiconfirm(app.NTBMovieSettingsUIFigure, sprintf('Current Movie parameters were written to %s.', ParamsFile), 'Movie parameters saved', 'Icon', 'success');
            end
        end

        % Button pushed function: Button_Load
        function Button_LoadPushed(app, event)
            
        end

        % Button pushed function: Button_Docs
        function Button_DocsPushed(app, event)
            
        end

        % Button pushed function: Button_Close
        function Button_ClosePushed(app, event)
            
        end

        % Button pushed function: Button_LoadTex
        function Button_LoadTexPushed(app, event)
            
        end

        % Close request function: NTBMovieSettingsUIFigure
        function NTBMovieSettingsUIFigureCloseRequest(app, event)
            app.NTBapp.Button_Movie.BackgroundColor = app.NTBapp.OffOnColors{1};
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NTBMovieSettingsUIFigure = uifigure('Visible', 'off');
            app.NTBMovieSettingsUIFigure.Name = 'NTB Movie Settings';
            app.NTBMovieSettingsUIFigure.Icon = 'Movie.png';
            app.NTBMovieSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @NTBMovieSettingsUIFigureCloseRequest, true);
            app.NTBMovieSettingsUIFigure.Tag = 'NTB_MovieSettings';
            app.OptionsPanel = uipanel(app.NTBMovieSettingsUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.Button_Docs = uibutton(app.OptionsPanel, 'push');
            app.Button_Docs.ButtonPushedFcn = createCallbackFcn(app, @Button_DocsPushed, true);
            app.Button_Docs.Icon = 'ReadTheDocs.png';
            app.Button_Docs.Tooltip = {'Open the NTB documentation webpage for Image Settings.'};
            app.Button_Docs.Text = '';
            app.Button_Save = uibutton(app.OptionsPanel, 'push');
            app.Button_Save.ButtonPushedFcn = createCallbackFcn(app, @Button_SavePushed, true);
            app.Button_Save.Icon = 'Save.png';
            app.Button_Save.Tooltip = {'Save current Image settings to Params file.'};
            app.Button_Save.Text = '';
            app.Button_Load = uibutton(app.OptionsPanel, 'push');
            app.Button_Load.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPushed, true);
            app.Button_Load.Icon = 'Transfer.png';
            app.Button_Load.Tooltip = {'Load Image settings from a different parameter file.'};
            app.Button_Load.Text = '';
            app.Button_Close = uibutton(app.OptionsPanel, 'push');
            app.Button_Close.ButtonPushedFcn = createCallbackFcn(app, @Button_ClosePushed, true);
            app.Button_Close.Icon = 'Exit.png';
            app.Button_Close.Tooltip = {'Exit Image Settings GUI without updating Image parameters.'};
            app.Button_Close.Text = '';
            app.Button_LoadTex = uibutton(app.OptionsPanel, 'push');
            app.Button_LoadTex.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadTexPushed, true);
            app.Button_LoadTex.Icon = 'Movie.png';
            app.Button_LoadTex.Tooltip = {'Load selected images to PTB textures.'};
            app.Button_LoadTex.Text = '';
            app.TabGroup = uitabgroup(app.NTBMovieSettingsUIFigure);
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            app.ImageDirectoryButton = uibutton(app.SelectionTab, 'push');
            app.ImageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ImageDirectoryButtonPushed, true);
            app.ImageDirectoryButton.Tooltip = {'Click to open image directory selection window'};
            app.ImageDirectoryButton.Text = '...';
            app.MovieformatDropDownLabel = uilabel(app.SelectionTab);
            app.MovieformatDropDownLabel.Text = 'Movie format';
            app.MovieformatDropDown = uidropdown(app.SelectionTab);
            app.MovieformatDropDown.Items = {'MP4', 'AVI', 'WMV', 'Any'};
            app.MovieformatDropDown.ValueChangedFcn = createCallbackFcn(app, @MovieformatDropDownValueChanged, true);
            app.MovieformatDropDown.Tooltip = {'File format of images to load'};
            app.MovieformatDropDown.Value = 'MP4';
            app.SubdirectoriesDropDownLabel = uilabel(app.SelectionTab);
            app.SubdirectoriesDropDownLabel.Text = 'Subdirectories';
            app.SubdirectoriesDropDown = uidropdown(app.SelectionTab);
            app.SubdirectoriesDropDown.Items = {'Ignore', 'Load all', 'Use as conditions'};
            app.SubdirectoriesDropDown.ValueChangedFcn = createCallbackFcn(app, @SubdirectoriesDropDownValueChanged, true);
            app.SubdirectoriesDropDown.Tooltip = {'Choose how to treat subdirectories found inside the image driectory'};
            app.SubdirectoriesDropDown.Value = 'Ignore';
            app.ConditionsDropDownLabel = uilabel(app.SelectionTab);
            app.ConditionsDropDownLabel.Text = 'Conditions';
            app.ConditionsDropDown = uidropdown(app.SelectionTab);
            app.ConditionsDropDown.Items = {};
            app.ConditionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionsDropDownValueChanged, true);
            app.ConditionsDropDown.Tooltip = {'If ''Use as conditions'' was selected for ''Subdirectories'' above, this drowndown menu lists the names of all subdirectories found in the image directory'};
            app.ConditionsDropDown.Value = {};
            app.SBS3D = uicheckbox(app.SelectionTab);
            app.SBS3D.ValueChangedFcn = createCallbackFcn(app, @SBS3DValueChanged, true);
            app.SBS3D.Tooltip = {'Are the images in a side-by-side stereoscopic 3D format?'};
            app.SBS3D.Text = '';
            app.SBS3DformatLabel = uilabel(app.SelectionTab);
            app.SBS3DformatLabel.Text = 'SBS 3D format?';
            app.MoviedirectoryLabel = uilabel(app.SelectionTab);
            app.MoviedirectoryLabel.Text = 'Movie directory';
            app.Imagedirectory = uieditfield(app.SelectionTab, 'text');
            app.Imagedirectory.ValueChangedFcn = createCallbackFcn(app, @ImagedirectoryValueChanged, true);
            app.Imagedirectory.Tooltip = {'Full path of the directory to load images from'};
            app.DomeformatLabel = uilabel(app.SelectionTab);
            app.DomeformatLabel.Text = 'Dome format?';
            app.SBS3D_2 = uicheckbox(app.SelectionTab);
            app.SBS3D_2.Tooltip = {'Are the images in a side-by-side stereoscopic 3D format?'};
            app.SBS3D_2.Text = '';
            app.TransformsTab = uitab(app.TabGroup);
            app.TransformsTab.Title = 'Transforms';
            app.Fullscreen = uicheckbox(app.TransformsTab);
            app.Fullscreen.ValueChangedFcn = createCallbackFcn(app, @FullscreenValueChanged, true);
            app.Fullscreen.Tooltip = {'Present the images at fullscreen size? (Images will be resized to fit the screen if necessary, but without changing aspect ratio)'};
            app.Fullscreen.Text = '';
            app.Fullscreen.Value = true;
            app.PresentfullscreenLabel = uilabel(app.TransformsTab);
            app.PresentfullscreenLabel.Text = 'Present fullscreen?';
            app.StimuluswidthLabel = uilabel(app.TransformsTab);
            app.StimuluswidthLabel.Enable = 'off';
            app.StimuluswidthLabel.Text = 'Stimulus width';
            app.StimulusWidth = uieditfield(app.TransformsTab, 'numeric');
            app.StimulusWidth.Limits = [0 Inf];
            app.StimulusWidth.ValueChangedFcn = createCallbackFcn(app, @StimulusWidthValueChanged, true);
            app.StimulusWidth.Enable = 'off';
            app.StimulusWidth.Tooltip = {'Dimension (degrees of visual angle) to present the stimulus at if fullscreen was not selected'};
            app.StimulusWidth.Value = 10;
            app.ImageRotation = uieditfield(app.TransformsTab, 'numeric');
            app.ImageRotation.Limits = [0 Inf];
            app.ImageRotation.ValueChangedFcn = createCallbackFcn(app, @ImageRotationValueChanged, true);
            app.ImageRotationFieldLabel = uilabel(app.TransformsTab);
            app.ImageRotationFieldLabel.Text = 'Image rotation (°)';
            app.ImagecontrastSliderLabel = uilabel(app.TransformsTab);
            app.ImagecontrastSliderLabel.Text = 'Image contrast';
            app.ImagecontrastSlider = uislider(app.TransformsTab);
            app.ImagecontrastSlider.Limits = [0 1];
            app.ImagecontrastSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastSliderValueChanged, true);
            app.ImagecontrastSlider.Value = 1;
            app.ColorDropDownLabel = uilabel(app.TransformsTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.TransformsTab);
            app.ColorDropDown.Items = {'Original', 'Greyscale', 'Hue inverted'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'Original';
            app.StimulusUnits = uidropdown(app.TransformsTab);
            app.StimulusUnits.Items = {'(°)', '(cm)', '(px)', '(%)'};
            app.StimulusUnits.ValueChangedFcn = createCallbackFcn(app, @StimulusUnitsValueChanged, true);
            app.StimulusUnits.Value = '(°)';
            app.PresentationTab = uitab(app.TabGroup);
            app.PresentationTab.Title = 'Presentation';
            app.StimulusorderLabel = uilabel(app.PresentationTab);
            app.StimulusorderLabel.Text = 'Stimulus order';
            app.StimulusorderDropDown = uidropdown(app.PresentationTab);
            app.StimulusorderDropDown.Items = {'Randomized', 'fMRI block design'};
            app.StimulusorderDropDown.Value = 'fMRI block design';
            app.TrialsperrunEditFieldLabel = uilabel(app.PresentationTab);
            app.TrialsperrunEditFieldLabel.Text = {'Trials per run'; ''};
            app.TrialsperrunEditField = uieditfield(app.PresentationTab, 'numeric');
            app.TrialsperrunEditField.Limits = [1 Inf];
            app.TrialsperrunEditField.Value = 100;
            app.StimpertrialEditFieldLabel = uilabel(app.PresentationTab);
            app.StimpertrialEditFieldLabel.Text = {'Stim. per trial'; ''};
            app.StimpertrialEditField = uieditfield(app.PresentationTab, 'numeric');
            app.StimpertrialEditField.Limits = [1 Inf];
            app.StimpertrialEditField.Value = 5;
            app.StimdurationsEditFieldLabel = uilabel(app.PresentationTab);
            app.StimdurationsEditFieldLabel.Text = {'Stim.duration (s)'; ''};
            app.StimdurationsEditField = uieditfield(app.PresentationTab, 'numeric');
            app.StimdurationsEditField.Limits = [0 Inf];
            app.StimdurationsEditField.Value = 2;
            app.InterstimintervalsEditFieldLabel = uilabel(app.PresentationTab);
            app.InterstimintervalsEditFieldLabel.Text = {'Inter-stim interval (s)'; ''};
            app.InterstimintervalsEditField = uieditfield(app.PresentationTab, 'numeric');
            app.InterstimintervalsEditField.Limits = [0 Inf];
            app.IntertrialintervalsEditFieldLabel = uilabel(app.PresentationTab);
            app.IntertrialintervalsEditFieldLabel.Text = {'Inter-trial interval (s)'; ''};
            app.IntertrialintervalsEditField = uieditfield(app.PresentationTab, 'numeric');
            app.IntertrialintervalsEditField.Limits = [0 Inf];
            app.TemporaljittermeanmsEditFieldLabel = uilabel(app.PresentationTab);
            app.TemporaljittermeanmsEditFieldLabel.Text = 'Temporal jitter (mean ms)';
            app.TemporaljittermeanmsEditField = uieditfield(app.PresentationTab, 'numeric');
            app.TemporaljittermeanmsEditField.Limits = [0 Inf];
            app.FixationTab = uitab(app.TabGroup);
            app.FixationTab.Title = 'Fixation';
            app.FixationrequiredLabel = uilabel(app.FixationTab);
            app.FixationrequiredLabel.Text = 'Fixation required?';
            app.CheckBox_5 = uicheckbox(app.FixationTab);
            app.CheckBox_5.Text = '';
            app.FixwindowsizeEditFieldLabel = uilabel(app.FixationTab);
            app.FixwindowsizeEditFieldLabel.Text = 'Fix window size';
            app.FixwindowsizeEditField = uieditfield(app.FixationTab, 'numeric');
            app.FixwindowsizeEditField_2 = uieditfield(app.FixationTab, 'numeric');
            app.StimuliPanel = uipanel(app.NTBMovieSettingsUIFigure);
            app.StimuliPanel.Title = 'Stimuli';
            app.NofilesEditFieldLabel = uilabel(app.StimuliPanel);
            app.NofilesEditFieldLabel.Text = 'No. files';
            app.NofilesEditField = uieditfield(app.StimuliPanel, 'numeric');
            app.NofilesEditField.Editable = 'off';
            app.MovrespxLabel = uilabel(app.StimuliPanel);
            app.MovrespxLabel.Text = 'Mov. res (px)';
            app.ImageResX = uieditfield(app.StimuliPanel, 'numeric');
            app.ImageResX.Editable = 'off';
            app.ImageResY = uieditfield(app.StimuliPanel, 'numeric');
            app.ImageResY.Editable = 'off';
            app.PreviewmovieDropDownLabel = uilabel(app.StimuliPanel);
            app.PreviewmovieDropDownLabel.Text = 'Preview movie';
            app.PreviewmovieDropDown = uidropdown(app.StimuliPanel);
            app.PreviewmovieDropDown.Items = {};
            app.PreviewmovieDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewmovieDropDownValueChanged, true);
            app.PreviewmovieDropDown.Value = {};
            app.UIAxes = uiaxes(app.StimuliPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.NTBMovieSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NTB_MovieSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NTBMovieSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NTBMovieSettingsUIFigure)
        end
    end
end