classdef NTB_RewardTraining < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RewardCounter                   matlab.ui.control.Label
        RewardcountLabel                matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        TimeoutdurationsEditField       matlab.ui.control.NumericEditField
        TimeoutdurationsEditFieldLabel  matlab.ui.control.Label
        ManualButton                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        TimeoutButton                   matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        PulsedurationmsEditField        matlab.ui.control.NumericEditField
        PulsedurationmsEditFieldLabel   matlab.ui.control.Label
        RewardfrequencyDropDown         matlab.ui.control.DropDown
        RewardfrequencyDropDownLabel    matlab.ui.control.Label
        InterrewardintervalsEditField   matlab.ui.control.NumericEditField
        InterrewardintervalsEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        RewardParams % Structure containing reward UI parameters
    end
    
    methods (Access = private)
        
        % Send TTL pulse for reward
        function app = GiveReward(app)

            app.Lamp.Color                  = [0,1,0];
            drawnow;
            app.RewardParams.channelVoltages(2,:) = 5;
            Datapixx('SetDacVoltages', app.RewardParams.channelVoltages);
            Datapixx('RegWrRd');    % Synchronize DATAPixx registers to local register cache
            app.RewardParams.StartTime = GetSecs;
        
            %==== Wait time
            while GetSecs < app.RewardParams.StartTime + app.RewardParams.SolenoidDurationMs/10^3
        
            end
        
            %==== Set to low
            app.RewardParams.channelVoltages(2,:) = 0;
            Datapixx('SetDacVoltages', app.RewardParams.channelVoltages);
            Datapixx('RegWrRd');    % Synchronize DATAPixx registers to local register cache
            
      
            if app.RewardParams.Running == 1
                app.Lamp.Color = [1,0,0];
            elseif app.RewardParams.Running == 0
                app.Lamp.Color = [0.50,0.50,0.50];
            end
            drawnow;
            app.RewardParams.RewardCount    = app.RewardParams.RewardCount+1;
            app.RewardCounter.Text          = num2str(app.RewardParams.RewardCount);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RewardParams                    = [];
            app.RewardParams.Running            = 0;
            app.RewardParams.RewardInterval     = 4;
            app.RewardParams.SolenoidDurationMs = 80;
            app.RewardParams.TimeOutDur         = 10;
            app.RewardParams.RewardCount        = 0;
            app.RewardParams.TimeOutActive       = 0;

            app.InterrewardintervalsEditField.Value = app.RewardParams.RewardInterval;
            app.PulsedurationmsEditField.Value      = app.RewardParams.SolenoidDurationMs;
            app.TimeoutdurationsEditField.Value     = app.RewardParams.TimeOutDur;
            app.RewardCounter.Text                  = num2str(app.RewardParams.RewardCount);
            app.RewardParams.LastReward             = GetSecs;
            
            % Initialize DataPixx
            Datapixx('Open'); 
            Datapixx('StopAllSchedules');
            Datapixx('RegWrRd');
            app.RewardParams.nChannels = Datapixx('GetDacNumChannels');
            app.RewardParams.dacRanges = Datapixx('GetDacRanges');  
            for channel = 0:app.RewardParams.nChannels-1
                app.RewardParams.channelVoltages(1,channel+1) = channel;
                app.RewardParams.channelVoltages(2,channel+1) = app.RewardParams.dacRanges(1, channel+1);
            end
            

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            if app.RewardParams.Running == 0
                app.RewardParams.Running = 1;
                app.Lamp.Color = [1,0,0];
                app.StartButton.Text = "Stop";
                app.RewardParams.StartTime = GetSecs;
            elseif app.RewardParams.Running == 1
                app.RewardParams.Running = 0;
                app.Lamp.Color = [0.75,0.75,0.75];
                app.StartButton.Text = "Start";
            end

        end

        % Value changed function: InterrewardintervalsEditField
        function InterrewardintervalsEditFieldValueChanged(app, event)
            value = app.InterrewardintervalsEditField.Value;
            app.RewardParams.RewardInterval = value;
        end

        % Value changed function: PulsedurationmsEditField
        function PulsedurationmsEditFieldValueChanged(app, event)
            value = app.PulsedurationmsEditField.Value;
            app.RewardParams.SolenoidDurationMs = value;
        end

        % Value changed function: TimeoutdurationsEditField
        function TimeoutdurationsEditFieldValueChanged(app, event)
            value = app.TimeoutdurationsEditField.Value;
            app.RewardParams.TimeOutDur = value;
        end

        % Button pushed function: TimeoutButton
        function TimeoutButtonPushed(app, event)
            if app.RewardParams.Running == 0
                return;
            end
            app.RewardParams.TimeOutActive  = 1;
            app.RewardParams.TimeOutStart   = GetSecs;
            app.TimeoutButton.BackgroundColor = [1,0,0];
            drawnow;

            while GetSecs < app.RewardParams.TimeOutStart + app.RewardParams.TimeOutDur

            end
            app.RewardParams.TimeOutActive  = 0;
            app.TimeoutButton.BackgroundColor = [0.96,0.96,0.96];
            drawnow;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            app = GiveReward(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {214, 214};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {335, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InterrewardintervalsEditFieldLabel = uilabel(app.LeftPanel);
            app.InterrewardintervalsEditFieldLabel.Text = 'Inter-reward interval (s)';
            app.InterrewardintervalsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InterrewardintervalsEditField.Limits = [0.01 100];
            app.InterrewardintervalsEditField.ValueDisplayFormat = '%.2f';
            app.InterrewardintervalsEditField.ValueChangedFcn = createCallbackFcn(app, @InterrewardintervalsEditFieldValueChanged, true);
            app.InterrewardintervalsEditField.Value = 3;
            app.RewardfrequencyDropDownLabel = uilabel(app.LeftPanel);
            app.RewardfrequencyDropDownLabel.Text = 'Reward frequency';
            app.RewardfrequencyDropDown = uidropdown(app.LeftPanel);
            app.RewardfrequencyDropDown.Items = {'Fixed, ', 'Cumulative'};
            app.RewardfrequencyDropDown.Value = 'Fixed, ';
            app.PulsedurationmsEditFieldLabel = uilabel(app.LeftPanel);
            app.PulsedurationmsEditFieldLabel.Text = 'Pulse duration (ms)';
            app.PulsedurationmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PulsedurationmsEditField.Limits = [0 1000];
            app.PulsedurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @PulsedurationmsEditFieldValueChanged, true);
            app.PulsedurationmsEditField.Value = 80;
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Start / stop automated reward delivery schedule (keyboard shortcut = space bar)'};
            app.StartButton.Text = 'Start';
            app.TimeoutButton = uibutton(app.LeftPanel, 'push');
            app.TimeoutButton.ButtonPushedFcn = createCallbackFcn(app, @TimeoutButtonPushed, true);
            app.TimeoutButton.Tooltip = {'Pause automated reward delivery for timeout (keyboartd shortcut = ''T'')'};
            app.TimeoutButton.Text = 'Timeout';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset current reward interval and pulse duration '};
            app.ResetButton.Text = 'Reset';
            app.ManualButton = uibutton(app.LeftPanel, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Tooltip = {'Give manual reward (keyboard shortcut =  Enter)'};
            app.ManualButton.Text = 'Manual';
            app.TimeoutdurationsEditFieldLabel = uilabel(app.LeftPanel);
            app.TimeoutdurationsEditFieldLabel.Text = 'Timeout duration (s)';
            app.TimeoutdurationsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TimeoutdurationsEditField.Limits = [0 100];
            app.TimeoutdurationsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeoutdurationsEditFieldValueChanged, true);
            app.TimeoutdurationsEditField.Value = 8;
            app.Lamp = uilamp(app.LeftPanel);
            app.RewardcountLabel = uilabel(app.LeftPanel);
            app.RewardcountLabel.Text = 'Reward count:';
            app.RewardCounter = uilabel(app.LeftPanel);
            app.RewardCounter.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Reward history')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'TTL output (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 5 10 15 20];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.UIAxes.YTick = [0 5];
            app.UIAxes.YTickLabel = {'0'; '5'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NTB_RewardTraining

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end