classdef NTB_BlockDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NTBBlockDesignUIFigure          matlab.ui.Figure
        DesignparametersPanel           matlab.ui.container.Panel
        InterstimintervalmsEditField_2Label  matlab.ui.control.Label
        SyncToTTL                       matlab.ui.control.CheckBox
        SyncstimtoTTLLabel              matlab.ui.control.Label
        BlocksperrunEditFieldLabel      matlab.ui.control.Label
        BlocksPerRun                    matlab.ui.control.NumericEditField
        StimperblockEditFieldLabel      matlab.ui.control.Label
        StimPerBlock                    matlab.ui.control.NumericEditField
        StimdurationmsEditFieldLabel    matlab.ui.control.Label
        StimDurationMS                  matlab.ui.control.NumericEditField
        AddfixationblocksDropDownLabel  matlab.ui.control.Label
        AddFixBlocks                    matlab.ui.control.DropDown
        AddfixationblocksDropDownLabel_2  matlab.ui.control.Label
        NoStimCond                      matlab.ui.control.Spinner
        ISIms                           matlab.ui.control.NumericEditField
        RandomizationDropDownLabel      matlab.ui.control.Label
        Randomization                   matlab.ui.control.DropDown
        BlocksperrunEditFieldLabel_2    matlab.ui.control.Label
        FixBlockDur                     matlab.ui.control.NumericEditField
        RecommendtimingDropDownLabel    matlab.ui.control.Label
        RecommendTiming                 matlab.ui.control.DropDown
        DesignPreviewPanel              matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        OptionsPanel                    matlab.ui.container.Panel
        Button_Docs                     matlab.ui.control.Button
        Button_Save                     matlab.ui.control.Button
        Button_Load                     matlab.ui.control.Button
        Button_Close                    matlab.ui.control.Button
        Button_LoadTex                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Block % Description
        Preview
    end
    
    methods (Access = private)
        
        function results = UpdatePreview(app)
            
            StimBlockDuration   = app.Block.StimPerBlock*(app.Block.StimDurationMs + app.Block.ISIms)/10^3;
            switch app.Block.AddFixBlocks
                case 'First and last'
                    TotalFixDuration = 2*app.Block.FixBlockDur;
                case 'Alternating'
                    TotalFixDuration = (app.Block.BlocksPerRun+1)*app.Block.FixBlockDur;
                otherwise
                    TotalFixDuration = 0;
            end
            RunDuration         = StimBlockDuration*app.Block.BlocksPerRun + TotalFixDuration;
            
            switch app.Block.RandomizationMethod
                case 'Latin square'
                    Mat = LatinSquare(app);
                case 'M-sequence'
                    Mat = Msequence(app);
                case 'No repeats'
                    
            end
            
            if ~isfield(app.Preview, 'BlockH')
                app.Preview.BlockH = imagesc(app.UIAxes, Mat);
            else
                set(app.Preview.BlockH, 'CData', Mat)
            end
            set(app.UIAxes, 'xlim', [0, RunDuration]);
            colormap(app.UIAxes, jet);
        end
        
        %========= Generate a Latin Square block design
        function R = LatinSquare(app)
            BlocksPerCond = [];
            while 1                                     % Until acceptable sequence is generated...
                [M,R]   = latsq(app.Block.NoStimCond);  % Generate randomized Latin square sequence
                R       = reshape(R,[1,numel(R)]);  	% Reshape matrix into vector
                ImmediateReps = find(diff(R)==0);    	% Find any consecutive repetitions
                if app.Block.NoStimCond <= 2
                    break;
                end
                if isempty(ImmediateReps)
                    if BlocksPerCond > app.Block.NoStimCond           
                        if R(1)~=R(end)                   
                            R = repmat(R,[1,2]);        % Repeat sequence 
                            break;
                        end
                    else
                        break;
                    end
                end
            end
            
            % Add fix blocks (condition # = 0)
            switch app.Block.AddFixBlocks
                case 'First and last'   % Only first and last blocks are fixation
                    R = [zeros(size(R,1),1), R, zeros(size(R,1),1)];
                    
                case 'Alternating'      % Every other block is fixation
                    for r = 1:size(R,1)
                        NewR(r,1) = 0;
                        for c = 1:size(R,2)
                            NewR(r,c) = [R(r,c),0];
                        end
                    end
                    R = [zeros(size(R,1), 1), NewR];
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NTBapp, Block)
            
            app.Block = Block;    
            
            %========== Set GUI values
            app.NoStimCond.Value        = app.Block.NoStimCond;
            app.AddFixBlocks.Value      = app.Block.AddFixBlocks;
            app.FixBlockDur.Value       = app.Block.FixBlockDur;
            app.BlocksPerRun.Value      = app.Block.BlocksPerRun;
            app.StimPerBlock.Value      = app.Block.StimPerBlock;
            app.StimDurationMS.Value    = app.Block.StimDurationMs;
            app.ISIms.Value             = app.Block.ISIms;
            app.SyncToTTL.Value         = app.Block.SyncToTTL;
            app.Randomization.Value     = app.Block.RandomizationMethod;
            app.RecommendTiming.Value   = app.Block.RecommendedTiming;
            
            %========== 
            rng(now,'twister');                 % Seed the ramdom number generator using today's date
            app.Block.Seed = rng;               % Save seed state
            UpdatePreview(app);
            
            
        end

        % Close request function: NTBBlockDesignUIFigure
        function NTBBlockDesignUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: NoStimCond
        function NoStimCondValueChanged(app, event)
            value = app.NoStimCond.Value;
            
        end

        % Value changed function: AddFixBlocks
        function AddFixBlocksValueChanged(app, event)
            value = app.AddFixBlocks.Value;
            
        end

        % Value changed function: FixBlockDur
        function FixBlockDurValueChanged(app, event)
            value = app.FixBlockDur.Value;
            
        end

        % Value changed function: BlocksPerRun
        function BlocksPerRunValueChanged(app, event)
            value = app.BlocksPerRun.Value;
            
        end

        % Value changed function: StimPerBlock
        function StimPerBlockValueChanged(app, event)
            value = app.StimPerBlock.Value;
            
        end

        % Value changed function: StimDurationMS
        function StimDurationMSValueChanged(app, event)
            value = app.StimDurationMS.Value;
            
        end

        % Value changed function: ISIms
        function ISImsValueChanged(app, event)
            value = app.ISIms.Value;
            
        end

        % Value changed function: SyncToTTL
        function SyncToTTLValueChanged(app, event)
            value = app.SyncToTTL.Value;
            
        end

        % Value changed function: Randomization
        function RandomizationValueChanged(app, event)
            value = app.Randomization.Value;
            
        end

        % Value changed function: RecommendTiming
        function RecommendTimingValueChanged(app, event)
            value = app.RecommendTiming.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NTBBlockDesignUIFigure = uifigure('Visible', 'off');
            app.NTBBlockDesignUIFigure.Name = 'NTB Block Design';
            app.NTBBlockDesignUIFigure.CloseRequestFcn = createCallbackFcn(app, @NTBBlockDesignUIFigureCloseRequest, true);
            app.NTBBlockDesignUIFigure.Tag = 'NTB_BLockDesign';
            app.DesignparametersPanel = uipanel(app.NTBBlockDesignUIFigure);
            app.DesignparametersPanel.Title = 'Design parameters';
            app.InterstimintervalmsEditField_2Label = uilabel(app.DesignparametersPanel);
            app.InterstimintervalmsEditField_2Label.Text = {'Inter-stim interval (ms)'; ''};
            app.SyncToTTL = uicheckbox(app.DesignparametersPanel);
            app.SyncToTTL.ValueChangedFcn = createCallbackFcn(app, @SyncToTTLValueChanged, true);
            app.SyncToTTL.Text = '';
            app.SyncstimtoTTLLabel = uilabel(app.DesignparametersPanel);
            app.SyncstimtoTTLLabel.Text = 'Sync stim to TTL?';
            app.BlocksperrunEditFieldLabel = uilabel(app.DesignparametersPanel);
            app.BlocksperrunEditFieldLabel.Text = {'Blocks per run'; ''};
            app.BlocksPerRun = uieditfield(app.DesignparametersPanel, 'numeric');
            app.BlocksPerRun.Limits = [1 Inf];
            app.BlocksPerRun.ValueChangedFcn = createCallbackFcn(app, @BlocksPerRunValueChanged, true);
            app.BlocksPerRun.Value = 10;
            app.StimperblockEditFieldLabel = uilabel(app.DesignparametersPanel);
            app.StimperblockEditFieldLabel.Text = 'Stim. per block';
            app.StimPerBlock = uieditfield(app.DesignparametersPanel, 'numeric');
            app.StimPerBlock.Limits = [1 Inf];
            app.StimPerBlock.ValueChangedFcn = createCallbackFcn(app, @StimPerBlockValueChanged, true);
            app.StimPerBlock.Value = 20;
            app.StimdurationmsEditFieldLabel = uilabel(app.DesignparametersPanel);
            app.StimdurationmsEditFieldLabel.Text = {'Stim.duration (ms)'; ''};
            app.StimDurationMS = uieditfield(app.DesignparametersPanel, 'numeric');
            app.StimDurationMS.Limits = [1 Inf];
            app.StimDurationMS.ValueChangedFcn = createCallbackFcn(app, @StimDurationMSValueChanged, true);
            app.StimDurationMS.Value = 1000;
            app.AddfixationblocksDropDownLabel = uilabel(app.DesignparametersPanel);
            app.AddfixationblocksDropDownLabel.Text = 'Add fixation blocks';
            app.AddFixBlocks = uidropdown(app.DesignparametersPanel);
            app.AddFixBlocks.Items = {'First and last', 'Alternating', 'None'};
            app.AddFixBlocks.ValueChangedFcn = createCallbackFcn(app, @AddFixBlocksValueChanged, true);
            app.AddFixBlocks.Value = 'First and last';
            app.AddfixationblocksDropDownLabel_2 = uilabel(app.DesignparametersPanel);
            app.AddfixationblocksDropDownLabel_2.Text = 'No. stimulus conditions';
            app.NoStimCond = uispinner(app.DesignparametersPanel);
            app.NoStimCond.Limits = [1 Inf];
            app.NoStimCond.RoundFractionalValues = 'on';
            app.NoStimCond.ValueChangedFcn = createCallbackFcn(app, @NoStimCondValueChanged, true);
            app.NoStimCond.Value = 8;
            app.ISIms = uieditfield(app.DesignparametersPanel, 'numeric');
            app.ISIms.Limits = [1 Inf];
            app.ISIms.ValueChangedFcn = createCallbackFcn(app, @ISImsValueChanged, true);
            app.ISIms.Value = 1000;
            app.RandomizationDropDownLabel = uilabel(app.DesignparametersPanel);
            app.RandomizationDropDownLabel.Text = 'Randomization';
            app.Randomization = uidropdown(app.DesignparametersPanel);
            app.Randomization.Items = {'Latin square', 'M-sequence', 'Rand. without repeats', 'Totally random'};
            app.Randomization.ValueChangedFcn = createCallbackFcn(app, @RandomizationValueChanged, true);
            app.Randomization.Value = 'Latin square';
            app.BlocksperrunEditFieldLabel_2 = uilabel(app.DesignparametersPanel);
            app.BlocksperrunEditFieldLabel_2.Text = 'Fix block duration (s)';
            app.FixBlockDur = uieditfield(app.DesignparametersPanel, 'numeric');
            app.FixBlockDur.Limits = [1 Inf];
            app.FixBlockDur.ValueChangedFcn = createCallbackFcn(app, @FixBlockDurValueChanged, true);
            app.FixBlockDur.Value = 20;
            app.RecommendtimingDropDownLabel = uilabel(app.DesignparametersPanel);
            app.RecommendtimingDropDownLabel.Text = 'Recommend timing';
            app.RecommendTiming = uidropdown(app.DesignparametersPanel);
            app.RecommendTiming.Items = {'BOLD', 'MION', 'ZTE', 'None'};
            app.RecommendTiming.ValueChangedFcn = createCallbackFcn(app, @RecommendTimingValueChanged, true);
            app.RecommendTiming.Value = 'BOLD';
            app.DesignPreviewPanel = uipanel(app.NTBBlockDesignUIFigure);
            app.DesignPreviewPanel.Title = 'Design Preview';
            app.UIAxes = uiaxes(app.DesignPreviewPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Run')
            app.OptionsPanel = uipanel(app.NTBBlockDesignUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.Button_Docs = uibutton(app.OptionsPanel, 'push');
            app.Button_Docs.Icon = 'ReadTheDocs.png';
            app.Button_Docs.Tooltip = {'Open the NTB documentation webpage for Image Settings.'};
            app.Button_Docs.Text = '';
            app.Button_Save = uibutton(app.OptionsPanel, 'push');
            app.Button_Save.Icon = 'Save.png';
            app.Button_Save.Tooltip = {'Save current Image settings to Params file.'};
            app.Button_Save.Text = '';
            app.Button_Load = uibutton(app.OptionsPanel, 'push');
            app.Button_Load.Icon = 'Transfer.png';
            app.Button_Load.Tooltip = {'Load Image settings from a different parameter file.'};
            app.Button_Load.Text = '';
            app.Button_Close = uibutton(app.OptionsPanel, 'push');
            app.Button_Close.Icon = 'Exit.png';
            app.Button_Close.Tooltip = {'Exit Image Settings GUI without updating Image parameters.'};
            app.Button_Close.Text = '';
            app.Button_LoadTex = uibutton(app.OptionsPanel, 'push');
            app.Button_LoadTex.Icon = 'Refresh.png';
            app.Button_LoadTex.Tooltip = {'Load selected images to PTB textures.'};
            app.Button_LoadTex.Text = '';
            % Show the figure after all components are created
            app.NTBBlockDesignUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NTB_BlockDesign(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NTBBlockDesignUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NTBBlockDesignUIFigure)
        end
    end
end