classdef NTB_CalibrationGrid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NTBCalibrationGridUIFigure      matlab.ui.Figure
        CloseButton                     matlab.ui.control.Button
        OnButton                        matlab.ui.control.StateButton
        GridapperancePanel              matlab.ui.container.Panel
        BackgroundcolorLabel            matlab.ui.control.Label
        BackgroundcolorButton           matlab.ui.control.Button
        LinewidthEditField              matlab.ui.control.NumericEditField
        LinewidthEditFieldLabel         matlab.ui.control.Label
        LinecolorLabel                  matlab.ui.control.Label
        LineColorButton                 matlab.ui.control.Button
        MinorlinespacingEditField       matlab.ui.control.NumericEditField
        MinorlinespacingEditFieldLabel  matlab.ui.control.Label
        UnitsDropDown                   matlab.ui.control.DropDown
        UnitsDropDownLabel              matlab.ui.control.Label
        MajorlinespacingEditField       matlab.ui.control.NumericEditField
        MajorlinespacingLabel           matlab.ui.control.Label
        GridtypeDropDown                matlab.ui.control.DropDown
        GridtypeDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
       Grid
       Display
       Run
       NTBapp
    end
    
    methods (Access = public)
        
        function app = SetDefaults(app)
            app.Grid.Type               = 'Radial';
            app.Grid.Units              = 'DVA';
            app.Grid.MajorSpacing.DVA   = 10;
            app.Grid.MinorSpacing.DVA   = 5;
            app.Grid.LineWidth.DVA      = 0.01;
            app.Grid.LineColor          = [1,0,0];
            app.Grid.BckgrndColor       = [0,0,0];
        end
        
        function app = ConvertUnits(app)
            Fields = {'MajorSpacing', 'MinorSpacing', 'LineWidth'};
            for f = 1:numel(Fields)
                Value = eval(sprintf('app.Grid.%s.%s', Fields{f}, app.Grid.Units));
                Out = NTB_UnitConversion(Value, app.Grid.Units, app.Display);
                eval(sprintf('app.Grid.%s = Out', Fields{f}));
            end
        end
        
        function app = UpdateUnits(app)
            app.MajorlinespacingEditField.Value = eval(sprintf('app.Grid.MajorSpacing.%s', app.Grid.Units));
            app.MinorlinespacingEditField.Value = eval(sprintf('app.Grid.MinorSpacing.%s', app.Grid.Units));
            app.LinewidthEditField.Value        = eval(sprintf('app.Grid.LineWidth.%s', app.Grid.Units));
        end
        
        %=============== Update PTB texture for calibration grid
        function app = UpdateTexture(app)
            if app.OnButton.Value == 1
                fprintf('Updating grid texture... \n');
                ImMat       = ones(app.Display.Basic.Rect(4), app.Display.Basic.Rect(3), 3);
                for ch = 1:3
                    ImMat(:,:,ch) = ImMat(:,:,ch)*app.Grid.BckgrndColor(ch);
                end
                app.Grid.Tex    = Screen('MakeTexture', app.NTBapp.Run.Win, ImMat);
                Screen('FillRect', app.Grid.Tex, app.Grid.BckgrndColor);
                ScreenRect      = app.Display.Basic.Rect;
                CrossHairV      = [0, ScreenRect(4)/2, ScreenRect(3), ScreenRect(4)/2];
                CrossHairH      = [ScreenRect(3)/2, 0, ScreenRect(3)/2, ScreenRect(4)];
     
                switch lower(app.Grid.Type)
                    case 'radial'
                        NoRings = ceil((max(ScreenRect)/2)/app.Grid.MinorSpacing.Pixels);
                        for r = 1:NoRings
                            Rect = [0,0, repmat(r*app.Grid.MajorSpacing.Pixels,[1,2])];
                            Screen('FrameOval', app.Grid.Tex, app.Grid.LineColor, CenterRect(Rect, ScreenRect), ceil(app.Grid.LineWidth.Pixels));
                        end
                        
                    case 'regular'
                        Center  = ScreenRect([3,4])/2;
                        Spacing = app.Grid.MinorSpacing.Pixels;
                        MajorSpacing = app.Grid.MajorSpacing.Pixels;
                        MinorLinesX  = [Center(1):-Spacing:0, (Center(1)+Spacing):Spacing:ScreenRect(3)];
                        MinorLinesY  = [Center(2):-Spacing:0, (Center(2)+Spacing):Spacing:ScreenRect(4)];
                        MajorLinesX  = [Center(1):-MajorSpacing:0, (Center(1)+MajorSpacing):MajorSpacing:ScreenRect(3)];
                        MajorLinesY  = [Center(2):-MajorSpacing:0, (Center(2)+MajorSpacing):MajorSpacing:ScreenRect(4)];
                        MajorLinesXY = [];
                        MinorLinesXY = [];
                        for l = 1:numel(MinorLinesX)
                            MinorLinesXY = [MinorLinesXY, [MinorLinesX(l), MinorLinesX(l); 0, ScreenRect(4)]];
                            if l <= numel(MinorLinesY)
                                MinorLinesXY = [MinorLinesXY, [0, ScreenRect(3); MinorLinesY(l), MinorLinesY(l)]];
                            end
                            if l <= numel(MajorLinesY)
                                MajorLinesXY = [MajorLinesXY, [0, ScreenRect(3); MajorLinesY(l), MajorLinesY(l)]];
                            end
                            if l <= numel(MajorLinesX)
                                MajorLinesXY = [MajorLinesXY, [MajorLinesX(l), MajorLinesX(l); 0, ScreenRect(4)]];
                            end
                        end
                        Screen('DrawLines', app.Grid.Tex, MinorLinesXY, ceil(app.Grid.LineWidth.Pixels), app.Grid.LineColor);
                        Screen('DrawLines', app.Grid.Tex, MajorLinesXY, ceil(app.Grid.LineWidth.Pixels)+2, app.Grid.LineColor);
                end
                Screen('DrawLine', app.Grid.Tex, app.Grid.LineColor, CrossHairV(1), CrossHairV(2), CrossHairV(3), CrossHairV(4), ceil(app.Grid.LineWidth.Pixels)+4)
                Screen('DrawLine', app.Grid.Tex, app.Grid.LineColor, CrossHairH(1), CrossHairH(2), CrossHairH(3), CrossHairH(4), ceil(app.Grid.LineWidth.Pixels)+4);
                Screen('DrawTexture', app.NTBapp.Run.Win, app.Grid.Tex);
                Screen('Flip', app.NTBapp.Run.Win);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NTBapp)
            
            if isfield(NTBapp.Params, 'Grid')               
                app.Grid            = NTBapp.Params.Grid;   % Add Grid struct to app object
            else
                app = SetDefaults(app);
            end
            app.NTBapp          = NTBapp;                   % Add NTB app object to app object 
            app.Display         = NTBapp.Params.Display;
            
            app.UnitsDropDown.Value = app.Grid.Units;
            app = ConvertUnits(app);
            app = UpdateUnits(app);
            app.LineColorButton.BackgroundColor         = app.Grid.LineColor;
            app.BackgroundcolorButton.BackgroundColor   = app.Grid.BckgrndColor;
            app = UpdateTexture(app);
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            value = app.UnitsDropDown.Value;
            app = ConvertUnits(app);
            app.Grid.Units = value;
            app = UpdateUnits(app);
        end

        % Value changed function: GridtypeDropDown
        function GridtypeDropDownValueChanged(app, event)
            value = app.GridtypeDropDown.Value;
            app.Grid.Type = value;
            app = UpdateTexture(app);
        end

        % Value changed function: MajorlinespacingEditField
        function MajorlinespacingEditFieldValueChanged(app, event)
            value = app.MajorlinespacingEditField.Value;
            eval(sprintf('app.Grid.MajorSpacing.%s = value', app.Grid.Units));
            app = ConvertUnits(app);
            app = UpdateTexture(app);
        end

        % Value changed function: MinorlinespacingEditField
        function MinorlinespacingEditFieldValueChanged(app, event)
            value = app.MinorlinespacingEditField.Value;
            eval(sprintf('app.Grid.MinorSpacing.%s = value', app.Grid.Units));
            app = ConvertUnits(app);
            app = UpdateTexture(app);
        end

        % Value changed function: LinewidthEditField
        function LinewidthEditFieldValueChanged(app, event)
            value = app.LinewidthEditField.Value;
            eval(sprintf('app.Grid.LineWidth.%s = value', app.Grid.Units));
            app = ConvertUnits(app);
            app = UpdateTexture(app);
        end

        % Button pushed function: LineColorButton
        function LineColorButtonPushed(app, event)
            c = uisetcolor(app.Grid.LineColor, 'Line color');
            if numel(c) == 3
                app.Grid.LineColor = c;
                app.LineColorButton.BackgroundColor = c;
            end
            app = UpdateTexture(app);
        end

        % Button pushed function: BackgroundcolorButton
        function BackgroundcolorButtonPushed(app, event)
            c = uisetcolor(app.Grid.BckgrndColor, 'Line color');
            if numel(c) == 3
                app.Grid.BckgrndColor = c;
                app.BackgroundcolorButton.BackgroundColor = c;
            end
            app = UpdateTexture(app);
        end

        % Value changed function: OnButton
        function OnButtonValueChanged(app, event)
            value = app.OnButton.Value;
            if value == 1
                app.OnButton.Text = 'Off';
                app = UpdateTexture(app);
            elseif value == 0
                app.OnButton.Text = 'On';
                Screen('Flip', app.NTBapp.Run.Win);
            end
            
        end

        % Callback function
        function UpdateButton_2Pushed(app, event)
            app = UpdateTexture(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            %msg = sprintf('Warning: Close calibration grid and GUI?');
            %selection   = uiconfirm(app.NTBCalibrationGridUIFigure, msg, 'Close window?', 'Options',{'Close','Cancel'}, 'Icon', 'Cal.png');
            %if strcmp(selection, 'Close')
            %%TBCalibrationGridUIFigure);
            %end
            NTBCalibrationGridUIFigureCloseRequest(app);
        end

        % Close request function: NTBCalibrationGridUIFigure
        function NTBCalibrationGridUIFigureCloseRequest(app, event)
            app.NTBapp.Params.Grid = app.Grid;
            app.NTBapp.Button_CalGrid.BackgroundColor = app.NTBapp.OffOnColors{1};
            Screen('Flip', app.NTBapp.Run.Win);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NTBCalibrationGridUIFigure = uifigure('Visible', 'off');
            app.NTBCalibrationGridUIFigure.Name = 'NTB Calibration Grid';
            app.NTBCalibrationGridUIFigure.CloseRequestFcn = createCallbackFcn(app, @NTBCalibrationGridUIFigureCloseRequest, true);
            app.NTBCalibrationGridUIFigure.Tag = 'NTB_CalibrationGrid';
            app.GridapperancePanel = uipanel(app.NTBCalibrationGridUIFigure);
            app.GridapperancePanel.Title = 'Grid apperance';
            app.GridtypeDropDownLabel = uilabel(app.GridapperancePanel);
            app.GridtypeDropDownLabel.Text = 'Grid type';
            app.GridtypeDropDown = uidropdown(app.GridapperancePanel);
            app.GridtypeDropDown.Items = {'Radial', 'Regular'};
            app.GridtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GridtypeDropDownValueChanged, true);
            app.GridtypeDropDown.Value = 'Radial';
            app.MajorlinespacingLabel = uilabel(app.GridapperancePanel);
            app.MajorlinespacingLabel.Text = 'Major line spacing';
            app.MajorlinespacingEditField = uieditfield(app.GridapperancePanel, 'numeric');
            app.MajorlinespacingEditField.ValueChangedFcn = createCallbackFcn(app, @MajorlinespacingEditFieldValueChanged, true);
            app.MajorlinespacingEditField.Value = 10;
            app.UnitsDropDownLabel = uilabel(app.GridapperancePanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.GridapperancePanel);
            app.UnitsDropDown.Items = {'DVA', 'Pixels', 'Cm', 'Inches'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'DVA';
            app.MinorlinespacingEditFieldLabel = uilabel(app.GridapperancePanel);
            app.MinorlinespacingEditFieldLabel.Text = 'Minor line spacing';
            app.MinorlinespacingEditField = uieditfield(app.GridapperancePanel, 'numeric');
            app.MinorlinespacingEditField.ValueChangedFcn = createCallbackFcn(app, @MinorlinespacingEditFieldValueChanged, true);
            app.MinorlinespacingEditField.Value = 5;
            app.LineColorButton = uibutton(app.GridapperancePanel, 'push');
            app.LineColorButton.ButtonPushedFcn = createCallbackFcn(app, @LineColorButtonPushed, true);
            app.LineColorButton.Text = '';
            app.LinecolorLabel = uilabel(app.GridapperancePanel);
            app.LinecolorLabel.Text = 'Line color';
            app.LinewidthEditFieldLabel = uilabel(app.GridapperancePanel);
            app.LinewidthEditFieldLabel.Text = 'Line width';
            app.LinewidthEditField = uieditfield(app.GridapperancePanel, 'numeric');
            app.LinewidthEditField.Limits = [0 Inf];
            app.LinewidthEditField.ValueChangedFcn = createCallbackFcn(app, @LinewidthEditFieldValueChanged, true);
            app.LinewidthEditField.Value = 0.15;
            app.BackgroundcolorButton = uibutton(app.GridapperancePanel, 'push');
            app.BackgroundcolorButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundcolorButtonPushed, true);
            app.BackgroundcolorButton.Text = '';
            app.BackgroundcolorLabel = uilabel(app.GridapperancePanel);
            app.BackgroundcolorLabel.Text = 'Background color';
            app.OnButton = uibutton(app.NTBCalibrationGridUIFigure, 'state');
            app.OnButton.ValueChangedFcn = createCallbackFcn(app, @OnButtonValueChanged, true);
            app.OnButton.Text = 'On';
            app.OnButton.Value = true;
            app.CloseButton = uibutton(app.NTBCalibrationGridUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.NTBCalibrationGridUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NTB_CalibrationGrid(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NTBCalibrationGridUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NTBCalibrationGridUIFigure)
        end
    end
end