classdef PTB_DisplaySettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NTBDisplaySettingsUIFigure      matlab.ui.Figure
        PreviewPanel                    matlab.ui.container.Panel
        OptionsPanel                    matlab.ui.container.Panel
        Button_Close                    matlab.ui.control.Button
        Button_Load                     matlab.ui.control.Button
        Button_Save                     matlab.ui.control.Button
        Button_Docs                     matlab.ui.control.Button
        BackgroundcolorLabel            matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GridTab                         matlab.ui.container.Tab
        MeridiansCheckBox               matlab.ui.control.CheckBox
        GridLinewidthpxSpinner          matlab.ui.control.Spinner
        LinewidthpxSpinnerLabel         matlab.ui.control.Label
        ColorLabel                      matlab.ui.control.Label
        VisibleLabel                    matlab.ui.control.Label
        GridFormatDropDown              matlab.ui.control.DropDown
        GridformatLabel                 matlab.ui.control.Label
        SpacingSlider                   matlab.ui.control.Slider
        SpacingLabel                    matlab.ui.control.Label
        Button_GridRGB                  matlab.ui.control.Button
        GridCheckBox                    matlab.ui.control.CheckBox
        FixationTab                     matlab.ui.container.Tab
        Fix_Diameter                    matlab.ui.control.Slider
        DiameterLabel                   matlab.ui.control.Label
        Fix_Linewidth                   matlab.ui.control.Spinner
        LinewidthpxSpinnerLabel_2       matlab.ui.control.Label
        FormatDropDown_2                matlab.ui.control.DropDown
        FormatDropDownLabel_2           matlab.ui.control.Label
        ColorLabel_2                    matlab.ui.control.Label
        Fix_RGB                         matlab.ui.control.Button
        CheckBox_FixMarkerOn            matlab.ui.control.CheckBox
        VisibleLabel_2                  matlab.ui.control.Label
        GazewindowTab                   matlab.ui.container.Tab
        GazeWinDimsY                    matlab.ui.control.NumericEditField
        GazeWinDimsX                    matlab.ui.control.NumericEditField
        GazeWin_Diameter                matlab.ui.control.Slider
        RadiusLabel                     matlab.ui.control.Label
        GazeLinewidth                   matlab.ui.control.Spinner
        LinewidthpxSpinnerLabel_3       matlab.ui.control.Label
        GazeFormat                      matlab.ui.control.DropDown
        FormatDropDownLabel_3           matlab.ui.control.Label
        ColorLabel_3                    matlab.ui.control.Label
        VisibleLabel_3                  matlab.ui.control.Label
        Button_FixWinRGB                matlab.ui.control.Button
        GazeWinCheckBox                 matlab.ui.control.CheckBox
        PhotodiodeTab                   matlab.ui.container.Tab
        PhotodiodeOn                    matlab.ui.control.Button
        PhotodiodeRGB                   matlab.ui.control.Button
        PhotodiodeSize                  matlab.ui.control.NumericEditField
        PhotodiodecontrastsRGBLabel     matlab.ui.control.Label
        PhotodiodesizepixelsLabel       matlab.ui.control.Label
        PhotodiodescreenLabel           matlab.ui.control.Label
        PhotodiodeScreen                matlab.ui.control.DropDown
        PhotodiodePos                   matlab.ui.control.DropDown
        PhotodiodepositionLabel         matlab.ui.control.Label
        EyetraceTab                     matlab.ui.container.Tab
        EyeTrace_Alpha                  matlab.ui.control.Slider
        EyeTrace_Duration               matlab.ui.control.Spinner
        LinewidthpxSpinnerLabel_4       matlab.ui.control.Label
        EyeTrace_Format                 matlab.ui.control.DropDown
        FormatDropDownLabel_4           matlab.ui.control.Label
        EyeTrace_Size                   matlab.ui.control.Slider
        MarkerDiameterLabel             matlab.ui.control.Label
        ColoralphaLabel                 matlab.ui.control.Label
        VisibleLabel_4                  matlab.ui.control.Label
        Button_EyePosRGB                matlab.ui.control.Button
        EyeTraceOn                      matlab.ui.control.CheckBox
        InfoTab                         matlab.ui.container.Tab
        InfoPanelPos                    matlab.ui.control.DropDown
        PositionLabel                   matlab.ui.control.Label
        InfoBkg_Alpha                   matlab.ui.control.Slider
        InfoBkg_Toggle                  matlab.ui.control.CheckBox
        InfoBkg_RGB                     matlab.ui.control.Button
        BackgroundLabel                 matlab.ui.control.Label
        FontsizeSpinner                 matlab.ui.control.Spinner
        FontsizeSpinnerLabel            matlab.ui.control.Label
        FontnameDropDown                matlab.ui.control.DropDown
        FontnameDropDownLabel           matlab.ui.control.Label
        EpochDropDown                   matlab.ui.control.DropDown
        EpochDropDownLabel              matlab.ui.control.Label
        DisplayelementsLabel            matlab.ui.control.Label
        ProgressDropDown                matlab.ui.control.DropDown
        ProgressDropDownLabel           matlab.ui.control.Label
        ResponseaccuracyCheckBox        matlab.ui.control.CheckBox
        FixationaccuracyCheckBox        matlab.ui.control.CheckBox
        RewardcounterCheckBox           matlab.ui.control.CheckBox
        EpochcounterCheckBox            matlab.ui.control.CheckBox
        TimerCheckBox                   matlab.ui.control.CheckBox
        FontcolorLabel                  matlab.ui.control.Label
        FontRGB                         matlab.ui.control.Button
        Button_BackgroundRGB            matlab.ui.control.Button
        UIAxes_R                        matlab.ui.control.UIAxes
        UIAxes_L                        matlab.ui.control.UIAxes
        ViewingGeometryPanel            matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        BasicTab                        matlab.ui.container.Tab
        DropDown_WinScale               matlab.ui.control.DropDown
        PTBWindowscaleLabel             matlab.ui.control.Label
        PixPerDegY                      matlab.ui.control.NumericEditField
        PixPerDegX                      matlab.ui.control.NumericEditField
        PixelsdegreeXYLabel             matlab.ui.control.Label
        ScreenResY                      matlab.ui.control.NumericEditField
        ScreenResX                      matlab.ui.control.NumericEditField
        ScreenresolutionpixelsLabel     matlab.ui.control.Label
        ScreenDimY                      matlab.ui.control.NumericEditField
        ScreenDimX                      matlab.ui.control.NumericEditField
        ScreendimensionscmLabel         matlab.ui.control.Label
        ViewingDistance                 matlab.ui.control.NumericEditField
        ViewingdistancecmLabel          matlab.ui.control.Label
        StereoscopicTab                 matlab.ui.container.Tab
        ExperimenterdisplayLabel        matlab.ui.control.Label
        DdisplayformatLabel             matlab.ui.control.Label
        PTBStereomodeLabel              matlab.ui.control.Label
        InterpupillarydistancecmLabel   matlab.ui.control.Label
        ExperimenterdisplayFormat       matlab.ui.control.DropDown
        StereoDisplayFormat             matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        Stereomode                      matlab.ui.control.DropDown
        IPD                             matlab.ui.control.NumericEditField
        OpenGLTab                       matlab.ui.container.Tab
        SystemProfilePanel              matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        SystemTab                       matlab.ui.container.Tab
        ComputerID                      matlab.ui.control.Label
        OperatingSystem                 matlab.ui.control.Label
        MATLABversion                   matlab.ui.control.Label
        PsychToolboxversion             matlab.ui.control.Label
        Graphicscard                    matlab.ui.control.Label
        OpenGLversion                   matlab.ui.control.Label
        OpenGLversionLabel              matlab.ui.control.Label
        GraphicscardLabel               matlab.ui.control.Label
        PsychToolboxversionLabel        matlab.ui.control.Label
        MATLABversionLabel              matlab.ui.control.Label
        OperatingSystemLabel            matlab.ui.control.Label
        ComputerIDLabel                 matlab.ui.control.Label
        XScreensTab                     matlab.ui.container.Tab
        DropDown_ExpCopyPos             matlab.ui.control.DropDown
        ExperimentercopyLabel           matlab.ui.control.Label
        HalfWidthPixels                 matlab.ui.control.Label
        HalfwidthpixelsLabel            matlab.ui.control.Label
        SelectedXScreen                 matlab.ui.control.DropDown
        SelectedXScreenLabel            matlab.ui.control.Label
        NoXscreens                      matlab.ui.control.Label
        Totalpixels                     matlab.ui.control.Label
        Refreshrate                     matlab.ui.control.Label
        RefreshrateHzLabel              matlab.ui.control.Label
        TotalresolutionpixelsLabel      matlab.ui.control.Label
        NoXscreensLabel                 matlab.ui.control.Label
        PTBTab                          matlab.ui.container.Tab
        VerbosityDropDown               matlab.ui.control.DropDown
        VerbosityDropDownLabel          matlab.ui.control.Label
        PsychDefaultSetupDropDown       matlab.ui.control.DropDown
        PsychDefaultSetupDropDownLabel  matlab.ui.control.Label
        VisualdebuglevelDropDown        matlab.ui.control.DropDown
        VisualdebuglevelDropDownLabel   matlab.ui.control.Label
        PTBSuppressallwarningsCheckBox  matlab.ui.control.CheckBox
        PTBSkipsynctestsCheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        NTBapp      % Handle to the main NTB GUI app data
        Display     % Display parameters structure
        Preview
        UIAxes_Exp
        UIAxes_Sub
    end
    
    methods (Access = private)
        
        function RangeWarning(app, component, lower, upper)
            if component.Value > lower && component.Value < upper
                component.BackgroundColor = [1,1,1];
            else
                component.BackgroundColor = [1,0,0];
            end
        end
        
        function RecalculatePixPerDeg(app)
            PixPerCm = app.Display.Basic.Rect([3,4])./app.Display.Basic.ScreenDims;
            CmPerDeg = tand(1)*app.Display.Basic.ViewingDist;
            app.Display.Basic.PixPerDeg = PixPerCm./CmPerDeg;
            app.Display.Basic.PixPerCm  = PixPerCm;
            app.Display.Basic.CmPerDeg  = CmPerDeg;
            app.PixPerDegX.Value = app.Display.Basic.PixPerDeg(1);
            app.PixPerDegY.Value = app.Display.Basic.PixPerDeg(2);
        end
        
        function UpdatePreview(app)
            
            %===== Update display background
            BkgIm = ones(app.Display.Basic.Rect([4,3]));
            for ch = 1:3
                BkgIm(:,:,ch) = app.Display.Screen.BackgroundColor(ch);
            end
            %===== Set experimenter and subject screen positions
            switch app.Display.Screen.ExpDispPos
                case 'Left'
                    app.UIAxes_Exp = app.UIAxes_L;
                    app.UIAxes_Sub = app.UIAxes_R;
                case 'Right'
                    app.UIAxes_Exp = app.UIAxes_R;
                    app.UIAxes_Sub = app.UIAxes_L;
                case 'None'
                    app.UIAxes_Exp = app.UIAxes_R;
                    app.UIAxes_Sub = app.UIAxes_L;
            end
            %===== Draw background image
            if ~isfield(app.Preview, 'ExpIm')
                app.Preview.ExpIm = image(app.UIAxes_Exp, BkgIm); hold on;
                app.Preview.SubIm = image(app.UIAxes_Sub, BkgIm); hold on;
            else
                set(app.Preview.ExpIm, 'CData', BkgIm);
                set(app.Preview.SubIm, 'CData', BkgIm);
            end
            
            %===== Plot grid
            GridSpacingPx   = round(app.Display.Grid.Spacing*app.Display.Basic.PixPerDeg(1));
            NoLines         = ceil(max(app.Display.Basic.Rect([3,4]))/(GridSpacingPx*2));
            if strcmp(app.Display.Grid.Format, 'Radial')
                GridCurvature = 1;
            else
                GridCurvature = 0;
            end
            for g = 1:NoLines
                Pos = [(app.Display.Basic.Rect([3,4])/2)-g*GridSpacingPx, 2*g*GridSpacingPx, 2*g*GridSpacingPx];
                if ~isfield(app.Preview, 'GridH') || numel(app.Preview.GridH) < g
                    app.Preview.GridH(g) = rectangle(app.UIAxes_Exp,'Position',Pos,'Curvature',GridCurvature,'EdgeColor',app.Display.Grid.Color,'LineWidth', app.Display.Grid.Width);
                    hold on;
                else
                    set(app.Preview.GridH(g),'Position', Pos,'Curvature',GridCurvature,'EdgeColor',app.Display.Grid.Color, 'LineWidth', app.Display.Grid.Width);
                end
            end
            if numel(app.Preview.GridH) > g
                delete(app.Preview.GridH(g:end));
                app.Preview.GridH(g:end) = [];
            end
            set(app.UIAxes_Exp, 'Xlim', [0, app.Display.Basic.Rect(3)], 'Ylim', [0, app.Display.Basic.Rect(4)],'DataAspectRatio',[1,1,1],'YDir','reverse');
            set(app.UIAxes_Sub, 'Xlim', [0, app.Display.Basic.Rect(3)], 'Ylim', [0, app.Display.Basic.Rect(4)],'DataAspectRatio',[1,1,1],'YDir','reverse');
            set(app.Preview.GridH, 'visible', app.Display.Grid.On);
            
            % Draw meridian lines
            if ~isfield(app.Preview, 'GridMH')
                set(app.UIAxes_Exp, 'NextPlot', 'add');
                app.Preview.GridMH(1) = plot(app.UIAxes_Exp, repmat(app.Display.Basic.Rect(3)/2,[1,2]), [0, app.Display.Basic.Rect(4)], 'Color',app.Display.Grid.Color,'LineWidth', app.Display.Grid.Width, 'visible', app.Display.Grid.MeridiansOn); 
                app.Preview.GridMH(2) = plot(app.UIAxes_Exp, [0, app.Display.Basic.Rect(3)], repmat(app.Display.Basic.Rect(4)/2,[1,2]), 'Color',app.Display.Grid.Color,'LineWidth', app.Display.Grid.Width, 'visible', app.Display.Grid.MeridiansOn);
            else
                set(app.Preview.GridMH(1), 'Xdata', repmat(app.Display.Basic.Rect(3)/2,[1,2]), 'Ydata', [0, app.Display.Basic.Rect(4)], 'Color',app.Display.Grid.Color,'LineWidth', app.Display.Grid.Width, 'visible', app.Display.Grid.MeridiansOn);
                set(app.Preview.GridMH(2), 'Xdata', [0, app.Display.Basic.Rect(3)], 'Ydata', repmat(app.Display.Basic.Rect(4)/2,[1,2]), 'Color',app.Display.Grid.Color,'LineWidth', app.Display.Grid.Width, 'visible', app.Display.Grid.MeridiansOn);
            end
            
            %===== Draw gaze window
            if strcmp(app.Display.GazeWin.Format, 'Circle')
                GazeWinCurv = 1;
            else
                GazeWinCurv = 0;
            end
            if strcmp(app.Display.GazeWin.Format, 'Rectangle')
                Pos = [app.Display.Basic.Rect([3,4])/2 - app.Display.Basic.PixPerDeg.*app.Display.GazeWin.Dimensions/2, app.Display.Basic.PixPerDeg.*app.Display.GazeWin.Dimensions];
            else
                Pos = [app.Display.Basic.Rect([3,4])/2 - app.Display.Basic.PixPerDeg*app.Display.GazeWin.Diameter/2, app.Display.Basic.PixPerDeg*app.Display.GazeWin.Diameter];
            end
            if ~isfield(app.Preview, 'GazeH')
                app.Preview.GazeH = rectangle(app.UIAxes_Exp,'Position',Pos,'Curvature',GazeWinCurv,'EdgeColor',app.Display.GazeWin.Color(1,:),'lineWidth',2, 'visible', app.Display.GazeWin.On);
                hold on;
            else
                set(app.Preview.GazeH,'Position',Pos,'Curvature',GazeWinCurv,'EdgeColor',app.Display.GazeWin.Color(2,:), 'visible', app.Display.GazeWin.On);
            end
            
            %===== Draw fixation marker
            FixRadiusPx = app.Display.Fix.Diameter*app.Display.Basic.PixPerDeg/2;
            FixRect = round([app.Display.Basic.Rect([3,4])/2 - FixRadiusPx, app.Display.Basic.Rect([3,4])/2 + FixRadiusPx]);
            switch app.Display.Fix.Format
                case 'Cross'
                    if ~isfield(app.Preview, 'Fix')
                        set(app.UIAxes_Exp, 'NextPlot', 'add');
                        set(app.UIAxes_Sub, 'NextPlot', 'add');
                        app.Preview.Fix(1,1) = plot(app.UIAxes_Exp, repmat(app.Display.Basic.Rect(3)/2, [1,2]), FixRect([2,4]),'color', app.Display.Fix.Color, 'LineWidth', app.Display.Fix.Width);
                        app.Preview.Fix(1,2) = plot(app.UIAxes_Exp, FixRect([1,3]), repmat(app.Display.Basic.Rect(4)/2, [1,2]),'color', app.Display.Fix.Color, 'LineWidth', app.Display.Fix.Width);
                        app.Preview.Fix(2,1) = plot(app.UIAxes_Sub, repmat(app.Display.Basic.Rect(3)/2, [1,2]), FixRect([2,4]),'color', app.Display.Fix.Color, 'LineWidth', app.Display.Fix.Width);
                        app.Preview.Fix(2,2) = plot(app.UIAxes_Sub, FixRect([1,3]), repmat(app.Display.Basic.Rect(4)/2, [1,2]),'color', app.Display.Fix.Color, 'LineWidth', app.Display.Fix.Width);
                    else
                        set(app.Preview.Fix(:,1), 'Xdata', repmat(app.Display.Basic.Rect(3)/2, [1,2]), 'Ydata', FixRect([2,4]));
                        set(app.Preview.Fix(:,2), 'Xdata', FixRect([1,3]), 'Ydata', repmat(app.Display.Basic.Rect(4)/2, [1,2]));
                        set(app.Preview.Fix, 'Color', app.Display.Fix.Color, 'LineWidth', app.Display.Fix.Width, 'visible', app.Display.Fix.On);
                    end
                    
                case 'Dot'
                    if ~isfield(app.Preview, 'Fix')
                        app.Preview.Fix(1,1) = rectangle(app.UIAxes_Exp, 'Position', [FixRect([1,3]), repmat(FixRadiusPx*2, [1,2])], 'Curvature', 1, 'FaceColor', app.Display.Fix.Color);
                        app.Preview.Fix(2,1) = rectangle(app.UIAxes_Sub, 'Position', [FixRect([1,3]), repmat(FixRadiusPx*2, [1,2])], 'Curvature', 1, 'FaceColor', app.Display.Fix.Color);
                    else
                        set(app.Preview.Fix(:,1), 'Position', [FixRect([1,3]), repmat(FixRadiusPx*2, [1,2])], 'FaceColor', app.Display.Fix.Color);
                    end
                case 'Square'
                    if ~isfield(app.Preview, 'Fix')
                        app.Preview.Fix(1,1) = rectangle(app.UIAxes_Exp, 'Position', [FixRect([1,3]), repmat(FixRadiusPx*2, [1,2])], 'Curvature', 0, 'FaceColor', app.Display.Fix.Color);
                        app.Preview.Fix(2,1) = rectangle(app.UIAxes_Sub, 'Position', [FixRect([1,3]), repmat(FixRadiusPx*2, [1,2])], 'Curvature', 0, 'FaceColor', app.Display.Fix.Color);
                    else
                        set(app.Preview.Fix(:,1), 'Position', [FixRect([1,3]), repmat(FixRadiusPx*2, [1,2])], 'FaceColor', app.Display.Fix.Color);
                    end
                case 'Cross-hair'
                    
            end
            set(app.Preview.Fix(ishandle(app.Preview.Fix)), 'visible', app.Display.Fix.On);
            
            
            %===== Draw photodiode markers
            switch app.Display.PD.Position
                case 'Top left'
                    Pos = [0, 0, app.Display.PD.Diameter, app.Display.PD.Diameter]; 
                case 'Top right'
                    Pos = [app.Display.Basic.Rect(3)-app.Display.PD.Diameter, 0, app.Display.PD.Diameter, app.Display.PD.Diameter]; 
                case 'Bottom left'
                    Pos = [0, app.Display.Basic.Rect(4)-app.Display.PD.Diameter, app.Display.PD.Diameter, app.Display.PD.Diameter]; 
                case 'Bottom right'
                    Pos = [app.Display.Basic.Rect(3)-app.Display.PD.Diameter, app.Display.Basic.Rect(4)-app.Display.PD.Diameter, app.Display.PD.Diameter, app.Display.PD.Diameter]; 
            end
            if ~isfield(app.Preview, 'PdH')
                app.Preview.PdH(1) = rectangle(app.UIAxes_Exp,'Position',Pos,'Curvature',1,'FaceColor',app.Display.PD.Color{1});
                app.Preview.PdH(2) = rectangle(app.UIAxes_Sub,'Position',Pos,'Curvature',1,'FaceColor',app.Display.PD.Color{1});
            else
                set(app.Preview.PdH, 'Position', Pos,'FaceColor',app.Display.PD.Color{1});
            end
            switch app.Display.PD.Screen
                case 'Subject display'
                    set(app.Preview.PdH(1), 'visible', 'off');
                    set(app.Preview.PdH(2), 'visible', 'on');
                case 'Experimenter display'
                    set(app.Preview.PdH(1), 'visible', 'on');
                    set(app.Preview.PdH(2), 'visible', 'off');
                case 'Both displays'
                    set(app.Preview.PdH, 'visible', 'on');
                case 'None'
                    set(app.Preview.PdH, 'visible', 'off');
            end

            
        end
        
        %============= Update display setting values
        function results = UpdateValues(app)
            
            % Set System Screens values
            app.NoXscreens.Text                 = num2str(app.Display.Screen.NoXScreens);
            if app.Display.Screen.NoXScreens == 1
                app.NoXscreens.BackgroundColor  = [1,0,0];
            else
                app.NoXscreens.BackgroundColor  = 'none';
            end
            app.SelectedXScreen.Items           = cellstr(num2str(Screen('screens')'));
            app.SelectedXScreen.Value           = num2str(app.Display.Screen.ID);
            app.Totalpixels.Text                = [num2str(app.Display.Screen.XScreenRect(3)),' x ',num2str(app.Display.Screen.XScreenRect(4))];
            app.Refreshrate.Text                = [num2str(app.Display.Screen.RefreshRate), ' Hz'];
            app.HalfWidthPixels.Text            = num2str(app.Display.Screen.XScreenRect(3)/2);
            app.DropDown_ExpCopyPos.Value       = app.Display.Screen.ExpDispPos;
            
            % PTB Settings
            app.PTBSkipsynctestsCheckBox.Value        = app.Display.PTB.SkipSyncTests;
            app.PTBSuppressallwarningsCheckBox.Value  = app.Display.PTB.SuppressWarnings;
            app.VisualdebuglevelDropDown.Value        = app.VisualdebuglevelDropDown.Items{app.Display.PTB.VisualDebugLevel};
            app.PsychDefaultSetupDropDown.Value       = app.PsychDefaultSetupDropDown.Items{app.Display.PTB.PsychDefaultSetup+1};
            app.VerbosityDropDown.Value               = app.VerbosityDropDown.Items{app.Display.PTB.Verbosity+1};
            
            % Set Basic Viewing Geometry values
            app.ViewingDistance.Value           = app.Display.Basic.ViewingDist;
            app.ScreenDimX.Value                = app.Display.Basic.ScreenDims(1);
            app.ScreenDimY.Value                = app.Display.Basic.ScreenDims(2);
            app.ScreenResX.Value                = app.Display.Basic.Rect(3);
            app.ScreenResY.Value                = app.Display.Basic.Rect(4);
            if app.Display.Screen.NoXScreens > 1
                app.DropDown_WinScale.Value     = app.Display.Basic.WinScale;
            else 
                app.DropDown_WinScale.Value     = 'Half-size';
            end
            RecalculatePixPerDeg(app);

            % Set Stereo Viewing Geometry values
            app.IPD.Value                       = app.Display.Stereo.IPD;
            app.Stereomode.Value                = app.Stereomode.Items{app.Display.Stereo.Mode+1};
            app.StereoDisplayFormat.Value       = app.Display.Stereo.DisplayFormat;
            app.ExperimenterdisplayFormat.Value = app.Display.Stereo.ExpDisplayFormat;
            
            % Set Apperance values
            app.Button_BackgroundRGB.BackgroundColor    = app.Display.Screen.BackgroundColor;
            
            %===== Grid
            app.GridCheckBox.Value                      = app.Display.Grid.On;
            app.MeridiansCheckBox.Value                 = app.Display.Grid.MeridiansOn;
            app.Button_GridRGB.BackgroundColor          = app.Display.Grid.Color;
            app.GridLinewidthpxSpinner.Value            = app.Display.Grid.Width;
            app.GridFormatDropDown.Value                = app.Display.Grid.Format;
            app.SpacingSlider.Value                     = app.Display.Grid.Spacing;
            
            %===== Fixation marker
            app.CheckBox_FixMarkerOn.Value              = app.Display.Fix.On;
            app.Fix_RGB.BackgroundColor                 = app.Display.Fix.Color;
            app.Fix_Linewidth.Value                     = app.Display.Fix.Width;
            app.Fix_Diameter.Value                      = app.Display.Fix.Diameter;
            app.FormatDropDown_2.Value                  = app.Display.Fix.Format;
            
            %===== Photodiode
            app.PhotodiodePos.Value                     = app.Display.PD.Position;
            app.PhotodiodeScreen.Value                  = app.Display.PD.Screen;
            app.PhotodiodeSize.Value                    = app.Display.PD.Diameter;
            app.PhotodiodeRGB.BackgroundColor           = app.Display.PD.Color{1};
            app.PhotodiodeOn.BackgroundColor            = app.Display.PD.Color{2};
            
            %===== Gaze window
            app.GazeWinCheckBox.Value                   = app.Display.GazeWin.On;
            app.Button_FixWinRGB.BackgroundColor        = app.Display.GazeWin.Color(2,:);
            app.GazeFormat.Value                        = app.Display.GazeWin.Format;
            app.GazeLinewidth.Value                     = app.Display.GazeWin.Width;
            app.GazeWin_Diameter.Value                  = app.Display.GazeWin.Diameter;
            app.GazeWinDimsX.Value                      = app.Display.GazeWin.Dimensions(1);
            app.GazeWinDimsY.Value                      = app.Display.GazeWin.Dimensions(2);
            if strcmp(app.Display.GazeWin.Format, 'Rectangle')
                app.GazeWin_Diameter.Visible    = 0;
                app.RadiusLabel.Text            = 'Dimensions (°)';
                app.GazeWinDimsX.Visible        = 1;
                app.GazeWinDimsY.Visible        = 1;
            else
                app.GazeWin_Diameter.Visible    = 1;
                app.RadiusLabel.Text            = 'Radius (°)';
                app.GazeWinDimsX.Visible        = 0;
                app.GazeWinDimsY.Visible        = 0;
            end
            
            %===== Eye trace
            app.EyeTraceOn.Value                        = app.Display.Eye.On;
            app.Button_EyePosRGB.BackgroundColor        = app.Display.Eye.Color(1,:);
            app.EyeTrace_Duration.Value                 = app.Display.Eye.TraceDuration;
            app.EyeTrace_Format.Value                   = app.Display.Eye.Format;
            app.EyeTrace_Size.Value                     = app.Display.Eye.Diameter;
            app.EyeTrace_Alpha.Value                    = app.Display.Eye.Alpha;
            
            %===== Info panel
            app.InfoPanelPos.Value                      = app.Display.Info.PanelPosStr;
            app.InfoBkg_Toggle.Value                    = app.Display.Info.BkgOn;    
            app.InfoBkg_RGB.BackgroundColor             = app.Display.Info.BkgRGB;
            app.InfoBkg_Alpha.Value                     = app.Display.Info.BkgAlpha;
            app.FontRGB.BackgroundColor                 = app.Display.Info.FontRGB;
            app.FontsizeSpinner.Value                   = app.Display.Info.FontSize;
            app.FontnameDropDown.Value                  = app.Display.Info.FontName;
            app.ProgressDropDown.Value                  = app.Display.Info.ProgressFormat; 
            app.EpochDropDown.Value                     = app.Display.Info.EpochFormat;
            app.TimerCheckBox.Value                     = app.Display.Info.ToggleTimer;         
            app.EpochcounterCheckBox.Value              = app.Display.Info.ToggleEpoch;
            app.RewardcounterCheckBox.Value             = app.Display.Info.ToggleReward;
            app.FixationaccuracyCheckBox.Value          = app.Display.Info.ToggleFixation;
            app.ResponseaccuracyCheckBox.Value          = app.Display.Info.ToggleResponse;
        end
        
        function UpdateInfoPanel(app, src, evt)
            evname  = evt.EventName;
            if strcmp(evname, 'ROIMoved')
                Pos     = evt.CurrentPosition + [20,200,0,0];
                set(app.Preview.InfoTxtH, 'Position', [Pos([1,2]),0]);
            end
        end
        
        %============= Update preview of experimenter screen info
        function results = UpdateDisplayInfo(app)
            
            %===== Set info panel background
            InfoPanelRect = [0,0,300,400];
            InfoPanelOffset = 50;
            switch app.Display.Info.PanelPosStr
                case 'Top left'
                    Pos = [0, 0, InfoPanelRect([3,4])]; 
                case 'Top right'
                    Pos = [0, 0, InfoPanelRect([3,4])] + repmat([app.Display.Basic.Rect(3)-InfoPanelRect(3), 0],[1,2]); 
                case 'Bottom left'
                    Pos = [0, 0, InfoPanelRect([3,4])] + repmat([0, app.Display.Basic.Rect(4)-InfoPanelRect(4)], [1,2]);
                case 'Bottom right'
                    Pos = [0, 0, InfoPanelRect([3,4])] + repmat([app.Display.Basic.Rect(3)-InfoPanelRect(3), app.Display.Basic.Rect(4)-InfoPanelRect(4)], [1,2]);  
            end
            panelXYZ = [Pos([1,1,3,3]); Pos([2,4,4,2]); 0,0,0,0];
            if ~isfield(app.Preview, 'InfoPanelH')
                app.Preview.InfoPanelH = images.roi.Rectangle(app.UIAxes_Exp, 'Position', [Pos([1,2]), InfoPanelRect([3,4])], 'Color', app.Display.Info.BkgRGB, 'FaceAlpha',app.Display.Info.BkgAlpha, 'LineWidth', 1, 'Visible', app.Display.Info.BkgOn);
                addlistener(app.Preview.InfoPanelH,'ROIMoved', @app.UpdateInfoPanel);
            else
                set(app.Preview.InfoPanelH, 'Position', [Pos([1,2]), InfoPanelRect([3,4])], 'Color', app.Display.Info.BkgRGB, 'FaceAlpha',app.Display.Info.BkgAlpha, 'Visible', app.Display.Info.BkgOn);
            end
            
            %===== Add text
            TextString = '';
            if app.Display.Info.ToggleTimer == 1
                TextString = sprintf('%sTime elapsed = %.2f s\n', TextString, 5.4);
            end
            if app.Display.Info.ToggleEpoch == 1
                switch app.Display.Info.EpochFormat
                    case 'Trial'
                        TextString = sprintf('%sTrial = %d\n', TextString, 1);
                    case 'Time / TR'
                        TextString = sprintf('%sTR # = %d\n', TextString, 1);
                end
            end
            if app.Display.Info.ToggleReward == 1
                TextString = sprintf('%sTotal reward = %d\n', TextString, 4);
            end
            if app.Display.Info.ToggleFixation == 1
                TextString = sprintf('%sValid fixation = %.1f%%\n', TextString, 83.5);
            end
            if app.Display.Info.ToggleResponse == 1
                TextString = sprintf('%sPerformance = %.1f%%\n', TextString, 78.2);
            end
            TextPos = [Pos([3,4]), 0];
            if ~isfield(app.Preview, 'InfoTxtH')
                app.Preview.InfoTxtH = text(app.UIAxes_Exp, TextPos(1), TextPos(2), TextString);
                
                %=== Set text to appear on top layer of plot
                ch = get(app.UIAxes_Exp,'children');
                [~,neworder] = sort(ismember(ch, app.Preview.InfoTxtH), 'descend');
                set(app.UIAxes_Exp,'children',ch(neworder));
            end
            set(app.Preview.InfoTxtH, 'Position', TextPos, 'FontName', app.Display.Info.FontName, 'FontSize', app.Display.Info.FontSize, 'Color', app.Display.Info.FontRGB);

        end
       
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NTBapp)
            
            app.Display         = NTBapp.Params.Display;    % Add display struct to app object
            app.NTBapp          = NTBapp;                   % Add NTB app object to app object 
            
            % Get environemnt profile info
            [~, CompName]       = system('hostname');
            Profile.CompName    = CompName;
            Profile.OS          = computer;
            Profile.MatlabVer   = version;
            Profile.OpenGL      = opengl('data');
            if ~exist('PsychtoolboxVersion.m','file')
                Profile.PTBdetected = 0;
                Profile.PTBversion  = 'NOT DETECTED!';
            else
                Profile.PTBdetected = 1;
                Profile.PTBversion  = deblank(PsychtoolboxVersion);
            end
            
            % Set System Profile values
            app.ComputerID.Text             = Profile.CompName;
            app.OperatingSystem.Text        = Profile.OS;
            app.MATLABversion.Text          = Profile.MatlabVer;
            app.OpenGLversion.Text          = Profile.OpenGL.Version;
            app.Graphicscard.Text           = Profile.OpenGL.Renderer;
            app.PsychToolboxversion.Text    = Profile.PTBversion;
            if Profile.PTBdetected == 0
                app.PsychToolboxversion.BackgroundColor = [1,0,0];
                app.PsychToolboxversion.FontColor = [1,1,1];
            else
                % Get System Screens values
                app.Display.Screen.NoXScreens 	= numel(Screen('screens'));
                app.Display.Screen.ID           = max(Screen('screens'));
                app.Display.Screen.XScreenRect  = Screen('rect',max(Screen('screens')));
                app.Display.Screen.RefreshRate  = Screen('NominalFramerate', max(Screen('screens')));
            end
            
            % Update GUI
            UpdateValues(app);          % Update GUI values
            UpdatePreview(app);         % Update display preview panel
            UpdateDisplayInfo(app);     % Update display preview info panel
        end

        % Close request function: NTBDisplaySettingsUIFigure
        function NTBDisplaySettingsUIFigureCloseRequest(app, event)
            app.NTBapp.Params.Display = app.Display;
            app.NTBapp.Button_Display.BackgroundColor = app.NTBapp.OffOnColors{1};
            delete(app)
            %clear app
        end

        % Value changed function: ViewingDistance
        function ViewingDistanceValueChanged(app, event)
            value = app.ViewingDistance.Value;
            app.Display.Basic.ViewingDist = value;
            RangeWarning(app, app.ViewingDistance, 0, 1000);
            RecalculatePixPerDeg(app);
        end

        % Value changed function: IPD
        function IPDValueChanged(app, event)
            value = app.IPD.Value;
            app.Display.IPD = value;
            RangeWarning(app, app.IPD, 1, 10);
        end

        % Value changed function: ScreenDimX
        function ScreenDimXValueChanged(app, event)
            value = app.ScreenDimX.Value;
            app.Display.Basic.ScreenDims(1) = value;
            RecalculatePixPerDeg(app);
        end

        % Value changed function: ScreenDimY
        function ScreenDimYValueChanged(app, event)
            value = app.ScreenDimY.Value;
            app.Display.Basic.ScreenDims(2) = value;
            RecalculatePixPerDeg(app);
        end

        % Value changed function: ScreenResX
        function ScreenResXValueChanged(app, event)
            value = app.ScreenResX.Value;
            app.Display.Basic.Rect(3) = value;
            RecalculatePixPerDeg(app);
        end

        % Value changed function: ScreenResY
        function ScreenResYValueChanged(app, event)
            value = app.ScreenResY.Value;
            app.Display.Basic.Rect(4) = value;
            RecalculatePixPerDeg(app);
        end

        % Value changed function: Stereomode
        function StereomodeValueChanged(app, event)
            value = app.Stereomode.Value;
            app.Display.StereoString = value;
            StereoModeIndx = find(~cellfun(@isempty, strfind(app.Stereomode.Items, value)));
            app.Display.Stereomode = StereoModeIndx-1;
        end

        % Value changed function: PhotodiodePos
        function PhotodiodePosValueChanged(app, event)
            value = app.PhotodiodePos.Value;
            app.Display.PD.Position = value;
            UpdatePreview(app);
        end

        % Value changed function: PhotodiodeScreen
        function PhotodiodeScreenValueChanged(app, event)
            value = app.PhotodiodeScreen.Value;
            app.Display.PD.Screen = value;
            app.Display.PD.ScreenIndx = find(~cellfun(@isempty, strfind(app.PhotodiodeScreen.Items, value)));
            UpdatePreview(app);
        end

        % Value changed function: PhotodiodeSize
        function PhotodiodeSizeValueChanged(app, event)
            value = app.PhotodiodeSize.Value;
            app.Display.PD.Diameter = value;
            UpdatePreview(app);
        end

        % Button pushed function: PhotodiodeRGB
        function PhotodiodeRGBButtonPushed(app, event)
            c = uisetcolor(app.Display.PD.Color{1}, 'Photodiode OFF color');
            if numel(c) == 3
                app.Display.PD.Color{1} = c;
                app.PhotodiodeRGB.BackgroundColor = c;
            end
            UpdatePreview(app);
        end

        % Button pushed function: PhotodiodeOn
        function PhotodiodeOnButtonPushed(app, event)
            c = uisetcolor(app.Display.PD.Color{2}, 'Photodiode ON color');
            if numel(c) == 3
                app.Display.PD.Color{2} = c;
                app.PhotodiodeOn.BackgroundColor = c;
            end
            UpdatePreview(app);
        end

        % Button pushed function: Button_GridRGB
        function Button_GridRGBPushed(app, event)
            c = uisetcolor(app.Display.Grid.Color, 'Grid color');
            if numel(c) == 3
                app.Display.Grid.Color = c;
                app.Button_GridRGB.BackgroundColor = c;
                set(app.Preview.GridH, 'edgecolor', c);
            end
            UpdatePreview(app);
        end

        % Button pushed function: Button_EyePosRGB
        function Button_EyePosRGBPushed(app, event)
            c = uisetcolor(app.Display.Eye.Color(2,:), 'Gaze position color');
            if numel(c) == 3
                app.Display.Eye.Color(2,:) = c;
                app.Button_EyePosRGB.BackgroundColor = c;
            end
            UpdatePreview(app);
        end

        % Button pushed function: Button_FixWinRGB
        function Button_FixWinRGBPushed(app, event)
            c = uisetcolor(app.Display.GazeWin.Color(2,:), 'Fixation window color');
            if numel(c) == 3
                app.Display.GazeWin.Color(2,:) = c;
                app.Button_FixWinRGB.BackgroundColor = c;
            end
            UpdatePreview(app);
        end

        % Button pushed function: Button_BackgroundRGB
        function Button_BackgroundRGBPushed(app, event)
            c = uisetcolor(app.Display.Screen.BackgroundColor, 'Display background color');
            if numel(c) == 3
                app.Display.Screen.BackgroundColor = c;
                app.Button_BackgroundRGB.BackgroundColor = c;
            end
            UpdatePreview(app);
        end

        % Callback function
        function DursamplesEditFieldValueChanged(app, event)
            value = app.DursamplesEditField.Value;
            app.Display.Eye.Samples = value;
        end

        % Value changed function: SpacingSlider
        function SpacingSliderValueChanged(app, event)
            value = app.SpacingSlider.Value;
            app.Display.Grid.Spacing = value;
            UpdatePreview(app);
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            app.Display.Grid.On = value;
            UpdatePreview(app);
        end

        % Value changed function: EyeTraceOn
        function EyeTraceOnValueChanged(app, event)
            value = app.EyeTraceOn.Value;
            app.Display.Eye.On = value;
            UpdatePreview(app);
        end

        % Value changed function: GazeWinCheckBox
        function GazeWinCheckBoxValueChanged(app, event)
            value = app.GazeWinCheckBox.Value;
            app.Display.GazeWin.On = value;
            UpdatePreview(app);
        end

        % Value changed function: GridLinewidthpxSpinner
        function GridLinewidthpxSpinnerValueChanged(app, event)
            value = app.GridLinewidthpxSpinner.Value;
            app.Display.Grid.Width = value;
            UpdatePreview(app);
        end

        % Value changed function: GridFormatDropDown
        function GridFormatDropDownValueChanged(app, event)
            value = app.GridFormatDropDown.Value;
            app.Display.Grid.Format = value;
            UpdatePreview(app);
        end

        % Value changed function: CheckBox_FixMarkerOn
        function CheckBox_FixMarkerOnValueChanged(app, event)
            value = app.CheckBox_FixMarkerOn.Value;
            app.Display.Fix.On = value;
            UpdatePreview(app);
        end

        % Button pushed function: Fix_RGB
        function Fix_RGBPushed(app, event)
            c = uisetcolor(app.Display.Fix.Color, 'Fixation markercolor');
            if numel(c) == 3
                app.Display.Fix.Color = c;
                app.Fix_RGB.BackgroundColor = c;
            end
            UpdatePreview(app);
        end

        % Value changed function: Fix_Linewidth
        function Fix_LinewidthValueChanged(app, event)
            value = app.Fix_Linewidth.Value;
            app.Display.Fix.Diameter = value;
            UpdatePreview(app);
        end

        % Value changed function: FormatDropDown_2
        function FormatDropDown_2ValueChanged(app, event)
            value = app.FormatDropDown_2.Value;
            app.Display.Fix.Format = value;
            UpdatePreview(app);
        end

        % Value changed function: Fix_Diameter
        function Fix_DiameterValueChanged(app, event)
            value = app.Fix_Diameter.Value;
            app.Display.Fix.Diameter = value;
            UpdatePreview(app);
        end

        % Value changed function: GazeLinewidth
        function GazeLinewidthValueChanged(app, event)
            value = app.GazeLinewidth.Value;
            app.Display.GazeWin.Width = value;
            UpdatePreview(app);
        end

        % Value changed function: GazeFormat
        function GazeFormatValueChanged(app, event)
            value = app.GazeFormat.Value;
            app.Display.GazeWin.Format = value;
            if strcmp(value, 'Rectangle')
                app.GazeWin_Diameter.Visible    = 0;
                app.RadiusLabel.Text            = 'Dimensions (°)';
                app.GazeWinDimsX.Visible        = 1;
                app.GazeWinDimsY.Visible        = 1;
            else
                app.GazeWin_Diameter.Visible    = 1;
                app.RadiusLabel.Text            = 'Radius (°)';
                app.GazeWinDimsX.Visible        = 0;
                app.GazeWinDimsY.Visible        = 0;
            end
            UpdatePreview(app);
        end

        % Value changed function: GazeWin_Diameter
        function GazeWin_DiameterValueChanged(app, event)
            value = app.GazeWin_Diameter.Value;
            app.Display.GazeWin.Diameter = value;
            UpdatePreview(app);
        end

        % Value changed function: GazeWinDimsX
        function GazeWinDimsXValueChanged(app, event)
            value = app.GazeWinDimsX.Value;
            app.Display.GazeWin.Dimensions(1) = value;
            UpdatePreview(app);
        end

        % Value changed function: GazeWinDimsY
        function GazeWinDimsYValueChanged(app, event)
            value = app.GazeWinDimsY.Value;
            app.Display.GazeWin.Dimensions(2) = value;
            UpdatePreview(app);
        end

        % Value changed function: EyeTrace_Duration
        function EyeTrace_DurationValueChanged(app, event)
            value = app.EyeTrace_Duration.Value;
            app.Display.Eye.TraceDuration = value;
            UpdatePreview(app);
        end

        % Value changed function: EyeTrace_Format
        function EyeTrace_FormatValueChanged(app, event)
            value = app.EyeTrace_Format.Value;
            app.Display.Eye.Format = value;
            UpdatePreview(app);
        end

        % Value changed function: EyeTrace_Size
        function EyeTrace_SizeValueChanged(app, event)
            value = app.EyeTrace_Size.Value;
            app.Display.Eye.Diameter = value;
            UpdatePreview(app);
        end

        % Value changed function: EyeTrace_Alpha
        function EyeTrace_AlphaValueChanged(app, event)
            value = app.EyeTrace_Alpha.Value;
            app.Display.Eye.Alpha = value;
            UpdatePreview(app);
        end

        % Button pushed function: Button_Save
        function Button_SavePushed(app, event)
            ParamsFile  = app.NTBapp.Params.File;
            selection   = uiconfirm(app.NTBDisplaySettingsUIFigure, sprintf('Save Display settings to parameter file %s?', ParamsFile), 'Save', 'Options',{'Save','Cancel'}, 'Icon', 'Save.png');
            if strcmp(selection, 'Save')
                Display     = app.Display;
                save(ParamsFile, 'Display','-append');
                uiconfirm(app.NTBDisplaySettingsUIFigure, sprintf('Current display parameters were written to %s.', ParamsFile), 'Display parameters saved', 'Icon', 'success');
            end
        end

        % Button pushed function: Button_Load
        function Button_LoadPushed(app, event)
            selection   = uiconfirm(app.NTBDisplaySettingsUIFigure, 'Load display settings from a different parameter file?', 'Load parameters', 'Options',{'Load','Cancel'}, 'Icon', 'Transfer.png');
            if strcmp(selection, 'Load')
                [file, path] = uigetfile('NTB*.mat', 'Select params file');
                ParamsFile = fullfile(path, file);
                TempParams = load(ParamsFile);
                app.Display = TempParams.Display;
                UpdatePreview(app);
                UpdateValues(app);
            end
        end

        % Button pushed function: Button_Docs
        function Button_DocsPushed(app, event)
            web(app.HelpURLs.DisplaySettings, '-browser')
        end

        % Value changed function: MeridiansCheckBox
        function MeridiansCheckBoxValueChanged(app, event)
            value = app.MeridiansCheckBox.Value;
            app.Display.Grid.MeridiansOn = value;
            UpdatePreview(app);
        end

        % Value changed function: SelectedXScreen
        function SelectedXScreenValueChanged(app, event)
            value = app.SelectedXScreen.Value;
            app.Display.Screen.ID  = str2double(value);
            app.Display.Screen.XScreenRect  = Screen('rect', app.Display.Screen.ID);
            app.Display.Screen.RefreshRate  = Screen('NominalFramerate', app.Display.Screen.ID);
            app.Totalpixels.Text            = sprintf('%d x %d', app.Display.Screen.XScreenRect([3,4]));
            app.Refreshrate.Text            = sprintf('%d Hz', app.Display.Screen.RefreshRate);
            app.HalfWidthPixels.Text        = sprintf('%d', app.Display.Screen.XScreenRect(3)/2);
            if app.Display.Screen.XScreenRect(3) < app.Display.Basic.Rect(3)
                app.ScreenResX.BackgroundColor = [1,0,0];
            else
                app.ScreenResX.BackgroundColor = [1,1,1];
            end
            if app.Display.Screen.XScreenRect(4) < app.Display.Basic.Rect(4)
                app.ScreenResY.BackgroundColor = [1,0,0];
            else
                app.ScreenResX.BackgroundColor = [1,1,1];
            end
        end

        % Value changed function: DropDown_ExpCopyPos
        function DropDown_ExpCopyPosValueChanged(app, event)
            value = app.DropDown_ExpCopyPos.Value;
            app.Display.Screen.ExpDispPos = value;
            UpdatePreview(app);
        end

        % Value changed function: DropDown_WinScale
        function DropDown_WinScaleValueChanged(app, event)
            value = app.DropDown_WinScale.Value;
            app.Display.Basic.WinScale = value;
        end

        % Value changed function: FontnameDropDown
        function FontnameDropDownValueChanged(app, event)
            value = app.FontnameDropDown.Value;
            app.Display.Info.FontName = value;
            Screen('Preference', 'DefaultFontName', value);
            UpdateDisplayInfo(app);
        end

        % Value changed function: FontsizeSpinner
        function FontsizeSpinnerValueChanged(app, event)
            value = app.FontsizeSpinner.Value;
            app.Display.Info.FontSize = value;
            Screen('Preference', 'DefaultFontSize', value);
            UpdateDisplayInfo(app);
        end

        % Value changed function: PTBSkipsynctestsCheckBox
        function PTBSkipsynctestsCheckBoxValueChanged(app, event)
            value = app.PTBSkipsynctestsCheckBox.Value;
            Screen('Preference', 'SkipSyncTests', double(value));
        end

        % Value changed function: PTBSuppressallwarningsCheckBox
        function PTBSuppressallwarningsCheckBoxValueChanged(app, event)
            value = app.PTBSuppressallwarningsCheckBox.Value;
            Screen('Preference', 'SuppressAllWarnings', double(value));
        end

        % Value changed function: PsychDefaultSetupDropDown
        function PsychDefaultSetupDropDownValueChanged(app, event)
            value = app.PsychDefaultSetupDropDown.Value;
            items = app.PsychDefaultSetupDropDown.Items;
            index = find(contains(items, value));
            PsychDefaultSetup(index);
        end

        % Value changed function: VisualdebuglevelDropDown
        function VisualdebuglevelDropDownValueChanged(app, event)
            value = app.VisualdebuglevelDropDown.Value;
            items = app.VisualdebuglevelDropDown.Items;
            index = find(contains(items, value));
            Screen('Preference', 'VisualDebugLevel', index);
        end

        % Value changed function: VerbosityDropDown
        function VerbosityDropDownValueChanged(app, event)
            value = app.VerbosityDropDown.Value;
            items = app.VerbosityDropDown.Items;
            index = find(contains(items, value));
            Screen('Preference', 'Verbosity', index);
        end

        % Value changed function: InfoBkg_Toggle
        function InfoBkg_ToggleValueChanged(app, event)
            value = app.InfoBkg_Toggle.Value;
            app.Display.Info.BkgOn = value;
            UpdateDisplayInfo(app);
        end

        % Button pushed function: InfoBkg_RGB
        function InfoBkg_RGBButtonPushed(app, event)
            c = uisetcolor(app.Display.Info.BkgRGB, 'Info panel background color');
            if numel(c) == 3
                app.Display.Info.BkgRGB = c;
                app.InfoBkg_RGB.BackgroundColor = c;
            end
            UpdateDisplayInfo(app);
        end

        % Value changed function: InfoBkg_Alpha
        function InfoBkg_AlphaValueChanged(app, event)
            value = app.InfoBkg_Alpha.Value;
            app.Display.Info.BkgAlpha = value;
            UpdateDisplayInfo(app);
        end

        % Button pushed function: FontRGB
        function FontRGBButtonPushed(app, event)
            c = uisetcolor(app.Display.Info.FontRGB, 'Info panel text color');
            if numel(c) == 3
                app.Display.Info.FontRGB = c;
                app.FontRGB.BackgroundColor = c;
            end
            UpdateDisplayInfo(app);
        end

        % Value changed function: ProgressDropDown
        function ProgressDropDownValueChanged(app, event)
            value = app.ProgressDropDown.Value;
            app.Display.Info.ProgressFormat = value;
            UpdateDisplayInfo(app);
        end

        % Value changed function: EpochDropDown
        function EpochDropDownValueChanged(app, event)
            value = app.EpochDropDown.Value;
            app.Display.Info.EpochFormat = value;
            UpdateDisplayInfo(app);
        end

        % Value changed function: TimerCheckBox
        function TimerCheckBoxValueChanged(app, event)
            value = app.TimerCheckBox.Value;
            app.Display.Info.ToggleTimer = value;
            UpdateDisplayInfo(app);
        end

        % Value changed function: EpochcounterCheckBox
        function EpochcounterCheckBoxValueChanged(app, event)
            value = app.EpochcounterCheckBox.Value;
            app.Display.Info.ToggleEpoch    = value;
            UpdateDisplayInfo(app);
        end

        % Value changed function: RewardcounterCheckBox
        function RewardcounterCheckBoxValueChanged(app, event)
            value = app.RewardcounterCheckBox.Value;
            app.Display.Info.ToggleReward = value;
            UpdateDisplayInfo(app);
        end

        % Value changed function: FixationaccuracyCheckBox
        function FixationaccuracyCheckBoxValueChanged(app, event)
            value = app.FixationaccuracyCheckBox.Value;
            app.Display.Info.ToggleFixation = value;
            UpdateDisplayInfo(app);
        end

        % Value changed function: ResponseaccuracyCheckBox
        function ResponseaccuracyCheckBoxValueChanged(app, event)
            value = app.ResponseaccuracyCheckBox.Value;
            app.Display.Info.ToggleResponse = value;
            UpdateDisplayInfo(app);
        end

        % Value changed function: InfoPanelPos
        function InfoPanelPosValueChanged(app, event)
            value = app.InfoPanelPos.Value;
            app.Display.Info.PanelPosStr = value;
            UpdateDisplayInfo(app);
        end

        % Button pushed function: Button_Close
        function Button_ClosePushed(app, event)
            msg = sprintf('Warning: Discard parameter edits made in this window?');
            uialert(app.NTBDisplaySettingsUIFigure, msg, 'Warning','Icon','warning');
            app.NTBapp.Button_Display.BackgroundColor = app.NTBapp.OffOnColors{1};
            delete(app.NTBDisplaySettingsUIFigure);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NTBDisplaySettingsUIFigure = uifigure('Visible', 'off');
            app.NTBDisplaySettingsUIFigure.Name = 'NTB Display Settings';
            app.NTBDisplaySettingsUIFigure.Icon = 'Display.png';
            app.NTBDisplaySettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @NTBDisplaySettingsUIFigureCloseRequest, true);
            app.NTBDisplaySettingsUIFigure.Tag = 'NTB_DispSettings';
            app.SystemProfilePanel = uipanel(app.NTBDisplaySettingsUIFigure);
            app.SystemProfilePanel.Title = 'System Profile';
            app.TabGroup3 = uitabgroup(app.SystemProfilePanel);
            app.SystemTab = uitab(app.TabGroup3);
            app.SystemTab.Title = 'System';
            app.ComputerIDLabel = uilabel(app.SystemTab);
            app.ComputerIDLabel.Text = 'Computer ID:';
            app.OperatingSystemLabel = uilabel(app.SystemTab);
            app.OperatingSystemLabel.Text = 'Operating System:';
            app.MATLABversionLabel = uilabel(app.SystemTab);
            app.MATLABversionLabel.Text = 'MATLAB version:';
            app.PsychToolboxversionLabel = uilabel(app.SystemTab);
            app.PsychToolboxversionLabel.Text = 'PsychToolbox version:';
            app.GraphicscardLabel = uilabel(app.SystemTab);
            app.GraphicscardLabel.Text = 'Graphics card:';
            app.OpenGLversionLabel = uilabel(app.SystemTab);
            app.OpenGLversionLabel.Text = 'Open GL version:';
            app.OpenGLversion = uilabel(app.SystemTab);
            app.OpenGLversion.Text = '';
            app.Graphicscard = uilabel(app.SystemTab);
            app.Graphicscard.Text = '';
            app.PsychToolboxversion = uilabel(app.SystemTab);
            app.PsychToolboxversion.Text = '';
            app.MATLABversion = uilabel(app.SystemTab);
            app.MATLABversion.Text = '';
            app.OperatingSystem = uilabel(app.SystemTab);
            app.OperatingSystem.Text = '';
            app.ComputerID = uilabel(app.SystemTab);
            app.ComputerID.Text = '';
            app.XScreensTab = uitab(app.TabGroup3);
            app.XScreensTab.Title = 'X-Screens';
            app.NoXscreensLabel = uilabel(app.XScreensTab);
            app.NoXscreensLabel.Text = 'No. X screens:';
            app.TotalresolutionpixelsLabel = uilabel(app.XScreensTab);
            app.TotalresolutionpixelsLabel.Text = 'Total resolution (pixels):';
            app.RefreshrateHzLabel = uilabel(app.XScreensTab);
            app.RefreshrateHzLabel.Text = 'Refresh rate (Hz):';
            app.Refreshrate = uilabel(app.XScreensTab);
            app.Refreshrate.Tooltip = {'System-detected refresh rate (Hz) of selected X-Screen.'};
            app.Refreshrate.Text = '';
            app.Totalpixels = uilabel(app.XScreensTab);
            app.Totalpixels.Tooltip = {'Total size (pixels) of selected X-Screen.'};
            app.Totalpixels.Text = '';
            app.NoXscreens = uilabel(app.XScreensTab);
            app.NoXscreens.Tooltip = {'Number of X-Screen windows detected by PTB'};
            app.NoXscreens.Text = '';
            app.SelectedXScreenLabel = uilabel(app.XScreensTab);
            app.SelectedXScreenLabel.Text = 'Selected X-Screen:';
            app.SelectedXScreen = uidropdown(app.XScreensTab);
            app.SelectedXScreen.Items = {'0', '1'};
            app.SelectedXScreen.Editable = 'on';
            app.SelectedXScreen.ValueChangedFcn = createCallbackFcn(app, @SelectedXScreenValueChanged, true);
            app.SelectedXScreen.Tooltip = {'Select which X-Screen to open PTB window in.'};
            app.SelectedXScreen.Value = '0';
            app.HalfwidthpixelsLabel = uilabel(app.XScreensTab);
            app.HalfwidthpixelsLabel.Text = 'Half-width (pixels):';
            app.HalfWidthPixels = uilabel(app.XScreensTab);
            app.HalfWidthPixels.Tooltip = {'Half-width (pixels) of selected X-Screen.'};
            app.HalfWidthPixels.Text = '';
            app.ExperimentercopyLabel = uilabel(app.XScreensTab);
            app.ExperimentercopyLabel.Text = 'Experimenter copy:';
            app.DropDown_ExpCopyPos = uidropdown(app.XScreensTab);
            app.DropDown_ExpCopyPos.Items = {'Left', 'Right', 'None'};
            app.DropDown_ExpCopyPos.ValueChangedFcn = createCallbackFcn(app, @DropDown_ExpCopyPosValueChanged, true);
            app.DropDown_ExpCopyPos.Tooltip = {'Which side of the extended X-screen to draw the experimenter''s copy of the stimuli to.'};
            app.DropDown_ExpCopyPos.Value = 'Left';
            app.PTBTab = uitab(app.TabGroup3);
            app.PTBTab.Title = 'PTB';
            app.PTBSkipsynctestsCheckBox = uicheckbox(app.PTBTab);
            app.PTBSkipsynctestsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PTBSkipsynctestsCheckBoxValueChanged, true);
            app.PTBSkipsynctestsCheckBox.Text = 'PTB - Skip sync tests?';
            app.PTBSuppressallwarningsCheckBox = uicheckbox(app.PTBTab);
            app.PTBSuppressallwarningsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PTBSuppressallwarningsCheckBoxValueChanged, true);
            app.PTBSuppressallwarningsCheckBox.Text = 'PTB - Suppress all warnings?';
            app.VisualdebuglevelDropDownLabel = uilabel(app.PTBTab);
            app.VisualdebuglevelDropDownLabel.Text = 'Visual debug level';
            app.VisualdebuglevelDropDown = uidropdown(app.PTBTab);
            app.VisualdebuglevelDropDown.Items = {'1 - Errors only', '2 - Moderate', '3 - Intermediate', '4 - Most thorough '};
            app.VisualdebuglevelDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualdebuglevelDropDownValueChanged, true);
            app.VisualdebuglevelDropDown.Value = '3 - Intermediate';
            app.PsychDefaultSetupDropDownLabel = uilabel(app.PTBTab);
            app.PsychDefaultSetupDropDownLabel.Text = 'Psych Default Setup';
            app.PsychDefaultSetupDropDown = uidropdown(app.PTBTab);
            app.PsychDefaultSetupDropDown.Items = {'0 - AssertOpenGL', '1 - UnifyKeyNames', '2 - Set ColorRange'};
            app.PsychDefaultSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @PsychDefaultSetupDropDownValueChanged, true);
            app.PsychDefaultSetupDropDown.Value = '1 - UnifyKeyNames';
            app.VerbosityDropDownLabel = uilabel(app.PTBTab);
            app.VerbosityDropDownLabel.Text = 'Verbosity';
            app.VerbosityDropDown = uidropdown(app.PTBTab);
            app.VerbosityDropDown.Items = {'0 - Disable all output', '1 - Critical errors only', '2 - Output warnings', '3 - Startup information', '4 - Optimization hints', '5 - Maximum verbosity', '', ''};
            app.VerbosityDropDown.ValueChangedFcn = createCallbackFcn(app, @VerbosityDropDownValueChanged, true);
            app.VerbosityDropDown.Value = '3 - Startup information';
            app.ViewingGeometryPanel = uipanel(app.NTBDisplaySettingsUIFigure);
            app.ViewingGeometryPanel.Title = 'Viewing Geometry';
            app.TabGroup2 = uitabgroup(app.ViewingGeometryPanel);
            app.BasicTab = uitab(app.TabGroup2);
            app.BasicTab.Title = 'Basic';
            app.ViewingdistancecmLabel = uilabel(app.BasicTab);
            app.ViewingdistancecmLabel.Text = 'Viewing distance (cm):';
            app.ViewingDistance = uieditfield(app.BasicTab, 'numeric');
            app.ViewingDistance.ValueChangedFcn = createCallbackFcn(app, @ViewingDistanceValueChanged, true);
            app.ViewingDistance.Tooltip = {'Set viewing distance of subject''s eye from the subject''s display (centimeters)'};
            app.ScreendimensionscmLabel = uilabel(app.BasicTab);
            app.ScreendimensionscmLabel.Text = 'Screen dimensions (cm):';
            app.ScreenDimX = uieditfield(app.BasicTab, 'numeric');
            app.ScreenDimX.ValueChangedFcn = createCallbackFcn(app, @ScreenDimXValueChanged, true);
            app.ScreenDimX.Tooltip = {'Set the physical width of the subject''s display screen (centimeters)'};
            app.ScreenDimY = uieditfield(app.BasicTab, 'numeric');
            app.ScreenDimY.ValueChangedFcn = createCallbackFcn(app, @ScreenDimYValueChanged, true);
            app.ScreenDimY.Tooltip = {'Set the physical height of the subject''s display screen (centimeters)'};
            app.ScreenresolutionpixelsLabel = uilabel(app.BasicTab);
            app.ScreenresolutionpixelsLabel.Text = 'Screen resolution (pixels):';
            app.ScreenResX = uieditfield(app.BasicTab, 'numeric');
            app.ScreenResX.ValueChangedFcn = createCallbackFcn(app, @ScreenResXValueChanged, true);
            app.ScreenResX.Tooltip = {'Set the X-dimension resolution of the subject''s physical display (pixels)'};
            app.ScreenResY = uieditfield(app.BasicTab, 'numeric');
            app.ScreenResY.ValueChangedFcn = createCallbackFcn(app, @ScreenResYValueChanged, true);
            app.ScreenResY.Tooltip = {'Set the Y-dimension resolution of the subject''s physical display (pixels)'};
            app.PixelsdegreeXYLabel = uilabel(app.BasicTab);
            app.PixelsdegreeXYLabel.Text = 'Pixels/degree (X, Y):';
            app.PixPerDegX = uieditfield(app.BasicTab, 'numeric');
            app.PixPerDegX.Enable = 'off';
            app.PixPerDegX.Tooltip = {'Calculates the number of pixels per degree of visual angle (X-dimension)'};
            app.PixPerDegY = uieditfield(app.BasicTab, 'numeric');
            app.PixPerDegY.Enable = 'off';
            app.PixPerDegY.Tooltip = {'Calculates the number of pixels per degree of visual angle (Y-dimension)'};
            app.PTBWindowscaleLabel = uilabel(app.BasicTab);
            app.PTBWindowscaleLabel.Text = 'PTB Window scale:';
            app.DropDown_WinScale = uidropdown(app.BasicTab);
            app.DropDown_WinScale.Items = {'Full-screen', 'Half-size', 'Half-width'};
            app.DropDown_WinScale.ValueChangedFcn = createCallbackFcn(app, @DropDown_WinScaleValueChanged, true);
            app.DropDown_WinScale.Value = 'Full-screen';
            app.StereoscopicTab = uitab(app.TabGroup2);
            app.StereoscopicTab.Title = 'Stereoscopic';
            app.IPD = uieditfield(app.StereoscopicTab, 'numeric');
            app.IPD.ValueChangedFcn = createCallbackFcn(app, @IPDValueChanged, true);
            app.Stereomode = uidropdown(app.StereoscopicTab);
            app.Stereomode.Items = {'Monocular', 'Shutter glasses', 'Split-screen (top-bottom)', 'Split-screen (bottom-top)', 'Split-screen (left-right)', 'Split-screen (right-left)', 'Anaglyph (red-green)', 'Anaglyph (green-red)', 'Anaglyph (red-blue)', 'Anaglyph (blue-red)', 'Free fusion OSX', 'PTB shutter glasses', 'Interleaved row', 'Interleaved column', 'Compressed HDMI'};
            app.Stereomode.ValueChangedFcn = createCallbackFcn(app, @StereomodeValueChanged, true);
            app.Stereomode.Value = 'Monocular';
            app.DropDownLabel = uilabel(app.StereoscopicTab);
            app.DropDownLabel.Text = '';
            app.StereoDisplayFormat = uidropdown(app.StereoscopicTab);
            app.StereoDisplayFormat.Items = {'2D', 'side-by-side', 'SBS squeezed'};
            app.StereoDisplayFormat.Value = 'SBS squeezed';
            app.ExperimenterdisplayFormat = uidropdown(app.StereoscopicTab);
            app.ExperimenterdisplayFormat.Items = {'Monocular (left)', 'Monocular (right)', 'SBS copy', 'Anaglyph', ''};
            app.ExperimenterdisplayFormat.Value = 'Monocular (left)';
            app.InterpupillarydistancecmLabel = uilabel(app.StereoscopicTab);
            app.InterpupillarydistancecmLabel.Text = 'Interpupillary distance (cm):';
            app.PTBStereomodeLabel = uilabel(app.StereoscopicTab);
            app.PTBStereomodeLabel.Text = 'PTB Stereomode:';
            app.DdisplayformatLabel = uilabel(app.StereoscopicTab);
            app.DdisplayformatLabel.Text = '3D display format:';
            app.ExperimenterdisplayLabel = uilabel(app.StereoscopicTab);
            app.ExperimenterdisplayLabel.Text = 'Experimenter display:';
            app.OpenGLTab = uitab(app.TabGroup2);
            app.OpenGLTab.Title = 'OpenGL';
            app.PreviewPanel = uipanel(app.NTBDisplaySettingsUIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.UIAxes_L = uiaxes(app.PreviewPanel);
            app.UIAxes_L.XTick = [];
            app.UIAxes_L.YTick = [];
            app.UIAxes_R = uiaxes(app.PreviewPanel);
            app.UIAxes_R.XTick = [];
            app.UIAxes_R.YTick = [];
            app.Button_BackgroundRGB = uibutton(app.PreviewPanel, 'push');
            app.Button_BackgroundRGB.ButtonPushedFcn = createCallbackFcn(app, @Button_BackgroundRGBPushed, true);
            app.Button_BackgroundRGB.Tooltip = {'Set the default background color.'};
            app.Button_BackgroundRGB.Text = '';
            app.TabGroup = uitabgroup(app.PreviewPanel);
            app.GridTab = uitab(app.TabGroup);
            app.GridTab.Title = 'Grid';
            app.GridCheckBox = uicheckbox(app.GridTab);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.GridCheckBox.Value = true;
            app.Button_GridRGB = uibutton(app.GridTab, 'push');
            app.Button_GridRGB.ButtonPushedFcn = createCallbackFcn(app, @Button_GridRGBPushed, true);
            app.Button_GridRGB.Text = '';
            app.SpacingLabel = uilabel(app.GridTab);
            app.SpacingLabel.Text = 'Spacing (°)';
            app.SpacingSlider = uislider(app.GridTab);
            app.SpacingSlider.Limits = [1 15];
            app.SpacingSlider.MajorTicks = [0 2 4 6 8 10 12 14];
            app.SpacingSlider.ValueChangedFcn = createCallbackFcn(app, @SpacingSliderValueChanged, true);
            app.SpacingSlider.MinorTicks = [1 3 5 7 9 11];
            app.SpacingSlider.Value = 1;
            app.GridformatLabel = uilabel(app.GridTab);
            app.GridformatLabel.Text = 'Grid format';
            app.GridFormatDropDown = uidropdown(app.GridTab);
            app.GridFormatDropDown.Items = {'Radial', 'Cross-hair', 'Grid'};
            app.GridFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @GridFormatDropDownValueChanged, true);
            app.GridFormatDropDown.Value = 'Radial';
            app.VisibleLabel = uilabel(app.GridTab);
            app.VisibleLabel.Text = 'Visible';
            app.ColorLabel = uilabel(app.GridTab);
            app.ColorLabel.Text = 'Color';
            app.LinewidthpxSpinnerLabel = uilabel(app.GridTab);
            app.LinewidthpxSpinnerLabel.Text = 'Line width (px)';
            app.GridLinewidthpxSpinner = uispinner(app.GridTab);
            app.GridLinewidthpxSpinner.Limits = [1 Inf];
            app.GridLinewidthpxSpinner.RoundFractionalValues = 'on';
            app.GridLinewidthpxSpinner.ValueChangedFcn = createCallbackFcn(app, @GridLinewidthpxSpinnerValueChanged, true);
            app.GridLinewidthpxSpinner.Value = 2;
            app.MeridiansCheckBox = uicheckbox(app.GridTab);
            app.MeridiansCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeridiansCheckBoxValueChanged, true);
            app.MeridiansCheckBox.Text = 'Meridians';
            app.MeridiansCheckBox.Value = true;
            app.FixationTab = uitab(app.TabGroup);
            app.FixationTab.Title = 'Fixation';
            app.VisibleLabel_2 = uilabel(app.FixationTab);
            app.VisibleLabel_2.Text = 'Visible';
            app.CheckBox_FixMarkerOn = uicheckbox(app.FixationTab);
            app.CheckBox_FixMarkerOn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_FixMarkerOnValueChanged, true);
            app.CheckBox_FixMarkerOn.Text = '';
            app.CheckBox_FixMarkerOn.Value = true;
            app.Fix_RGB = uibutton(app.FixationTab, 'push');
            app.Fix_RGB.ButtonPushedFcn = createCallbackFcn(app, @Fix_RGBPushed, true);
            app.Fix_RGB.Text = '';
            app.ColorLabel_2 = uilabel(app.FixationTab);
            app.ColorLabel_2.Text = 'Color';
            app.FormatDropDownLabel_2 = uilabel(app.FixationTab);
            app.FormatDropDownLabel_2.Text = 'Marker format';
            app.FormatDropDown_2 = uidropdown(app.FixationTab);
            app.FormatDropDown_2.Items = {'Cross', 'Dot', 'Square', 'Cross-hair'};
            app.FormatDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FormatDropDown_2ValueChanged, true);
            app.FormatDropDown_2.Value = 'Cross';
            app.LinewidthpxSpinnerLabel_2 = uilabel(app.FixationTab);
            app.LinewidthpxSpinnerLabel_2.Text = 'Line width (px)';
            app.Fix_Linewidth = uispinner(app.FixationTab);
            app.Fix_Linewidth.ValueChangedFcn = createCallbackFcn(app, @Fix_LinewidthValueChanged, true);
            app.DiameterLabel = uilabel(app.FixationTab);
            app.DiameterLabel.Text = 'Diameter (°)';
            app.Fix_Diameter = uislider(app.FixationTab);
            app.Fix_Diameter.Limits = [0 4];
            app.Fix_Diameter.MajorTicks = [0 1 2 3 4];
            app.Fix_Diameter.ValueChangedFcn = createCallbackFcn(app, @Fix_DiameterValueChanged, true);
            app.Fix_Diameter.MinorTicks = [0.5 1.5 2.5 3.5 4.5];
            app.Fix_Diameter.Value = 1;
            app.GazewindowTab = uitab(app.TabGroup);
            app.GazewindowTab.Title = 'Gaze window';
            app.GazeWinCheckBox = uicheckbox(app.GazewindowTab);
            app.GazeWinCheckBox.ValueChangedFcn = createCallbackFcn(app, @GazeWinCheckBoxValueChanged, true);
            app.GazeWinCheckBox.Text = '';
            app.GazeWinCheckBox.Value = true;
            app.Button_FixWinRGB = uibutton(app.GazewindowTab, 'push');
            app.Button_FixWinRGB.ButtonPushedFcn = createCallbackFcn(app, @Button_FixWinRGBPushed, true);
            app.Button_FixWinRGB.Text = '';
            app.VisibleLabel_3 = uilabel(app.GazewindowTab);
            app.VisibleLabel_3.Text = 'Visible';
            app.ColorLabel_3 = uilabel(app.GazewindowTab);
            app.ColorLabel_3.Text = 'Color';
            app.FormatDropDownLabel_3 = uilabel(app.GazewindowTab);
            app.FormatDropDownLabel_3.Text = 'Window shape';
            app.GazeFormat = uidropdown(app.GazewindowTab);
            app.GazeFormat.Items = {'Circle', 'Square', 'Rectangle'};
            app.GazeFormat.ValueChangedFcn = createCallbackFcn(app, @GazeFormatValueChanged, true);
            app.GazeFormat.Value = 'Circle';
            app.LinewidthpxSpinnerLabel_3 = uilabel(app.GazewindowTab);
            app.LinewidthpxSpinnerLabel_3.Text = 'Line width (px)';
            app.GazeLinewidth = uispinner(app.GazewindowTab);
            app.GazeLinewidth.ValueChangedFcn = createCallbackFcn(app, @GazeLinewidthValueChanged, true);
            app.RadiusLabel = uilabel(app.GazewindowTab);
            app.RadiusLabel.Text = 'Radius (°)';
            app.GazeWin_Diameter = uislider(app.GazewindowTab);
            app.GazeWin_Diameter.Limits = [0 40];
            app.GazeWin_Diameter.MajorTicks = [0 8 16 24 32 40];
            app.GazeWin_Diameter.ValueChangedFcn = createCallbackFcn(app, @GazeWin_DiameterValueChanged, true);
            app.GazeWin_Diameter.MinorTicks = [2 4 6 10 12 14 18 20 22 26 28 30 34 36 38];
            app.GazeWin_Diameter.Value = 1;
            app.GazeWinDimsX = uieditfield(app.GazewindowTab, 'numeric');
            app.GazeWinDimsX.ValueChangedFcn = createCallbackFcn(app, @GazeWinDimsXValueChanged, true);
            app.GazeWinDimsY = uieditfield(app.GazewindowTab, 'numeric');
            app.GazeWinDimsY.ValueChangedFcn = createCallbackFcn(app, @GazeWinDimsYValueChanged, true);
            app.PhotodiodeTab = uitab(app.TabGroup);
            app.PhotodiodeTab.Title = 'Photodiode';
            app.PhotodiodepositionLabel = uilabel(app.PhotodiodeTab);
            app.PhotodiodepositionLabel.Text = 'Photodiode position:';
            app.PhotodiodePos = uidropdown(app.PhotodiodeTab);
            app.PhotodiodePos.Items = {'Bottom left', 'Bottom right', 'Top left', 'Top right'};
            app.PhotodiodePos.ValueChangedFcn = createCallbackFcn(app, @PhotodiodePosValueChanged, true);
            app.PhotodiodePos.Value = 'Bottom left';
            app.PhotodiodeScreen = uidropdown(app.PhotodiodeTab);
            app.PhotodiodeScreen.Items = {'Subject display', 'Experimenter display', 'Both displays', 'None'};
            app.PhotodiodeScreen.ValueChangedFcn = createCallbackFcn(app, @PhotodiodeScreenValueChanged, true);
            app.PhotodiodeScreen.Value = 'Subject display';
            app.PhotodiodescreenLabel = uilabel(app.PhotodiodeTab);
            app.PhotodiodescreenLabel.Text = 'Photodiode screen:';
            app.PhotodiodesizepixelsLabel = uilabel(app.PhotodiodeTab);
            app.PhotodiodesizepixelsLabel.Text = 'Photodiode size (pixels):';
            app.PhotodiodecontrastsRGBLabel = uilabel(app.PhotodiodeTab);
            app.PhotodiodecontrastsRGBLabel.Text = 'Photodiode contrasts (RGB):';
            app.PhotodiodeSize = uieditfield(app.PhotodiodeTab, 'numeric');
            app.PhotodiodeSize.ValueChangedFcn = createCallbackFcn(app, @PhotodiodeSizeValueChanged, true);
            app.PhotodiodeRGB = uibutton(app.PhotodiodeTab, 'push');
            app.PhotodiodeRGB.ButtonPushedFcn = createCallbackFcn(app, @PhotodiodeRGBButtonPushed, true);
            app.PhotodiodeRGB.Text = 'OFF';
            app.PhotodiodeOn = uibutton(app.PhotodiodeTab, 'push');
            app.PhotodiodeOn.ButtonPushedFcn = createCallbackFcn(app, @PhotodiodeOnButtonPushed, true);
            app.PhotodiodeOn.Text = 'ON';
            app.EyetraceTab = uitab(app.TabGroup);
            app.EyetraceTab.Title = 'Eye trace';
            app.EyeTraceOn = uicheckbox(app.EyetraceTab);
            app.EyeTraceOn.ValueChangedFcn = createCallbackFcn(app, @EyeTraceOnValueChanged, true);
            app.EyeTraceOn.Text = '';
            app.EyeTraceOn.Value = true;
            app.Button_EyePosRGB = uibutton(app.EyetraceTab, 'push');
            app.Button_EyePosRGB.ButtonPushedFcn = createCallbackFcn(app, @Button_EyePosRGBPushed, true);
            app.Button_EyePosRGB.Text = '';
            app.VisibleLabel_4 = uilabel(app.EyetraceTab);
            app.VisibleLabel_4.Text = 'Visible';
            app.ColoralphaLabel = uilabel(app.EyetraceTab);
            app.ColoralphaLabel.Text = 'Color + alpha';
            app.MarkerDiameterLabel = uilabel(app.EyetraceTab);
            app.MarkerDiameterLabel.Text = 'Marker Diameter (°)';
            app.EyeTrace_Size = uislider(app.EyetraceTab);
            app.EyeTrace_Size.Limits = [0.5 8];
            app.EyeTrace_Size.MajorTicks = [1 2 3 4 5 6 7 8];
            app.EyeTrace_Size.ValueChangedFcn = createCallbackFcn(app, @EyeTrace_SizeValueChanged, true);
            app.EyeTrace_Size.MinorTicks = [1 3 5 7 9 11];
            app.EyeTrace_Size.Value = 1;
            app.FormatDropDownLabel_4 = uilabel(app.EyetraceTab);
            app.FormatDropDownLabel_4.Text = 'Marker style';
            app.EyeTrace_Format = uidropdown(app.EyetraceTab);
            app.EyeTrace_Format.Items = {'Dot', 'Trace', 'Map'};
            app.EyeTrace_Format.ValueChangedFcn = createCallbackFcn(app, @EyeTrace_FormatValueChanged, true);
            app.EyeTrace_Format.Value = 'Dot';
            app.LinewidthpxSpinnerLabel_4 = uilabel(app.EyetraceTab);
            app.LinewidthpxSpinnerLabel_4.Text = 'Duration (ms)';
            app.EyeTrace_Duration = uispinner(app.EyetraceTab);
            app.EyeTrace_Duration.ValueChangedFcn = createCallbackFcn(app, @EyeTrace_DurationValueChanged, true);
            app.EyeTrace_Alpha = uislider(app.EyetraceTab);
            app.EyeTrace_Alpha.Limits = [0 1];
            app.EyeTrace_Alpha.MajorTicks = [0 1];
            app.EyeTrace_Alpha.ValueChangedFcn = createCallbackFcn(app, @EyeTrace_AlphaValueChanged, true);
            app.EyeTrace_Alpha.MinorTicks = [0.2 0.4 0.6 0.8];
            app.EyeTrace_Alpha.Value = 1;
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.FontRGB = uibutton(app.InfoTab, 'push');
            app.FontRGB.ButtonPushedFcn = createCallbackFcn(app, @FontRGBButtonPushed, true);
            app.FontRGB.Text = '';
            app.FontcolorLabel = uilabel(app.InfoTab);
            app.FontcolorLabel.Text = 'Font color';
            app.TimerCheckBox = uicheckbox(app.InfoTab);
            app.TimerCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimerCheckBoxValueChanged, true);
            app.TimerCheckBox.Text = 'Timer';
            app.EpochcounterCheckBox = uicheckbox(app.InfoTab);
            app.EpochcounterCheckBox.ValueChangedFcn = createCallbackFcn(app, @EpochcounterCheckBoxValueChanged, true);
            app.EpochcounterCheckBox.Text = 'Epoch counter';
            app.RewardcounterCheckBox = uicheckbox(app.InfoTab);
            app.RewardcounterCheckBox.ValueChangedFcn = createCallbackFcn(app, @RewardcounterCheckBoxValueChanged, true);
            app.RewardcounterCheckBox.Text = 'Reward counter';
            app.FixationaccuracyCheckBox = uicheckbox(app.InfoTab);
            app.FixationaccuracyCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixationaccuracyCheckBoxValueChanged, true);
            app.FixationaccuracyCheckBox.Text = 'Fixation accuracy';
            app.ResponseaccuracyCheckBox = uicheckbox(app.InfoTab);
            app.ResponseaccuracyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResponseaccuracyCheckBoxValueChanged, true);
            app.ResponseaccuracyCheckBox.Text = 'Response accuracy';
            app.ProgressDropDownLabel = uilabel(app.InfoTab);
            app.ProgressDropDownLabel.Text = 'Progress';
            app.ProgressDropDown = uidropdown(app.InfoTab);
            app.ProgressDropDown.Items = {'Bar', 'Pie', 'Proportion'};
            app.ProgressDropDown.ValueChangedFcn = createCallbackFcn(app, @ProgressDropDownValueChanged, true);
            app.ProgressDropDown.Value = 'Bar';
            app.DisplayelementsLabel = uilabel(app.InfoTab);
            app.DisplayelementsLabel.Text = 'Display elements';
            app.EpochDropDownLabel = uilabel(app.InfoTab);
            app.EpochDropDownLabel.Text = 'Epoch';
            app.EpochDropDown = uidropdown(app.InfoTab);
            app.EpochDropDown.Items = {'Trial', 'Time / TR'};
            app.EpochDropDown.ValueChangedFcn = createCallbackFcn(app, @EpochDropDownValueChanged, true);
            app.EpochDropDown.Value = 'Trial';
            app.FontnameDropDownLabel = uilabel(app.InfoTab);
            app.FontnameDropDownLabel.Text = 'Font name';
            app.FontnameDropDown = uidropdown(app.InfoTab);
            app.FontnameDropDown.Items = {'Arial', 'Courier', 'Times'};
            app.FontnameDropDown.ValueChangedFcn = createCallbackFcn(app, @FontnameDropDownValueChanged, true);
            app.FontnameDropDown.Value = 'Arial';
            app.FontsizeSpinnerLabel = uilabel(app.InfoTab);
            app.FontsizeSpinnerLabel.Text = 'Font size';
            app.FontsizeSpinner = uispinner(app.InfoTab);
            app.FontsizeSpinner.Limits = [1 100];
            app.FontsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @FontsizeSpinnerValueChanged, true);
            app.FontsizeSpinner.Value = 18;
            app.BackgroundLabel = uilabel(app.InfoTab);
            app.BackgroundLabel.Text = 'Background';
            app.InfoBkg_RGB = uibutton(app.InfoTab, 'push');
            app.InfoBkg_RGB.ButtonPushedFcn = createCallbackFcn(app, @InfoBkg_RGBButtonPushed, true);
            app.InfoBkg_RGB.Text = '';
            app.InfoBkg_Toggle = uicheckbox(app.InfoTab);
            app.InfoBkg_Toggle.ValueChangedFcn = createCallbackFcn(app, @InfoBkg_ToggleValueChanged, true);
            app.InfoBkg_Toggle.Text = '';
            app.InfoBkg_Toggle.Value = true;
            app.InfoBkg_Alpha = uislider(app.InfoTab);
            app.InfoBkg_Alpha.Limits = [0 1];
            app.InfoBkg_Alpha.MajorTicks = [0 1];
            app.InfoBkg_Alpha.ValueChangedFcn = createCallbackFcn(app, @InfoBkg_AlphaValueChanged, true);
            app.InfoBkg_Alpha.MinorTicks = [0.2 0.4 0.6 0.8];
            app.InfoBkg_Alpha.Value = 1;
            app.PositionLabel = uilabel(app.InfoTab);
            app.PositionLabel.Text = 'Position';
            app.InfoPanelPos = uidropdown(app.InfoTab);
            app.InfoPanelPos.Items = {'Bottom left', 'Bottom right', 'Top left', 'Top right'};
            app.InfoPanelPos.ValueChangedFcn = createCallbackFcn(app, @InfoPanelPosValueChanged, true);
            app.InfoPanelPos.Value = 'Bottom left';
            app.BackgroundcolorLabel = uilabel(app.PreviewPanel);
            app.BackgroundcolorLabel.Text = 'Background color';
            app.OptionsPanel = uipanel(app.PreviewPanel);
            app.OptionsPanel.Title = 'Options';
            app.Button_Docs = uibutton(app.OptionsPanel, 'push');
            app.Button_Docs.ButtonPushedFcn = createCallbackFcn(app, @Button_DocsPushed, true);
            app.Button_Docs.Icon = 'ReadTheDocs.png';
            app.Button_Docs.Tooltip = {'Open the NTB documentation webpage for DisplaySettings.'};
            app.Button_Docs.Text = '';
            app.Button_Save = uibutton(app.OptionsPanel, 'push');
            app.Button_Save.ButtonPushedFcn = createCallbackFcn(app, @Button_SavePushed, true);
            app.Button_Save.Icon = 'Save.png';
            app.Button_Save.Tooltip = {'Save current display settings to Params file.'};
            app.Button_Save.Text = '';
            app.Button_Load = uibutton(app.OptionsPanel, 'push');
            app.Button_Load.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadPushed, true);
            app.Button_Load.Icon = 'Transfer.png';
            app.Button_Load.Tooltip = {'Load display settings from a different parameter file.'};
            app.Button_Load.Text = '';
            app.Button_Close = uibutton(app.OptionsPanel, 'push');
            app.Button_Close.ButtonPushedFcn = createCallbackFcn(app, @Button_ClosePushed, true);
            app.Button_Close.Icon = 'Exit.png';
            app.Button_Close.Tooltip = {'Exit Display Settings GUI without updating Display parameters.'};
            app.Button_Close.Text = '';
            % Show the figure after all components are created
            app.NTBDisplaySettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PTB_DisplaySettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NTBDisplaySettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NTBDisplaySettingsUIFigure)
        end
    end
end