classdef NTB_VisuotopySettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NTBVisuotopySettingsUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SettingsTab                   matlab.ui.container.Tab
        StimtypeDropDownLabel         matlab.ui.control.Label
        StimtypeDropDown              matlab.ui.control.DropDown
        EyeDropDownLabel              matlab.ui.control.Label
        EyeDropDown                   matlab.ui.control.DropDown
        DirectionDropDownLabel        matlab.ui.control.Label
        DirectionDropDown             matlab.ui.control.DropDown
        FixmarkertypeDropDownLabel    matlab.ui.control.Label
        FixmarkertypeDropDown         matlab.ui.control.DropDown
        StimulusTab                   matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        CheckerboardTab               matlab.ui.container.Tab
        FlickerfrequencyHzEditFieldLabel  matlab.ui.control.Label
        FlickerfrequencyHzEditField   matlab.ui.control.NumericEditField
        PolardivisionsEditFieldLabel  matlab.ui.control.Label
        PolardivisionsEditField       matlab.ui.control.NumericEditField
        ArcwidthLabel                 matlab.ui.control.Label
        ArcwidthEditField             matlab.ui.control.NumericEditField
        EccdivisionsEditFieldLabel    matlab.ui.control.Label
        EccdivisionsEditField         matlab.ui.control.NumericEditField
        ColorDropDown_2Label          matlab.ui.control.Label
        CheckerColorDropDown          matlab.ui.control.DropDown
        RadiusscaleDropDownLabel      matlab.ui.control.Label
        RadiusscaleDropDown           matlab.ui.control.DropDown
        DotsTab                       matlab.ui.container.Tab
        CoherenceEditFieldLabel       matlab.ui.control.Label
        CoherenceEditField            matlab.ui.control.NumericEditField
        DirectionEditFieldLabel       matlab.ui.control.Label
        DirectionEditField            matlab.ui.control.NumericEditField
        ColorDropDownLabel            matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        Button                        matlab.ui.control.Button
        DotdiameterEditFieldLabel     matlab.ui.control.Label
        DotdiameterEditField          matlab.ui.control.NumericEditField
        SpeedsecEditFieldLabel        matlab.ui.control.Label
        SpeedsecEditField             matlab.ui.control.NumericEditField
        ImagesTab                     matlab.ui.container.Tab
        MoviesTab                     matlab.ui.container.Tab
        MaskTab                       matlab.ui.container.Tab
        TabGroup3                     matlab.ui.container.TabGroup
        PolarTab                      matlab.ui.container.Tab
        WedgearcLabel                 matlab.ui.control.Label
        WedgearcEditField             matlab.ui.control.NumericEditField
        RotationspeedsEditFieldLabel  matlab.ui.control.Label
        RotationspeedsEditField       matlab.ui.control.NumericEditField
        EccentricityTab               matlab.ui.container.Tab
        InnereccDVAEditField_2Label   matlab.ui.control.Label
        InnereccDVAEditField_2        matlab.ui.control.NumericEditField
        AnnuluswidthEditFieldLabel    matlab.ui.control.Label
        AnnuluswidthEditField         matlab.ui.control.NumericEditField
        OutereccDVAEditFieldLabel_2   matlab.ui.control.Label
        OutereccDVAEditField_2        matlab.ui.control.NumericEditField
        DutycycleEditFieldLabel       matlab.ui.control.Label
        DutycycleEditField            matlab.ui.control.NumericEditField
        SwipeTab                      matlab.ui.container.Tab
        Button_2                      matlab.ui.control.Button
        ForegroundcolorLabel          matlab.ui.control.Label
        OutereccDVAEditFieldLabel_3   matlab.ui.control.Label
        InnereccDVAEditFieldLabel     matlab.ui.control.Label
        InnereccDVAEditField_3        matlab.ui.control.NumericEditField
        OutereccDVAEditField_3        matlab.ui.control.NumericEditField
        FixationTab                   matlab.ui.container.Tab
        TabGroup4                     matlab.ui.container.TabGroup
        StandardTab                   matlab.ui.container.Tab
        ImagesTab_2                   matlab.ui.container.Tab
        MoviesTab_2                   matlab.ui.container.Tab
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                matlab.ui.control.EditField
        UnitsDropDownLabel            matlab.ui.control.Label
        UnitsDropDown                 matlab.ui.control.DropDown
        HeightEditFieldLabel          matlab.ui.control.Label
        HeightEditField               matlab.ui.control.EditField
        DirectoryEditFieldLabel       matlab.ui.control.Label
        DirectoryEditField            matlab.ui.control.EditField
        Button_3                      matlab.ui.control.Button
        MaskDropDownLabel             matlab.ui.control.Label
        MaskDropDown                  matlab.ui.control.DropDown
        PreviewPanel                  matlab.ui.container.Panel
        PlayButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       NTBapp
       Visuotopy
       Preview
    end
        
    methods (Access = private)
        
        %========== Update checkboard stimulus
        function results = UpdateCheckerboard(app)
            
            PolarAngles = (0:app.Visuotopy.Checker.ArcWidth:360);
            EccDivPx    = app.Visuotopy.Checker.EccDivs*app.Display.Basic.PixPerDeg;
            EccMaxPx    = ceil(app.Display.Basic.Rect(3)/2);
            switch app.Visuotopy.Checker.RadiusScale
                case 'Linear'
                    StimEccentricities = EccDivPx:app.Visuotopy.Checker.EccDivs:EccMaxPx;
                case 'Logarithmic'
                    StimEccentricities = EccDivPx:app.Visuotopy.Checker.EccDivs:EccMaxPx;
            end
            SegmentLum = {-1,1};
            Checker{1} = [1 2;2 1];
            Checker{2} = [2 1;1 2];
            WinTexture = ones(app.NTBapp.Params.Display.Rect);
            for t = 1:2
                app.Visuotopy.Checker.Texture(t) = Screen('MakeTexture', app.Display.win, WinTexture); % Create a blank texture
                for e = numel(StimEccentricities):-1:1                         % For each eccentricity (in descending order)
                    for p = 1:1:numel(PolarAngles)                          	% For each polar angle
                        s = Checker{t}(mod(e,2)+1,mod(p,2)+1);                  % Find segment brightness ('dark' or 'light')
                        switch app.Visuotopy.Checker.Color
                            case 'Chromatic'
                                SegmentColor = ceil(rand(1,3)*255);                 % Generate random RGB
                                SegmentColor = SegmentColor+(50*SegmentLum{s});     % Change brightness ('dark' or 'light')
                            case 'Greyscale'
                                
                                
                            case 'Monochrome'
                                SegmentColor = max([0 0 0],SegmentLum{s}*255);      % Black or white
                        end
                        Xdim = 2*StimEccentricities(e);
                        StimEccRect = CenterRect([0 0 Xdim StimEccentricities(e)*2], Mask.Rect);   
                        Screen('FillArc', app.Visuotopy.Checker.Texture(t), SegmentColor, StimEccRect, PolarAngles(p), 360-(p*Stim.PolarAngle));
                    end
                end
                app.Preview.CheckerArray{t} = Screen('GetImage', StimTexture(t));
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NTBapp)
            app.Visuotopy   = NTBapp.Params.Visuotopy;    % Add visuotopy params to app object
            app.NTBapp      = NTBapp;     
            
            %====== Populate UI parameters
            app.StimtypeDropDown.Value          = app.Visotopy.StimType;
            
            %=== Checkerboard params
            app.CheckerColorDropDown.Value      = app.Visuotopy.Checker.Color; 
            app.PolardivisionsEditField.Value   = app.Visuotopy.Checker.PolarDivs;
            app.ArcwidthEditField.Value         = app.Visuotopy.Checker.ArcWidth;
            app.EccdivisionsEditField.Value     = app.Visuotopy.Checker.EccDivs;
            app.RadiusscaleDropDown.Value       = app.Visuotopy.Checker.RadiusScale;
            app.FlickerfrequencyHzEditField.Value = app.Visuotopy.Checker.FlickerFrequency;
            
        end

        % Value changed function: StimtypeDropDown
        function StimtypeDropDownValueChanged(app, event)
            value = app.StimtypeDropDown.Value;
            app.Visotopy.StimType = value;
            switch(app.Visotopy.StimType)
                case 'Checkerboard'
                    UpdateCheckerboard(app);
                    
                case 'Moving Dots'
                
                case 'Images'
                    
                case 'Movies'
                    
            end
            
        end

        % Value changed function: CheckerColorDropDown
        function CheckerColorDropDownValueChanged(app, event)
            value = app.CheckerColorDropDown.Value;
            app.Visuotopy.Checker.Color = value;
            UpdateCheckerboard(app);
        end

        % Value changed function: PolardivisionsEditField
        function PolardivisionsEditFieldValueChanged(app, event)
            value = app.PolardivisionsEditField.Value;
            if value ~= floor(value) 
                sprintf('Number of divisions must be an integer!');
            else
                app.Visuotopy.Checker.PolarDivs = value;
                app.Visuotopy.Checker.ArcWidth = 360/app.Visuotopy.Checker.PolarDivs;
                app.ArcwidthEditField.Value = app.Visuotopy.Checker.ArcWidth;
                UpdateCheckerboard(app);
            end
        end

        % Value changed function: ArcwidthEditField
        function ArcwidthEditFieldValueChanged(app, event)
            value = app.ArcwidthEditField.Value;
            app.Visuotopy.Checker.ArcWidth = value;
            PolarDivs = 360/app.Visuotopy.Checker.ArcWidth;
            if floor(PolarDivs) == PolarDivs
                app.Visuotopy.Checker.PolarDivs = PolarDivs;
            else
                app.Visuotopy.Checker.PolarDivs = round(PolarDivs);
                app.Visuotopy.Checker.ArcWidth  = 360/app.Visuotopy.Checker.PolarDivs;
                app.ArcwidthEditField.Value     = app.Visuotopy.Checker.ArcWidth;
            end
            app.PolardivisionsEditField.Value = PolarDivs;
            UpdateCheckerboard(app);  
        end

        % Value changed function: EccdivisionsEditField
        function EccdivisionsEditFieldValueChanged(app, event)
            value = app.EccdivisionsEditField.Value;
            if value ~= floor(value) 
                sprintf('Number of divisions must be an integer!');
            else
                app.Visuotopy.Checker.EccDivs = value;
                UpdateCheckerboard(app);
            end
        end

        % Value changed function: RadiusscaleDropDown
        function RadiusscaleDropDownValueChanged(app, event)
            value = app.RadiusscaleDropDown.Value;
            app.Visuotopy.Checker.RadiusScale = value;
            UpdateCheckerboard(app);
        end

        % Value changed function: FlickerfrequencyHzEditField
        function FlickerfrequencyHzEditFieldValueChanged(app, event)
            value = app.FlickerfrequencyHzEditField.Value;
            app.Visuotopy.Checker.FlickerFrequency = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NTBVisuotopySettingsUIFigure = uifigure('Visible', 'off');
            app.NTBVisuotopySettingsUIFigure.Name = 'NTB Visuotopy Settings';
            app.NTBVisuotopySettingsUIFigure.Icon = 'Checkerboard.png';
            app.NTBVisuotopySettingsUIFigure.Tag = 'NTB_VisuotopySettings';
            app.TabGroup = uitabgroup(app.NTBVisuotopySettingsUIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.StimtypeDropDownLabel = uilabel(app.SettingsTab);
            app.StimtypeDropDownLabel.Text = 'Stim type';
            app.StimtypeDropDown = uidropdown(app.SettingsTab);
            app.StimtypeDropDown.Items = {'Checkerboard', 'Moving dots', 'Images', 'Movies'};
            app.StimtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StimtypeDropDownValueChanged, true);
            app.StimtypeDropDown.Value = 'Checkerboard';
            app.EyeDropDownLabel = uilabel(app.SettingsTab);
            app.EyeDropDownLabel.Text = 'Eye';
            app.EyeDropDown = uidropdown(app.SettingsTab);
            app.EyeDropDown.Items = {'Binocular', 'Monocular - Left', 'Monocular - Right'};
            app.EyeDropDown.Value = 'Binocular';
            app.DirectionDropDownLabel = uilabel(app.SettingsTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.SettingsTab);
            app.DirectionDropDown.Items = {'Clockwise', 'Anticlockwise'};
            app.DirectionDropDown.Value = 'Clockwise';
            app.FixmarkertypeDropDownLabel = uilabel(app.SettingsTab);
            app.FixmarkertypeDropDownLabel.Text = 'Fix marker type';
            app.FixmarkertypeDropDown = uidropdown(app.SettingsTab);
            app.FixmarkertypeDropDown.Items = {'Standard', 'Images', 'Movies'};
            app.FixmarkertypeDropDown.Value = 'Standard';
            app.StimulusTab = uitab(app.TabGroup);
            app.StimulusTab.Title = 'Stimulus';
            app.TabGroup2 = uitabgroup(app.StimulusTab);
            app.CheckerboardTab = uitab(app.TabGroup2);
            app.CheckerboardTab.Title = 'Checkerboard';
            app.FlickerfrequencyHzEditFieldLabel = uilabel(app.CheckerboardTab);
            app.FlickerfrequencyHzEditFieldLabel.Text = 'Flicker frequency (Hz )';
            app.FlickerfrequencyHzEditField = uieditfield(app.CheckerboardTab, 'numeric');
            app.FlickerfrequencyHzEditField.Limits = [0 Inf];
            app.FlickerfrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FlickerfrequencyHzEditFieldValueChanged, true);
            app.FlickerfrequencyHzEditField.Value = 4;
            app.PolardivisionsEditFieldLabel = uilabel(app.CheckerboardTab);
            app.PolardivisionsEditFieldLabel.Text = 'Polar divisions';
            app.PolardivisionsEditField = uieditfield(app.CheckerboardTab, 'numeric');
            app.PolardivisionsEditField.Limits = [0 Inf];
            app.PolardivisionsEditField.RoundFractionalValues = 'on';
            app.PolardivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @PolardivisionsEditFieldValueChanged, true);
            app.PolardivisionsEditField.Value = 36;
            app.ArcwidthLabel = uilabel(app.CheckerboardTab);
            app.ArcwidthLabel.Text = 'Arc width (°)';
            app.ArcwidthEditField = uieditfield(app.CheckerboardTab, 'numeric');
            app.ArcwidthEditField.Limits = [0 Inf];
            app.ArcwidthEditField.ValueDisplayFormat = '%.2f';
            app.ArcwidthEditField.ValueChangedFcn = createCallbackFcn(app, @ArcwidthEditFieldValueChanged, true);
            app.ArcwidthEditField.Value = 10;
            app.EccdivisionsEditFieldLabel = uilabel(app.CheckerboardTab);
            app.EccdivisionsEditFieldLabel.Text = 'Ecc. divisions';
            app.EccdivisionsEditField = uieditfield(app.CheckerboardTab, 'numeric');
            app.EccdivisionsEditField.Limits = [0 Inf];
            app.EccdivisionsEditField.RoundFractionalValues = 'on';
            app.EccdivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @EccdivisionsEditFieldValueChanged, true);
            app.EccdivisionsEditField.Value = 20;
            app.ColorDropDown_2Label = uilabel(app.CheckerboardTab);
            app.ColorDropDown_2Label.Text = 'Color';
            app.CheckerColorDropDown = uidropdown(app.CheckerboardTab);
            app.CheckerColorDropDown.Items = {'Chromatic', 'Greyscale', 'Black & white'};
            app.CheckerColorDropDown.ValueChangedFcn = createCallbackFcn(app, @CheckerColorDropDownValueChanged, true);
            app.CheckerColorDropDown.Value = 'Chromatic';
            app.RadiusscaleDropDownLabel = uilabel(app.CheckerboardTab);
            app.RadiusscaleDropDownLabel.Text = 'Radius scale';
            app.RadiusscaleDropDown = uidropdown(app.CheckerboardTab);
            app.RadiusscaleDropDown.Items = {'Logarithmic', 'Linear'};
            app.RadiusscaleDropDown.ValueChangedFcn = createCallbackFcn(app, @RadiusscaleDropDownValueChanged, true);
            app.RadiusscaleDropDown.Value = 'Logarithmic';
            app.DotsTab = uitab(app.TabGroup2);
            app.DotsTab.Title = 'Dots';
            app.CoherenceEditFieldLabel = uilabel(app.DotsTab);
            app.CoherenceEditFieldLabel.Text = 'Coherence (%)';
            app.CoherenceEditField = uieditfield(app.DotsTab, 'numeric');
            app.CoherenceEditField.Limits = [0 100];
            app.CoherenceEditField.Value = 50;
            app.DirectionEditFieldLabel = uilabel(app.DotsTab);
            app.DirectionEditFieldLabel.Text = 'Direction (°)';
            app.DirectionEditField = uieditfield(app.DotsTab, 'numeric');
            app.DirectionEditField.Limits = [0 360];
            app.DirectionEditField.Value = 5;
            app.ColorDropDownLabel = uilabel(app.DotsTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.DotsTab);
            app.ColorDropDown.Items = {'Black & white', 'Single color'};
            app.ColorDropDown.Value = 'Single color';
            app.Button = uibutton(app.DotsTab, 'push');
            app.Button.Text = '...';
            app.DotdiameterEditFieldLabel = uilabel(app.DotsTab);
            app.DotdiameterEditFieldLabel.Text = 'Dot diameter (°)';
            app.DotdiameterEditField = uieditfield(app.DotsTab, 'numeric');
            app.DotdiameterEditField.Limits = [0 Inf];
            app.DotdiameterEditField.Value = 0.1;
            app.SpeedsecEditFieldLabel = uilabel(app.DotsTab);
            app.SpeedsecEditFieldLabel.Text = 'Speed (°/sec)';
            app.SpeedsecEditField = uieditfield(app.DotsTab, 'numeric');
            app.SpeedsecEditField.Limits = [0 Inf];
            app.SpeedsecEditField.Value = 5;
            app.ImagesTab = uitab(app.TabGroup2);
            app.ImagesTab.Title = 'Images';
            app.MoviesTab = uitab(app.TabGroup2);
            app.MoviesTab.Title = 'Movies';
            app.MaskTab = uitab(app.TabGroup);
            app.MaskTab.Title = 'Mask';
            app.TabGroup3 = uitabgroup(app.MaskTab);
            app.PolarTab = uitab(app.TabGroup3);
            app.PolarTab.Title = 'Polar';
            app.WedgearcLabel = uilabel(app.PolarTab);
            app.WedgearcLabel.Text = 'Wedge arc (°)';
            app.WedgearcEditField = uieditfield(app.PolarTab, 'numeric');
            app.WedgearcEditField.Value = 45;
            app.RotationspeedsEditFieldLabel = uilabel(app.PolarTab);
            app.RotationspeedsEditFieldLabel.Text = 'Rotation speed (°/s)';
            app.RotationspeedsEditField = uieditfield(app.PolarTab, 'numeric');
            app.RotationspeedsEditField.Value = 9;
            app.EccentricityTab = uitab(app.TabGroup3);
            app.EccentricityTab.Title = 'Eccentricity';
            app.InnereccDVAEditField_2Label = uilabel(app.EccentricityTab);
            app.InnereccDVAEditField_2Label.Text = 'Inner ecc. (DVA)';
            app.InnereccDVAEditField_2 = uieditfield(app.EccentricityTab, 'numeric');
            app.InnereccDVAEditField_2.Value = 1;
            app.AnnuluswidthEditFieldLabel = uilabel(app.EccentricityTab);
            app.AnnuluswidthEditFieldLabel.Text = 'Annulus width (°)';
            app.AnnuluswidthEditField = uieditfield(app.EccentricityTab, 'numeric');
            app.AnnuluswidthEditField.Value = 2;
            app.OutereccDVAEditFieldLabel_2 = uilabel(app.EccentricityTab);
            app.OutereccDVAEditFieldLabel_2.Text = 'Outer ecc. (DVA)';
            app.OutereccDVAEditField_2 = uieditfield(app.EccentricityTab, 'numeric');
            app.OutereccDVAEditField_2.Value = 15;
            app.DutycycleEditFieldLabel = uilabel(app.EccentricityTab);
            app.DutycycleEditFieldLabel.Text = 'Duty cycle (%)';
            app.DutycycleEditField = uieditfield(app.EccentricityTab, 'numeric');
            app.DutycycleEditField.Value = 12;
            app.SwipeTab = uitab(app.TabGroup3);
            app.SwipeTab.Title = 'Swipe';
            app.Button_2 = uibutton(app.MaskTab, 'push');
            app.Button_2.Text = '';
            app.ForegroundcolorLabel = uilabel(app.MaskTab);
            app.ForegroundcolorLabel.Text = 'Foreground color';
            app.OutereccDVAEditFieldLabel_3 = uilabel(app.MaskTab);
            app.OutereccDVAEditFieldLabel_3.Text = 'Outer ecc. (DVA)';
            app.InnereccDVAEditFieldLabel = uilabel(app.MaskTab);
            app.InnereccDVAEditFieldLabel.Text = 'Inner ecc. (DVA)';
            app.InnereccDVAEditField_3 = uieditfield(app.MaskTab, 'numeric');
            app.InnereccDVAEditField_3.Value = 1;
            app.OutereccDVAEditField_3 = uieditfield(app.MaskTab, 'numeric');
            app.OutereccDVAEditField_3.Value = 15;
            app.FixationTab = uitab(app.TabGroup);
            app.FixationTab.Title = 'Fixation';
            app.TabGroup4 = uitabgroup(app.FixationTab);
            app.StandardTab = uitab(app.TabGroup4);
            app.StandardTab.Title = 'Standard';
            app.ImagesTab_2 = uitab(app.TabGroup4);
            app.ImagesTab_2.Title = 'Images';
            app.MoviesTab_2 = uitab(app.TabGroup4);
            app.MoviesTab_2.Title = 'Movies';
            app.WidthEditFieldLabel = uilabel(app.MoviesTab_2);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.MoviesTab_2, 'text');
            app.UnitsDropDownLabel = uilabel(app.MoviesTab_2);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.MoviesTab_2);
            app.UnitsDropDown.Items = {'Degrees', 'Pixels', 'Cm', 'Inches'};
            app.UnitsDropDown.Value = 'Degrees';
            app.HeightEditFieldLabel = uilabel(app.MoviesTab_2);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.MoviesTab_2, 'text');
            app.DirectoryEditFieldLabel = uilabel(app.MoviesTab_2);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.MoviesTab_2, 'text');
            app.Button_3 = uibutton(app.MoviesTab_2, 'push');
            app.Button_3.Text = '...';
            app.MaskDropDownLabel = uilabel(app.MoviesTab_2);
            app.MaskDropDownLabel.Text = 'Mask';
            app.MaskDropDown = uidropdown(app.MoviesTab_2);
            app.MaskDropDown.Items = {'None', 'Circle - hard', 'Circle - Gaussian', 'Circle - Sinusoid'};
            app.MaskDropDown.Value = 'None';
            app.PreviewPanel = uipanel(app.NTBVisuotopySettingsUIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.PlayButton = uibutton(app.PreviewPanel, 'push');
            app.PlayButton.Text = 'Play';
            app.UIAxes = uiaxes(app.PreviewPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.NTBVisuotopySettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NTB_VisuotopySettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NTBVisuotopySettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NTBVisuotopySettingsUIFigure)
        end
    end
end