classdef NIF_Toolbar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NIFToolbarUIFigure            matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        fMRITab                       matlab.ui.container.Tab
        Button_EPInoise               matlab.ui.control.Button
        Button_BlockDesign            matlab.ui.control.Button
        Button_ScannerPulse           matlab.ui.control.Button
        EphysTab                      matlab.ui.container.Tab
        Button_Stimulation            matlab.ui.control.Button
        Button_Optitrack              matlab.ui.control.Button
        Button_Photodiode             matlab.ui.control.Button
        Button_OpenEPhys              matlab.ui.control.Button
        Button_TDT                    matlab.ui.control.Button
        DisplayTab                    matlab.ui.container.Tab
        Button_Unity                  matlab.ui.control.Button
        Button_Blender                matlab.ui.control.Button
        Button_DomeProjection         matlab.ui.control.Button
        Button_Sterescopic            matlab.ui.control.Button
        CalibrateTab                  matlab.ui.container.Tab
        Button_NationalInstruments_2  matlab.ui.control.Button
        Button_DataPixx_2             matlab.ui.control.Button
        Button_Joystick               matlab.ui.control.Button
        Button_ScannerCal             matlab.ui.control.Button
        Button_GammaCorrect           matlab.ui.control.Button
        Button_RewardCal              matlab.ui.control.Button
        Button_DebugMode              matlab.ui.control.Button
        Button_CalGrid                matlab.ui.control.Button
        Button_LumCal                 matlab.ui.control.Button
        HelpTab                       matlab.ui.container.Tab
        Button_ReadTheDocs            matlab.ui.control.Button
        Button_NationalInstruments    matlab.ui.control.Button
        Button_HelpGit                matlab.ui.control.Button
        Button_HelpTDT                matlab.ui.control.Button
        Button_HelpArrington          matlab.ui.control.Button
        Button_HelpDataPixx           matlab.ui.control.Button
        Button_HelpPTB                matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        OptionsTab                    matlab.ui.container.Tab
        Button_CommandLine            matlab.ui.control.StateButton
        TabGroup2                     matlab.ui.container.TabGroup
        ActionsTab                    matlab.ui.container.Tab
        Button_Finish                 matlab.ui.control.Button
        Button_LightsOut              matlab.ui.control.Button
        Button_PlayMovie              matlab.ui.control.Button
        Button_PlayAudio              matlab.ui.control.Button
        Button_Penalty                matlab.ui.control.Button
        Button_RunEyeCal              matlab.ui.control.Button
        Button_ManualReward           matlab.ui.control.Button
        Button_OpenWindow             matlab.ui.control.Button
        Button_RunExperiment          matlab.ui.control.Button
        SettingsTab                   matlab.ui.container.Tab
        Button_GitHub                 matlab.ui.control.Button
        Button_Transfer               matlab.ui.control.Button
        Button_Save                   matlab.ui.control.Button
        Button_Keyboard               matlab.ui.control.Button
        Button_ExpSettings            matlab.ui.control.Button
        Button_Reward                 matlab.ui.control.Button
        Button_EyeTracking            matlab.ui.control.Button
        Button_DataPixx               matlab.ui.control.Button
        Button_Display                matlab.ui.control.Button
        ExperimentsTab                matlab.ui.container.Tab
        Button_BlenderExpSettings     matlab.ui.control.Button
        Button_VisuotopySettings      matlab.ui.control.Button
        Button_MovieExpSettings       matlab.ui.control.Button
        Button_ImageExpSettings       matlab.ui.control.Button
        SessionPanel                  matlab.ui.container.Panel
        DropDown_Experiment_2         matlab.ui.control.DropDown
        Button_Experiment_2           matlab.ui.control.Button
        DropDown_Experiment           matlab.ui.control.DropDown
        Button_Experiment             matlab.ui.control.Button
        DropDown_Subject              matlab.ui.control.DropDown
        Button_Subject                matlab.ui.control.Button
        DropDown_User                 matlab.ui.control.DropDown
        Button_User                   matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        DropDown_Parameters           matlab.ui.control.DropDown
        Button_Parameters             matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        TabGroup3                     matlab.ui.container.TabGroup
        RewardTab                     matlab.ui.container.Tab
        AutomsSlider                  matlab.ui.control.Slider
        AutomsSliderLabel             matlab.ui.control.Label
        ManualmsSlider                matlab.ui.control.Slider
        ManualmsSliderLabel           matlab.ui.control.Label
        RunCounter                    matlab.ui.control.TextArea
        RunCounterReset               matlab.ui.control.Button
        RunTextAreaLabel              matlab.ui.control.Label
        Button_ExpSettings_2          matlab.ui.control.Button
        SessionCounter                matlab.ui.control.TextArea
        SessionCounterReset           matlab.ui.control.Button
        SessionTextAreaLabel          matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        EyeTab                        matlab.ui.container.Tab
        Button_ExpSettings_4          matlab.ui.control.Button
        LeverTab                      matlab.ui.container.Tab
        Lever2Lamp                    matlab.ui.control.Lamp
        Lever2LampLabel               matlab.ui.control.Label
        Button_ExpSettings_3          matlab.ui.control.Button
        Lever1Lamp                    matlab.ui.control.Lamp
        Lever1LampLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        SessionTab                    matlab.ui.container.Tab
        BlockSpinner                  matlab.ui.control.Spinner
        BlockSpinnerLabel             matlab.ui.control.Label
        RunSpinner                    matlab.ui.control.Spinner
        RunSpinnerLabel               matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Params          % The Params structure holds all NTB parameter values for running experiments
        OffOnColors     
        PTBdetected     
        Run             % The Run structure holds current experiment run data          
    end
    
    methods (Access = public)
        
        %================= OPEN NEW PTB WINDOW 
        function results = NTB_OpenPTBwindow(app)
            winPtr = Screen('Windows');                                                 % Find all current PTB window pointers
            if ~isfield(app.Run, 'Win') || isempty(winPtr)                              % If a PTB window is not already open...
                Screen('Preference', 'VisualDebugLevel', 0);                            % Set debug level
                if app.Params.Display.Screen.ID == 0
                    selection   = uiconfirm(app.NIFToolbarUIFigure, sprintf('PTB window will open on screen 0 (this screen). What scale should it be?'), 'Scale window', 'Options',{'Full-screen','Half-size','Cancel'}, 'Icon', 'Display.png');
                    switch selection
                        case 'Full-screen'
                            Scale = 1;
                        case 'Half-size'
                            Scale = 2;
                        case 'Cancel'
                            results = 0;
                            return;
                    end
                    ScreenRect = app.Params.Display.Basic.Rect/Scale;
                else
                    ScreenRect = app.Params.Display.Screen.XScreenRect;
                end
                [app.Run.Win]    = Screen('OpenWindow', app.Params.Display.Screen.ID, app.Params.Display.Screen.BackgroundColor, ScreenRect,[],[], [], []);
                Screen('BlendFunction', app.Run.Win, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);  	% Enable alpha transparency channel
                Screen('ColorRange', app.Run.Win, 1);
                results = 1;
                if ~ismac
                    fprintf('Constraining cursor to avoid encroaching subject display');
                    Screen('ConstrainCursor', app.Run.Win, 1, [1,1,10,10]);                         % Avoid cursor entering subject window
                end
                
                app = NTB_SplashScreen(app);    % Add splash screen
                Screen('Flip', app.Run.Win);
                
                
            else
                results = 0;
            end
        end
        
        
        %================= CLOSE EXISTING PTB WINDOW 
        function results = NTB_ClosePTBwindow(app)
            ShowCursor;
            winPtr = Screen('Windows');                                                 % Find all current PTB window pointers
            if isfield(app.Run, 'Win') || ~isempty(winPtr)   
                Screen('Close', app.Run.Win);
                results = 1;
            else
                results = 0;
            end
        end


    end
    
    methods (Access = private)
        
        function results = NTB_ResizeMatlab(app, Starting)
            desktop = com.mathworks.mde.desk.MLDesktop.getInstance;
            desktopMainFrame = desktop.getMainFrame;
            ScreenSize = get(0, 'screensize');
            if Starting == 1
                desktopMainFrame.setSize(ScreenSize(3), ScreenSize(4)-app.NIFToolbarUIFigure.Position(4));
              
            elseif Starting == 0
                desktopMainFrame.setSize(ScreenSize(3), ScreenSize(4));     % Return Matlab window to full screen
                NTB_CommandColor('reset');                                  % Return default command window color
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %NIFlogo = 'NIF_MagneticField_White.gif';
            %app.HTML('String',sprintf('<html><img src="%s"/></html>', NIFlogo));
            %je = javax.swing.JEditorPane('text/html', sprintf('<html><img src="%s"/></html>', NIFlogo));
            %[hj, hc] =  javacomponent(je,[], app.HTML);
            %set(hc, 'pos', [10,10,70,70]);

            % Load default params file for this host
            Params = NTB_LoadParams(app);
            app.Params = Params;
            app.DropDown_Parameters.Items = app.Params.P.AllParamsFiles;
            app.DropDown_Parameters.Value = app.Params.P.ParamsFile;
            app.OffOnColors = {[0.9,0.9,0.9],[0,1,0]};
            
            % Set figure size based on screen size
            if ~IsWin
                ScreenSize = get(0, 'screensize');
                set(app.NIFToolbarUIFigure, 'Position', [0, 0, ScreenSize(3), 230])
                NTB_ResizeMatlab(app, 1);
            elseif IsWin
                set(app.NIFToolbarUIFigure, 'Position', [0, 0, 1920, 230])
            end
            
            % 
            app.Run.IsRunning = 0;
            app.Run.WinOpen = 0;
            
            if ~exist('PsychtoolboxVersion.m','file')
                app.PTBdetected = 0;
                msg = sprintf('WARNING: PsychToolbox was not detected on the Matlab path! You will not be able to run experiments without it.');
                uialert(app.NIFToolbarUIFigure, msg,'Warning','Icon','warning');
            else 
                app.PTBdetected = 1;
            end
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NIFToolbarUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {227, 227};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {702, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Button_Display
        function Button_DisplayPushed(app, event)
            %app.Button_Display.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Display Settings','tag','NTB_DispSettings');
            if isempty(fh)
                newapp = NTB_DisplaySettings(app);
            else
                figure(fh);
            end
        end

        % Button pushed function: Button_Parameters
        function LoadParams(app, event)
            [file, path] = uigetfile('NTB_*.mat', 'Select params file');
            if file == 0
                return
            end
            ParamsFile  = fullfile(path, file);
            app.Params  = load(ParamsFile);    
            app.DropDown_Parameters.Items{end+1} = file;
            app.DropDown_Parameters.Value = file;

        end

        % Button pushed function: Button_DataPixx
        function Button_DataPixxPushed(app, event)
            app.Button_DataPixx.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Datapixx Settings','tag','NTB_Datapixx');
            if isempty(fh)
                app.Params.DPx = NTB_DatapixxSettings(app, app.Params.DPx);
            else
                figure(fh);
            end
        end

        % Button pushed function: Button_Reward
        function Button_RewardPushed(app, event)
            app.Button_Reward.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Reward Settings','tag','NTB_Reward');
            if isempty(fh)
                app.Params.Reward = NTB_RewardSettings(app, app.Params.Reward);
            else
                figure(fh);
            end
        end

        % Button pushed function: Button_EyeTracking
        function Button_EyeTrackingPushed(app, event)
            app.Button_EyeTracking.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Eye Cal Settings','tag','NTB_EyeCal');
            if isempty(fh)
                app.Params.Eye = NTB_EyeCalSettings(app, app.Params.Eye);
            else
                figure(fh);
            end
        end

        % Button pushed function: Button_RunExperiment
        function Button_RunExperimentPushed(app, event)
            
            if app.Run.IsRunning == 0
                app.Button_RunExperiment.BackgroundColor = app.OffOnColors{2};
                app.Button_RunExperiment.Icon = 'PlayOff.png';
                app.Button_RunExperiment.Tooltip = 'Stop the experiment that is currently running.';
                app.Run.IsRunning = 1;
                
            elseif app.Run.IsRunning == 1 
                app.Button_RunExperiment.BackgroundColor = app.OffOnColors{1};
                app.Button_RunExperiment.Icon = 'Play.png';
                app.Button_RunExperiment.Tooltip = 'Run the currently selected experiment.';
                app.Run.IsRunning = 0;
                
            end
            
            
        end

        % Button pushed function: Button_OpenWindow
        function Button_OpenWindowPushed(app, event)
            
            winPtr = Screen('Windows');                                                 % Find all current PTB window pointers
            if app.Run.WinOpen == 0
                uiconfirm(app.NIFToolbarUIFigure, sprintf('Opening new PTB window...'), 'Opening window', 'Icon', 'Display.png');
                result = NTB_OpenPTBwindow(app);
                if result == 1
                    app.Run.WinOpen = 1;
                    app.Button_OpenWindow.BackgroundColor = app.OffOnColors{2};
                    app.Button_OpenWindow.Icon = 'Exit.png';
                    app.Button_OpenWindow.Tooltip = 'Close the current PTB window.';
                    app.Button_RunExperiment.Enable = 1;
                end
                NTB_SplashScreen(app);
            elseif app.Run.WinOpen == 1
                if ~isempty(winPtr)
                    if app.Run.IsRunning == 1
                        selection   = uiconfirm(app.NIFToolbarUIFigure, sprintf('Closing the PTB window will end the currently running experiment! Do you wish to continue?'), 'Close window?', 'Options',{'Close','Cancel'}, 'Icon', 'Display.png');
                    else
                        selection   = uiconfirm(app.NIFToolbarUIFigure, sprintf('Are you sure you want to close the current PTB window?'), 'Close window?', 'Options',{'Close','Cancel'}, 'Icon', 'Display.png');
                    end
                    if strcmp(selection, 'Close')
                        result = NTB_ClosePTBwindow(app);
                    else
                        result = 0;
                    end
                elseif isempty(winPtr)
                    result = 1;
                end
                if result == 1
                    app.Run.WinOpen = 0;
                    app.Button_OpenWindow.BackgroundColor = app.OffOnColors{1};
                    app.Button_OpenWindow.Icon = 'Display.png';
                    app.Button_OpenWindow.Tooltip = 'Open a new PTB window.';
                    app.Button_RunExperiment.Enable = 0;
                end
            end
        end

        % Button pushed function: Button_ManualReward
        function Button_ManualRewardPushed(app, event)


        end

        % Button pushed function: Button_RunEyeCal
        function Button_RunEyeCalPushed(app, event)
            if app.Run.IsRunning == 1
                selection   = uiconfirm(app.NIFToolbarUIFigure, sprintf('Running an eye calibration will end the currently running experiment! Do you wish to continue?'), 'Eye calibration', 'Options',{'Run calibration','Cancel'}, 'Icon', 'Eye.png');
            end
            if app.Run.WinOpen == 0
                OpenWin   = uiconfirm(app.NIFToolbarUIFigure, sprintf('No open PTB window detected. Would you like to open one?'), 'Eye calibration', 'Options',{'Run calibration','Cancel'}, 'Icon', 'Eye.png');
                if strcmp(OpenWin, 'Run calibration')
                    NTB_OpenPTBwindow(app);
                end
            end
            if strcmp(selection, 'Run calibration')
                
                
            end
            
        end

        % Button pushed function: Button_ImageExpSettings
        function Button_ImageExpSettingsPushed(app, event)
            app.Button_ImageExpSettings.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Image Settings','tag','NTB_ImageSettings');
            if isempty(fh)
                newapp = NTB_ImageSettings(app, app.Params);
            else
                figure(fh);
            end
        end

        % Button pushed function: Button_MovieExpSettings
        function Button_MovieExpSettingsPushed(app, event)
            app.Button_MovieExpSettings.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Movie Settings','tag','NTB_MovieSettings');
            if isempty(fh)
                newapp = NTB_MovieSettings(app);
            else
                figure(fh);
            end
        end

        % Button pushed function: Button_VisuotopySettings
        function Button_VisuotopySettingsPushed(app, event)
            app.Button_VisuotopySettings.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Visuotopy Settings','tag','NTB_VisuotopySettings');
            if isempty(fh)
                newapp = NTB_VisuotopySettings(app);
            else
                figure(fh);
            end
        end

        % Button pushed function: Button_BlenderExpSettings
        function Button_BlenderExpSettingsPushed(app, event)
            
        end

        % Button pushed function: Button_BlockDesign
        function Button_BlockDesignPushed(app, event)
            app.Button_BlockDesign.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Block Design','tag','NTB_BlockDesign');
            if isempty(fh)
                app.Params.Block = NTB_BlockDesign(app);
            else
                figure(fh);
            end
        end

        % Button pushed function: SessionCounterReset
        function SessionCounterResetPushed(app, event)
            app.SessionCounter.Value = '0';
        end

        % Button pushed function: RunCounterReset
        function RunCounterResetButtonPushed(app, event)
            app.RunCounter.Value = '0';
        end

        % Button pushed function: Button_CalGrid
        function Button_CalGridPushed(app, event)
            app.Button_CalGrid.BackgroundColor = app.OffOnColors{2};
            fh = findall(0, 'name','NTB Calibration Grid','tag','NTB_CalibrationGrid');
            if isempty(fh)
                app = NTB_CalibrationGrid(app);
            else
                figure(fh);
            end
        end

        % Value changed function: DropDown_User
        function NTB_Session_User(app, event)
            value = app.DropDown_User.Value;
            if strcmp(value, "Create new")
                Selection  = uiconfirm(app.NIFToolbarUIFigure, sprintf('Create new user profile?'), 'Create user', 'Options',{'OK','Cancel'}, 'Icon', 'Users.png');
                if strcmp(Selection, 'Cancel') 
                    app.DropDown_User.Value = 'Select User';
                elseif strcmp(Selection, 'OK')
                    NewUser = inputdlg('Enter user name string:','Create new user');
                    app.DropDown_User.Items(end) = NewUser;
                   
                    app.DropDown_User.Items = [app.DropDown_User.Items, 'Create new'];  
                    app.DropDown_User.Value = NewUser;
                end
            elseif strcmp(value,'Select User')
                return;
            else

            end
        end

        % Value changed function: DropDown_Parameters
        function NTB_Session_Params(app, event)
            value = app.DropDown_Parameters.Value;
            if strcmp(value, 'Create new')
                fprintf('Creating new Params object file')
            else
                Filename = fullfile(value);
                if isfile(Filename)

                else
                    
                end
            end

        end

        % Close request function: NIFToolbarUIFigure
        function NIFToolbarUIFigureCloseRequest(app, event)
            NTB_CommandColor('reset');
            delete(app)

        end

        % Callback function
        function Button_ScannerPulse_2Pushed(app, event)
            NTB_CommandColor();
            
        end

        % Value changed function: Button_CommandLine
        function Button_CommandLineValueChanged(app, event)
            value = app.Button_CommandLine.Value;
            if value == 1
                NTB_CommandColor('black','cyan')
            else
                NTB_CommandColor('white','black')
            end
        end

        % Value changed function: DropDown_Subject
        function DropDown_SubjectValueChanged(app, event)
            value = app.DropDown_Subject.Value;
            if strcmp(value, "Create new")
                Selection  = uiconfirm(app.NIFToolbarUIFigure, sprintf('Create new subject profile?'), 'Create subject', 'Options',{'OK','Cancel'}, 'Icon', 'Subject.png');
                if strcmp(Selection, 'Cancel') 
                    app.DropDown_Subject.Value = 'Select Subject';
                elseif strcmp(Selection, 'OK')
                    NewSubject = inputdlg('Enter subject name string:','Create new subject');
                    app.DropDown_Subject.Items(end) = NewSubject;
                    app.DropDown_Subject.Items = [app.DropDown_Subject.Items, 'Create new'];  
                    app.DropDown_Subject.Value = NewSubject;
                end
            elseif strcmp(value,'Select Subject')
                return;
            else
                
            end           

        end

        % Value changed function: DropDown_Experiment_2
        function DropDown_Experiment_2ValueChanged(app, event)
            value = app.DropDown_Experiment_2.Value;
            NewSessionTime = now();
            NewSessionTimeString = datestr(NewSessionTime, 'mm/dd/yy HH:MM');
            if strcmp(value, 'Create new')
                Selection  = uiconfirm(app.NIFToolbarUIFigure, sprintf('Create new session ''%s''?', NewSessionTimeString), 'Create session', 'Options',{'OK','Cancel'}, 'Icon', 'Calendar.png');
                if strcmp(Selection, 'Cancel') 
                    app.DropDown_Experiment_2.Value = 'Select Session';
                elseif strcmp(Selection, 'OK')
                    app.DropDown_Experiment_2.Items(end) = {NewSessionTimeString};
                    app.DropDown_Experiment_2.Items = [app.DropDown_Experiment_2.Items, 'Create new'];  
                    app.DropDown_Experiment_2.Value = NewSessionTimeString;
                end
            elseif strcmp(value,'Select Subject')
                return;
            else

               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NIFToolbarUIFigure = uifigure('Visible', 'off');
            app.NIFToolbarUIFigure.Name = 'NIF Toolbar';
            app.NIFToolbarUIFigure.CloseRequestFcn = createCallbackFcn(app, @NIFToolbarUIFigureCloseRequest, true);
            app.NIFToolbarUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.NIFToolbarUIFigure.Tag = 'NTB';
            app.GridLayout = uigridlayout(app.NIFToolbarUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SessionPanel = uipanel(app.LeftPanel);
            app.SessionPanel.Title = 'Session';
            app.Button_Parameters = uibutton(app.SessionPanel, 'push');
            app.Button_Parameters.ButtonPushedFcn = createCallbackFcn(app, @LoadParams, true);
            app.Button_Parameters.Icon = 'Settings.png';
            app.Button_Parameters.Tooltip = {'Select and load a params (.mat) file'};
            app.Button_Parameters.Text = '';
            app.DropDown_Parameters = uidropdown(app.SessionPanel);
            app.DropDown_Parameters.Items = {'Select Parameters'};
            app.DropDown_Parameters.ValueChangedFcn = createCallbackFcn(app, @NTB_Session_Params, true);
            app.DropDown_Parameters.Tooltip = {'Select an existing params (.mat) file from the deault directory'};
            app.DropDown_Parameters.Value = 'Select Parameters';
            app.Label_3 = uilabel(app.SessionPanel);
            app.Label_3.Text = '';
            app.Button_User = uibutton(app.SessionPanel, 'push');
            app.Button_User.Icon = 'Users.png';
            app.Button_User.Tooltip = {'Select User'};
            app.Button_User.Text = '';
            app.DropDown_User = uidropdown(app.SessionPanel);
            app.DropDown_User.Items = {'Select User', 'Create new'};
            app.DropDown_User.ValueChangedFcn = createCallbackFcn(app, @NTB_Session_User, true);
            app.DropDown_User.Tooltip = {'Select or create a user'};
            app.DropDown_User.Value = 'Select User';
            app.Button_Subject = uibutton(app.SessionPanel, 'push');
            app.Button_Subject.Icon = 'Subject.png';
            app.Button_Subject.Tooltip = {'Select a subject'};
            app.Button_Subject.Text = '';
            app.DropDown_Subject = uidropdown(app.SessionPanel);
            app.DropDown_Subject.Items = {'Select Subject', 'Create new'};
            app.DropDown_Subject.ValueChangedFcn = createCallbackFcn(app, @DropDown_SubjectValueChanged, true);
            app.DropDown_Subject.Tooltip = {'Select or create a subject'};
            app.DropDown_Subject.Value = 'Select Subject';
            app.Button_Experiment = uibutton(app.SessionPanel, 'push');
            app.Button_Experiment.Icon = 'Experiment.png';
            app.Button_Experiment.Tooltip = {'Select an experiment'};
            app.Button_Experiment.Text = '';
            app.DropDown_Experiment = uidropdown(app.SessionPanel);
            app.DropDown_Experiment.Items = {'Select Experiment', 'Create new'};
            app.DropDown_Experiment.Value = 'Select Experiment';
            app.Button_Experiment_2 = uibutton(app.SessionPanel, 'push');
            app.Button_Experiment_2.Icon = 'Calendar.png';
            app.Button_Experiment_2.Tooltip = {'Select a session'};
            app.Button_Experiment_2.Text = '';
            app.DropDown_Experiment_2 = uidropdown(app.SessionPanel);
            app.DropDown_Experiment_2.Items = {'Select Session', 'Create new'};
            app.DropDown_Experiment_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_Experiment_2ValueChanged, true);
            app.DropDown_Experiment_2.Value = 'Select Session';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.ActionsTab = uitab(app.TabGroup2);
            app.ActionsTab.Title = 'Actions';
            app.Button_RunExperiment = uibutton(app.ActionsTab, 'push');
            app.Button_RunExperiment.ButtonPushedFcn = createCallbackFcn(app, @Button_RunExperimentPushed, true);
            app.Button_RunExperiment.Icon = 'Play.png';
            app.Button_RunExperiment.Enable = 'off';
            app.Button_RunExperiment.Tooltip = {'Run the currently selected experiment.'};
            app.Button_RunExperiment.Text = '';
            app.Button_OpenWindow = uibutton(app.ActionsTab, 'push');
            app.Button_OpenWindow.ButtonPushedFcn = createCallbackFcn(app, @Button_OpenWindowPushed, true);
            app.Button_OpenWindow.Icon = 'Display.png';
            app.Button_OpenWindow.Tooltip = {'Open a new PTB window.'};
            app.Button_OpenWindow.Text = '';
            app.Button_ManualReward = uibutton(app.ActionsTab, 'push');
            app.Button_ManualReward.ButtonPushedFcn = createCallbackFcn(app, @Button_ManualRewardPushed, true);
            app.Button_ManualReward.Icon = 'Liquid.png';
            app.Button_ManualReward.Tooltip = {'Manual liquid reward delivery'};
            app.Button_ManualReward.Text = '';
            app.Button_RunEyeCal = uibutton(app.ActionsTab, 'push');
            app.Button_RunEyeCal.ButtonPushedFcn = createCallbackFcn(app, @Button_RunEyeCalPushed, true);
            app.Button_RunEyeCal.Icon = 'Eye.png';
            app.Button_RunEyeCal.Tooltip = {'Run eye tracking calibration'};
            app.Button_RunEyeCal.Text = '';
            app.Button_Penalty = uibutton(app.ActionsTab, 'push');
            app.Button_Penalty.Icon = 'Penalty.png';
            app.Button_Penalty.Enable = 'off';
            app.Button_Penalty.Tooltip = {'Give penalty'};
            app.Button_Penalty.Text = '';
            app.Button_PlayAudio = uibutton(app.ActionsTab, 'push');
            app.Button_PlayAudio.Icon = 'SpeakerOn.png';
            app.Button_PlayAudio.Enable = 'off';
            app.Button_PlayAudio.Tooltip = {'Play audio'};
            app.Button_PlayAudio.Text = '';
            app.Button_PlayMovie = uibutton(app.ActionsTab, 'push');
            app.Button_PlayMovie.Icon = 'Movie.png';
            app.Button_PlayMovie.Enable = 'off';
            app.Button_PlayMovie.Tooltip = {'Play a movie'};
            app.Button_PlayMovie.Text = '';
            app.Button_LightsOut = uibutton(app.ActionsTab, 'push');
            app.Button_LightsOut.Icon = 'Sleep.png';
            app.Button_LightsOut.Enable = 'off';
            app.Button_LightsOut.Tooltip = {'Dim the screen for a timeout'};
            app.Button_LightsOut.Text = '';
            app.Button_Finish = uibutton(app.ActionsTab, 'push');
            app.Button_Finish.Icon = 'Finish.png';
            app.Button_Finish.Enable = 'off';
            app.Button_Finish.Tooltip = {'Dim the screen for a timeout'};
            app.Button_Finish.Text = '';
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.Button_Display = uibutton(app.SettingsTab, 'push');
            app.Button_Display.ButtonPushedFcn = createCallbackFcn(app, @Button_DisplayPushed, true);
            app.Button_Display.Icon = 'Display.png';
            app.Button_Display.Tooltip = {'Open Display settings GUI'};
            app.Button_Display.Text = '';
            app.Button_DataPixx = uibutton(app.SettingsTab, 'push');
            app.Button_DataPixx.ButtonPushedFcn = createCallbackFcn(app, @Button_DataPixxPushed, true);
            app.Button_DataPixx.Icon = 'DataPixx.png';
            app.Button_DataPixx.Tooltip = {'Open DataPixx Settings GUI'};
            app.Button_DataPixx.Text = '';
            app.Button_EyeTracking = uibutton(app.SettingsTab, 'push');
            app.Button_EyeTracking.ButtonPushedFcn = createCallbackFcn(app, @Button_EyeTrackingPushed, true);
            app.Button_EyeTracking.Icon = 'Eye.png';
            app.Button_EyeTracking.Tooltip = {'Open EyeTracker Settings GUI'};
            app.Button_EyeTracking.Text = '';
            app.Button_Reward = uibutton(app.SettingsTab, 'push');
            app.Button_Reward.ButtonPushedFcn = createCallbackFcn(app, @Button_RewardPushed, true);
            app.Button_Reward.Icon = 'Liquid.png';
            app.Button_Reward.Tooltip = {'Open Reward Settings GUI'};
            app.Button_Reward.Text = '';
            app.Button_ExpSettings = uibutton(app.SettingsTab, 'push');
            app.Button_ExpSettings.Icon = 'Settings.png';
            app.Button_ExpSettings.Tooltip = {'Open settings GUI for currently selected experiment'};
            app.Button_ExpSettings.Text = '';
            app.Button_Keyboard = uibutton(app.SettingsTab, 'push');
            app.Button_Keyboard.Icon = 'Keyboard.png';
            app.Button_Keyboard.Enable = 'off';
            app.Button_Keyboard.Tooltip = {'Keyboard shortcut settings'};
            app.Button_Keyboard.Text = '';
            app.Button_Save = uibutton(app.SettingsTab, 'push');
            app.Button_Save.Icon = 'Save.png';
            app.Button_Save.Enable = 'off';
            app.Button_Save.Tooltip = {'Save current settings'};
            app.Button_Save.Text = '';
            app.Button_Transfer = uibutton(app.SettingsTab, 'push');
            app.Button_Transfer.Icon = 'Transfer.png';
            app.Button_Transfer.Enable = 'off';
            app.Button_Transfer.Tooltip = {'Data transfer settings'};
            app.Button_Transfer.Text = '';
            app.Button_GitHub = uibutton(app.SettingsTab, 'push');
            app.Button_GitHub.Icon = 'Github.png';
            app.Button_GitHub.Enable = 'off';
            app.Button_GitHub.Tooltip = {'Install updates from GitHub repository'};
            app.Button_GitHub.Text = '';
            app.ExperimentsTab = uitab(app.TabGroup2);
            app.ExperimentsTab.Title = 'Experiments';
            app.Button_ImageExpSettings = uibutton(app.ExperimentsTab, 'push');
            app.Button_ImageExpSettings.ButtonPushedFcn = createCallbackFcn(app, @Button_ImageExpSettingsPushed, true);
            app.Button_ImageExpSettings.Icon = 'Slideshow.png';
            app.Button_ImageExpSettings.Tooltip = {'Open Image experiment settings GUI'};
            app.Button_ImageExpSettings.Text = '';
            app.Button_MovieExpSettings = uibutton(app.ExperimentsTab, 'push');
            app.Button_MovieExpSettings.ButtonPushedFcn = createCallbackFcn(app, @Button_MovieExpSettingsPushed, true);
            app.Button_MovieExpSettings.Icon = 'Movie.png';
            app.Button_MovieExpSettings.Tooltip = {'Open Movie experiment settings GUI'};
            app.Button_MovieExpSettings.Text = '';
            app.Button_VisuotopySettings = uibutton(app.ExperimentsTab, 'push');
            app.Button_VisuotopySettings.ButtonPushedFcn = createCallbackFcn(app, @Button_VisuotopySettingsPushed, true);
            app.Button_VisuotopySettings.Icon = 'Checkerboard.png';
            app.Button_VisuotopySettings.Enable = 'off';
            app.Button_VisuotopySettings.Tooltip = {'Open Retotopic Mapping Settings GUI'};
            app.Button_VisuotopySettings.Text = '';
            app.Button_BlenderExpSettings = uibutton(app.ExperimentsTab, 'push');
            app.Button_BlenderExpSettings.ButtonPushedFcn = createCallbackFcn(app, @Button_BlenderExpSettingsPushed, true);
            app.Button_BlenderExpSettings.Icon = 'Blender.png';
            app.Button_BlenderExpSettings.Enable = 'off';
            app.Button_BlenderExpSettings.Tooltip = {'Open Blender experiment settings GUI'};
            app.Button_BlenderExpSettings.Text = '';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.fMRITab = uitab(app.TabGroup);
            app.fMRITab.Title = 'fMRI';
            app.Button_ScannerPulse = uibutton(app.fMRITab, 'push');
            app.Button_ScannerPulse.Icon = 'EPI.png';
            app.Button_ScannerPulse.Enable = 'off';
            app.Button_ScannerPulse.Tooltip = {'Scanner pulse settings'};
            app.Button_ScannerPulse.Text = '';
            app.Button_BlockDesign = uibutton(app.fMRITab, 'push');
            app.Button_BlockDesign.ButtonPushedFcn = createCallbackFcn(app, @Button_BlockDesignPushed, true);
            app.Button_BlockDesign.Icon = 'Block.png';
            app.Button_BlockDesign.Tooltip = {'Scanner TTL pulse settings'};
            app.Button_BlockDesign.Text = '';
            app.Button_EPInoise = uibutton(app.fMRITab, 'push');
            app.Button_EPInoise.Icon = 'SpeakerOn.png';
            app.Button_EPInoise.Tooltip = {'Scanner sound playback'};
            app.Button_EPInoise.Text = '';
            app.EphysTab = uitab(app.TabGroup);
            app.EphysTab.Title = 'Ephys';
            app.Button_TDT = uibutton(app.EphysTab, 'push');
            app.Button_TDT.Icon = 'TDT.png';
            app.Button_TDT.Tooltip = {'Open TDT Settings'};
            app.Button_TDT.Text = '';
            app.Button_OpenEPhys = uibutton(app.EphysTab, 'push');
            app.Button_OpenEPhys.Icon = 'OpenEphys.png';
            app.Button_OpenEPhys.Enable = 'off';
            app.Button_OpenEPhys.Tooltip = {'Open OpenEphys Settings'};
            app.Button_OpenEPhys.Text = '';
            app.Button_Photodiode = uibutton(app.EphysTab, 'push');
            app.Button_Photodiode.Icon = 'Photodiode.png';
            app.Button_Photodiode.Enable = 'off';
            app.Button_Photodiode.Tooltip = {'Open photodiode settings'};
            app.Button_Photodiode.Text = '';
            app.Button_Optitrack = uibutton(app.EphysTab, 'push');
            app.Button_Optitrack.Icon = 'Motive.png';
            app.Button_Optitrack.Enable = 'off';
            app.Button_Optitrack.Tooltip = {'Open head tracking settings'};
            app.Button_Optitrack.Text = '';
            app.Button_Stimulation = uibutton(app.EphysTab, 'push');
            app.Button_Stimulation.Icon = 'Stimulation.png';
            app.Button_Stimulation.Enable = 'off';
            app.Button_Stimulation.Tooltip = {'Open stimulation settings'};
            app.Button_Stimulation.Text = '';
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.Button_Sterescopic = uibutton(app.DisplayTab, 'push');
            app.Button_Sterescopic.Icon = 'Stereoscopic.png';
            app.Button_Sterescopic.Tooltip = {'Toggle stereoscopic 3D presentation'};
            app.Button_Sterescopic.Text = '';
            app.Button_DomeProjection = uibutton(app.DisplayTab, 'push');
            app.Button_DomeProjection.Icon = 'Dome.png';
            app.Button_DomeProjection.Tooltip = {'Hemispheric projection dispay settings'};
            app.Button_DomeProjection.Text = '';
            app.Button_Blender = uibutton(app.DisplayTab, 'push');
            app.Button_Blender.Icon = 'Blender.png';
            app.Button_Blender.Enable = 'off';
            app.Button_Blender.Tooltip = {'Open Blender communication settings'};
            app.Button_Blender.Text = '';
            app.Button_Unity = uibutton(app.DisplayTab, 'push');
            app.Button_Unity.Icon = 'Unity.png';
            app.Button_Unity.Enable = 'off';
            app.Button_Unity.Tooltip = {'Open Unity communication settings'};
            app.Button_Unity.Text = '';
            app.CalibrateTab = uitab(app.TabGroup);
            app.CalibrateTab.Title = 'Calibrate';
            app.Button_LumCal = uibutton(app.CalibrateTab, 'push');
            app.Button_LumCal.Icon = 'Sleep.png';
            app.Button_LumCal.Tooltip = {'Run display luminance measurement'};
            app.Button_LumCal.Text = '';
            app.Button_CalGrid = uibutton(app.CalibrateTab, 'push');
            app.Button_CalGrid.ButtonPushedFcn = createCallbackFcn(app, @Button_CalGridPushed, true);
            app.Button_CalGrid.Icon = 'Cal.png';
            app.Button_CalGrid.Tooltip = {'Display calibration grid on subject''s display'};
            app.Button_CalGrid.Text = '';
            app.Button_DebugMode = uibutton(app.CalibrateTab, 'push');
            app.Button_DebugMode.Icon = 'Debug.png';
            app.Button_DebugMode.Tooltip = {'Toggle debug mode popups and warnings'};
            app.Button_DebugMode.Text = '';
            app.Button_RewardCal = uibutton(app.CalibrateTab, 'push');
            app.Button_RewardCal.Icon = 'Liquid.png';
            app.Button_RewardCal.Enable = 'off';
            app.Button_RewardCal.Tooltip = {'Run liquid reward calibration sequence'};
            app.Button_RewardCal.Text = '';
            app.Button_GammaCorrect = uibutton(app.CalibrateTab, 'push');
            app.Button_GammaCorrect.Icon = 'GammaCorrect.png';
            app.Button_GammaCorrect.Enable = 'off';
            app.Button_GammaCorrect.Tooltip = {'Edit and apply Gamma correction olor look up table (CLUTs)'};
            app.Button_GammaCorrect.Text = '';
            app.Button_ScannerCal = uibutton(app.CalibrateTab, 'push');
            app.Button_ScannerCal.Icon = 'EPI.png';
            app.Button_ScannerCal.Enable = 'off';
            app.Button_ScannerCal.Tooltip = {'Scanner pulse settings'};
            app.Button_ScannerCal.Text = '';
            app.Button_Joystick = uibutton(app.CalibrateTab, 'push');
            app.Button_Joystick.Icon = 'Joystick.png';
            app.Button_Joystick.Enable = 'off';
            app.Button_Joystick.Tooltip = {'Joystick / lever press settings'};
            app.Button_Joystick.Text = '';
            app.Button_DataPixx_2 = uibutton(app.CalibrateTab, 'push');
            app.Button_DataPixx_2.Icon = 'DataPixx.png';
            app.Button_DataPixx_2.Enable = 'off';
            app.Button_DataPixx_2.Tooltip = {'Open DataPixx Settings GUI'};
            app.Button_DataPixx_2.Text = '';
            app.Button_NationalInstruments_2 = uibutton(app.CalibrateTab, 'push');
            app.Button_NationalInstruments_2.Icon = 'NationalInstruments.png';
            app.Button_NationalInstruments_2.Enable = 'off';
            app.Button_NationalInstruments_2.Tooltip = {'Open National Instruments documentation'};
            app.Button_NationalInstruments_2.Text = '';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Button = uibutton(app.HelpTab, 'push');
            app.Button.Icon = 'NIF_MagneticField_White.gif';
            app.Button.Text = '';
            app.Button_HelpPTB = uibutton(app.HelpTab, 'push');
            app.Button_HelpPTB.Icon = 'Display.png';
            app.Button_HelpPTB.Tooltip = {'Open documentation'};
            app.Button_HelpPTB.Text = '';
            app.Button_HelpDataPixx = uibutton(app.HelpTab, 'push');
            app.Button_HelpDataPixx.Icon = 'DataPixx.png';
            app.Button_HelpDataPixx.Tooltip = {'Open documentation'};
            app.Button_HelpDataPixx.Text = '';
            app.Button_HelpArrington = uibutton(app.HelpTab, 'push');
            app.Button_HelpArrington.Icon = 'Eye.png';
            app.Button_HelpArrington.Tooltip = {'Open documentation'};
            app.Button_HelpArrington.Text = '';
            app.Button_HelpTDT = uibutton(app.HelpTab, 'push');
            app.Button_HelpTDT.Icon = 'TDT.png';
            app.Button_HelpTDT.Tooltip = {'Open documentation'};
            app.Button_HelpTDT.Text = '';
            app.Button_HelpGit = uibutton(app.HelpTab, 'push');
            app.Button_HelpGit.Icon = 'Github.png';
            app.Button_HelpGit.Tooltip = {'Open documentation'};
            app.Button_HelpGit.Text = '';
            app.Button_NationalInstruments = uibutton(app.HelpTab, 'push');
            app.Button_NationalInstruments.Icon = 'NationalInstruments.png';
            app.Button_NationalInstruments.Tooltip = {'Open National Instruments documentation'};
            app.Button_NationalInstruments.Text = '';
            app.Button_ReadTheDocs = uibutton(app.HelpTab, 'push');
            app.Button_ReadTheDocs.Icon = 'ReadTheDocs.png';
            app.Button_ReadTheDocs.Tooltip = {'Open National Instruments documentation'};
            app.Button_ReadTheDocs.Text = '';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.Button_CommandLine = uibutton(app.OptionsTab, 'state');
            app.Button_CommandLine.ValueChangedFcn = createCallbackFcn(app, @Button_CommandLineValueChanged, true);
            app.Button_CommandLine.Icon = 'Sleep.png';
            app.Button_CommandLine.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.RewardTab = uitab(app.TabGroup3);
            app.RewardTab.Title = 'Reward';
            app.UIAxes2 = uiaxes(app.RewardTab);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Reward (ml)')
            app.UIAxes2.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'};
            app.UIAxes2.YTickLabel = {'0'; '100'; '200'};
            app.SessionTextAreaLabel = uilabel(app.RewardTab);
            app.SessionTextAreaLabel.Text = 'Session';
            app.SessionCounterReset = uibutton(app.RewardTab, 'push');
            app.SessionCounterReset.ButtonPushedFcn = createCallbackFcn(app, @SessionCounterResetPushed, true);
            app.SessionCounterReset.Text = 'Reset';
            app.SessionCounter = uitextarea(app.RewardTab);
            app.SessionCounter.Value = {'0'};
            app.Button_ExpSettings_2 = uibutton(app.RewardTab, 'push');
            app.Button_ExpSettings_2.Icon = 'Settings.png';
            app.Button_ExpSettings_2.Tooltip = {'Open settings GUI for currently selected experiment'};
            app.Button_ExpSettings_2.Text = '';
            app.RunTextAreaLabel = uilabel(app.RewardTab);
            app.RunTextAreaLabel.Text = 'Run';
            app.RunCounterReset = uibutton(app.RewardTab, 'push');
            app.RunCounterReset.ButtonPushedFcn = createCallbackFcn(app, @RunCounterResetButtonPushed, true);
            app.RunCounterReset.Text = 'Reset';
            app.RunCounter = uitextarea(app.RewardTab);
            app.RunCounter.Value = {'0'};
            app.ManualmsSliderLabel = uilabel(app.RewardTab);
            app.ManualmsSliderLabel.Text = 'Manual (ms)';
            app.ManualmsSlider = uislider(app.RewardTab);
            app.ManualmsSlider.Value = 50;
            app.AutomsSliderLabel = uilabel(app.RewardTab);
            app.AutomsSliderLabel.Text = 'Auto (ms)';
            app.AutomsSlider = uislider(app.RewardTab);
            app.AutomsSlider.Value = 50;
            app.EyeTab = uitab(app.TabGroup3);
            app.EyeTab.Title = 'Eye';
            app.Button_ExpSettings_4 = uibutton(app.EyeTab, 'push');
            app.Button_ExpSettings_4.Icon = 'Settings.png';
            app.Button_ExpSettings_4.Tooltip = {'Open settings GUI for currently selected experiment'};
            app.Button_ExpSettings_4.Text = '';
            app.LeverTab = uitab(app.TabGroup3);
            app.LeverTab.Title = 'Lever';
            app.UIAxes = uiaxes(app.LeverTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Lever1LampLabel = uilabel(app.LeverTab);
            app.Lever1LampLabel.Text = 'Lever 1';
            app.Lever1Lamp = uilamp(app.LeverTab);
            app.Button_ExpSettings_3 = uibutton(app.LeverTab, 'push');
            app.Button_ExpSettings_3.Icon = 'Settings.png';
            app.Button_ExpSettings_3.Tooltip = {'Open settings GUI for currently selected experiment'};
            app.Button_ExpSettings_3.Text = '';
            app.Lever2LampLabel = uilabel(app.LeverTab);
            app.Lever2LampLabel.Text = 'Lever 2';
            app.Lever2Lamp = uilamp(app.LeverTab);
            app.SessionTab = uitab(app.TabGroup3);
            app.SessionTab.Title = 'Session';
            app.RunSpinnerLabel = uilabel(app.SessionTab);
            app.RunSpinnerLabel.Text = 'Run #';
            app.RunSpinner = uispinner(app.SessionTab);
            app.BlockSpinnerLabel = uilabel(app.SessionTab);
            app.BlockSpinnerLabel.Text = 'Block #';
            app.BlockSpinner = uispinner(app.SessionTab);
            % Show the figure after all components are created
            app.NIFToolbarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NIF_Toolbar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NIFToolbarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NIFToolbarUIFigure)
        end
    end
end